classdef EscherAppMaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EscherMasterFig                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        uipanel6                        matlab.ui.container.Panel
        OpenScreenBtn                   matlab.ui.control.Button
        RefreshStBy                     matlab.ui.control.Button
        OneScreenMode_ck                matlab.ui.control.CheckBox
        oculusCk                        matlab.ui.control.CheckBox
        SetstandbyluminanceEditFieldLabel  matlab.ui.control.Label
        StandbyL                        matlab.ui.control.NumericEditField
        LabelLumi_1                     matlab.ui.control.Label
        ApplydistortionCheckBox         matlab.ui.control.CheckBox
        uipanel4                        matlab.ui.container.Panel
        ScreendistancecmLabel           matlab.ui.control.Label
        ScreenDistance                  matlab.ui.control.NumericEditField
        WidthpixelsEditFieldLabel       matlab.ui.control.Label
        WidthPix                        matlab.ui.control.NumericEditField
        HeightpixelsEditFieldLabel      matlab.ui.control.Label
        HeightPix                       matlab.ui.control.NumericEditField
        WidthcmLabel                    matlab.ui.control.Label
        ScreenWidth                     matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel          matlab.ui.control.Label
        ScreenHeight                    matlab.ui.control.NumericEditField
        AngularsizeofscreenEditFieldLabel  matlab.ui.control.Label
        ScreenSizeAngle                 matlab.ui.control.EditField
        SynchronizationPanel            matlab.ui.container.Panel
        ard_ck                          matlab.ui.control.CheckBox
        HeightpxEditFieldLabel          matlab.ui.control.Label
        hhTxt                           matlab.ui.control.NumericEditField
        CueWidthpxEditFieldLabel        matlab.ui.control.Label
        wwTxt                           matlab.ui.control.NumericEditField
        CuepositionPanel                matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        CueTopRB                        matlab.ui.control.RadioButton
        CueBotRB                        matlab.ui.control.RadioButton
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        CueLeftRB                       matlab.ui.control.RadioButton
        CueRightRB                      matlab.ui.control.RadioButton
        DurationofcuemsEditFieldLabel   matlab.ui.control.Label
        optDtrTimeTxt                   matlab.ui.control.NumericEditField
        UDPConnectionPanel              matlab.ui.container.Panel
        StartUdpBtn                     matlab.ui.control.Button
        SlaveIPLabel                    matlab.ui.control.Label
        SlaveIp                         matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        SlavePort                       matlab.ui.control.NumericEditField
        MasterIPLabel                   matlab.ui.control.Label
        MasterIp                        matlab.ui.control.EditField
        PortEditFieldLabel_2            matlab.ui.control.Label
        MasterPort                      matlab.ui.control.NumericEditField
        GetMasterIPandMACBtn            matlab.ui.control.Button
        StatusEditFieldLabel_2          matlab.ui.control.Label
        ConnectionStatus                matlab.ui.control.EditField
        MACLabel_3                      matlab.ui.control.Label
        MasterMac                       matlab.ui.control.EditField
        MACLabel_2                      matlab.ui.control.Label
        SlaveMac                        matlab.ui.control.EditField
        TimeouttimesEditFieldLabel      matlab.ui.control.Label
        TimeoutTime                     matlab.ui.control.NumericEditField
        ResendScreenParams              matlab.ui.control.Button
        SubnetmaskEditFieldLabel        matlab.ui.control.Label
        SubnetMask                      matlab.ui.control.EditField
        BroadcastEditFieldLabel         matlab.ui.control.Label
        BroadcastAddress                matlab.ui.control.EditField
        UDPBroadcastDropDownLabel       matlab.ui.control.Label
        UDPBroadcastDropDown            matlab.ui.control.DropDown
        SendtoallstartaddressLabel      matlab.ui.control.Label
        STAstartTxt                     matlab.ui.control.EditField
        SendtoallendaddressEditFieldLabel  matlab.ui.control.Label
        STAendTxt                       matlab.ui.control.EditField
        LoadScreenInformationPanel      matlab.ui.container.Panel
        LoadScreenFileButton            matlab.ui.control.Button
        ScreennameEditFieldLabel        matlab.ui.control.Label
        ScreenNameTxt                   matlab.ui.control.EditField
        ScreenTransferFormulaLabel      matlab.ui.control.Label
        ScreenFuncTxt                   matlab.ui.control.EditField
        NotesLabel                      matlab.ui.control.Label
        ScreenNotesTxt                  matlab.ui.control.EditField
        SaveScreenFileButton            matlab.ui.control.Button
        luminanceLabel                  matlab.ui.control.Label
        CalModeRBGroup                  matlab.ui.container.ButtonGroup
        UncalibratedButton              matlab.ui.control.RadioButton
        CalibratedButton                matlab.ui.control.RadioButton
        StimulationTab                  matlab.ui.container.Tab
        FlashPanel                      matlab.ui.container.Panel
        BaselineluminanceLabel          matlab.ui.control.Label
        FSbaselineL                     matlab.ui.control.NumericEditField
        FlashluminanceLabel             matlab.ui.control.Label
        FSflashL                        matlab.ui.control.NumericEditField
        LabelLumi_2                     matlab.ui.control.Label
        LabelLumi_3                     matlab.ui.control.Label
        FSbaselineLEsch                 matlab.ui.control.NumericEditField
        LabelLumiEsch_2                 matlab.ui.control.Label
        FSflashLEsch                    matlab.ui.control.NumericEditField
        LabelLumiEsch_3                 matlab.ui.control.Label
        FastFlashStimulusSelectionPanel  matlab.ui.container.Panel
        FlashFastTable                  matlab.ui.control.Table
        noftotstimLabel                 matlab.ui.control.Label
        FlashFastTotN                   matlab.ui.control.NumericEditField
        MinLEditFieldLabel              matlab.ui.control.Label
        FlashFastMinL                   matlab.ui.control.NumericEditField
        MaxLEditFieldLabel              matlab.ui.control.Label
        FlashFastMaxL                   matlab.ui.control.NumericEditField
        logspacedFlashCk                matlab.ui.control.CheckBox
        includezeroFlashCk              matlab.ui.control.CheckBox
        generatestimuliFlashBtn         matlab.ui.control.Button
        savetableButton                 matlab.ui.control.Button
        FlashFastLoadTable              matlab.ui.control.Button
        ChoosestimEditFieldLabel        matlab.ui.control.Label
        FlashFastN                      matlab.ui.control.NumericEditField
        FSFlashGo                       matlab.ui.control.Button
        FlashdurationsLabel             matlab.ui.control.Label
        FlashDuration                   matlab.ui.control.NumericEditField
        RepetitionsLabel_2              matlab.ui.control.Label
        FlashRepetitions                matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel           matlab.ui.control.Label
        FlashPeriod                     matlab.ui.control.NumericEditField
        uipanel14                       matlab.ui.container.Panel
        InitialdelaysLabel              matlab.ui.control.Label
        initialDelay                    matlab.ui.control.NumericEditField
        ContrastStimulationPanel        matlab.ui.container.Panel
        LabelLumi_4                     matlab.ui.control.Label
        BlackluminanceLabel             matlab.ui.control.Label
        CheckLumiB                      matlab.ui.control.NumericEditField
        LabelLumi_5                     matlab.ui.control.Label
        WhiteluminanceLabel             matlab.ui.control.Label
        CheckLumiW                      matlab.ui.control.NumericEditField
        LabelLumi_6                     matlab.ui.control.Label
        MeanluminanceLabel              matlab.ui.control.Label
        CheckLumiMean                   matlab.ui.control.NumericEditField
        ContrastLabel                   matlab.ui.control.Label
        CheckContrast                   matlab.ui.control.NumericEditField
        CheckerboardPanel               matlab.ui.container.Panel
        CheckGo                         matlab.ui.control.Button
        RepetitionsEditField_2Label     matlab.ui.control.Label
        CheckRepetitions                matlab.ui.control.NumericEditField
        PeriodsEditField_2Label         matlab.ui.control.Label
        CheckPeriod                     matlab.ui.control.NumericEditField
        GratingsPanel                   matlab.ui.container.Panel
        SliderOrientation               matlab.ui.control.Slider
        GoGrid                          matlab.ui.control.Button
        DrawMask                        matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SineGrid                        matlab.ui.control.RadioButton
        SquareGrid                      matlab.ui.control.RadioButton
        GridorientationdegEditFieldLabel  matlab.ui.control.Label
        GridOrientation                 matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel       matlab.ui.control.Label
        GridRepetitions                 matlab.ui.control.NumericEditField
        BaselineGratingPostEditFieldLabel  matlab.ui.control.Label
        PreGridDuration                 matlab.ui.control.NumericEditField
        GridDuration                    matlab.ui.control.NumericEditField
        PostGridDuration                matlab.ui.control.NumericEditField
        DriftingspeedcyclessecEditFieldLabel  matlab.ui.control.Label
        DriftingSpeed_txt               matlab.ui.control.NumericEditField
        SizegaussianmaskpixEditFieldLabel  matlab.ui.control.Label
        GaussSize_txt                   matlab.ui.control.NumericEditField
        FastOrientationStimulusSelectionPanel  matlab.ui.container.Panel
        generatestimuliOrientBtn        matlab.ui.control.Button
        OrientFastSaveTable             matlab.ui.control.Button
        OrientFastLoadTable             matlab.ui.control.Button
        noftotstimLabel_3               matlab.ui.control.Label
        OrientFastTotN                  matlab.ui.control.NumericEditField
        StartAngleEditFieldLabel        matlab.ui.control.Label
        OrientFastMinA                  matlab.ui.control.NumericEditField
        EndAngleEditFieldLabel          matlab.ui.control.Label
        OrientFastMaxA                  matlab.ui.control.NumericEditField
        OrientFastTable                 matlab.ui.control.Table
        ChoosestimEditFieldLabel_3      matlab.ui.control.Label
        OrientFastN                     matlab.ui.control.NumericEditField
        IntrinsicImagingPanel           matlab.ui.container.Panel
        IntrinsicDefaultParametersCheckBox  matlab.ui.control.CheckBox
        IntrinsicGOButton               matlab.ui.control.Button
        OrientationSwitchLabel          matlab.ui.control.Label
        IntrOrientationSwitch           matlab.ui.control.ToggleSwitch
        MovementdirectionSwitchLabel    matlab.ui.control.Label
        IntrDirectionSwitch             matlab.ui.control.ToggleSwitch
        RectanglesizedegEditFieldLabel  matlab.ui.control.Label
        IntrRectSizeTxt                 matlab.ui.control.NumericEditField
        CheckLumiBEsch                  matlab.ui.control.NumericEditField
        CheckLumiWEsch                  matlab.ui.control.NumericEditField
        CheckLumiMeanEsch               matlab.ui.control.NumericEditField
        LabelLumiEsch_4                 matlab.ui.control.Label
        LabelLumiEsch_5                 matlab.ui.control.Label
        LabelLumiEsch_6                 matlab.ui.control.Label
        FastContrastStimulusSelectionPanel  matlab.ui.container.Panel
        CheckFastTable                  matlab.ui.control.Table
        ChoosestimEditFieldLabel_2      matlab.ui.control.Label
        CheckFastN                      matlab.ui.control.NumericEditField
        CheckFastSaveTable              matlab.ui.control.Button
        CheckFastLoadTable              matlab.ui.control.Button
        generatestimuliCheckBtn         matlab.ui.control.Button
        logspacedCheckCk                matlab.ui.control.CheckBox
        includezeroCheckCk              matlab.ui.control.CheckBox
        MinLEditFieldLabel_2            matlab.ui.control.Label
        CheckFastMinC                   matlab.ui.control.NumericEditField
        MaxLEditFieldLabel_2            matlab.ui.control.Label
        CheckFastMaxC                   matlab.ui.control.NumericEditField
        noftotstimLabel_2               matlab.ui.control.Label
        CheckFastTotN                   matlab.ui.control.NumericEditField
        MeanLEditFieldLabel             matlab.ui.control.Label
        CheckFastMeanL                  matlab.ui.control.NumericEditField
        FrequencycyclesDegLabel         matlab.ui.control.Label
        spatialFrequency                matlab.ui.control.NumericEditField
        SomatosensoryPanel              matlab.ui.container.Panel
        RepetitionsLabel_3              matlab.ui.control.Label
        PuffRepetitions                 matlab.ui.control.NumericEditField
        PuffdurationmsLabel             matlab.ui.control.Label
        PuffDuration                    matlab.ui.control.NumericEditField
        PeriodsLabel                    matlab.ui.control.Label
        PuffPeriod                      matlab.ui.control.NumericEditField
        PuffGo                          matlab.ui.control.Button
        ExperimentlistTab               matlab.ui.container.Tab
        ExperimentTable                 matlab.ui.control.Table
        RandomizeexperimentPanel        matlab.ui.container.Panel
        ExpListFlashCk                  matlab.ui.control.CheckBox
        ExpListCheckCk                  matlab.ui.control.CheckBox
        ExpListGratingsOrientCk         matlab.ui.control.CheckBox
        RandomizeButton                 matlab.ui.control.Button
        NumberofsessionsEditFieldLabel  matlab.ui.control.Label
        ExpListSessionN                 matlab.ui.control.NumericEditField
        ExpListGratingsContrastCk       matlab.ui.control.CheckBox
        ExpListGo                       matlab.ui.control.Button
        ExpListAddRows                  matlab.ui.control.Button
        ExpListDeleteRows               matlab.ui.control.Button
        ExpMoveUp                       matlab.ui.control.Button
        ExpMoveDown                     matlab.ui.control.Button
        CopytableButton                 matlab.ui.control.Button
        CurrentrunnEditFieldLabel       matlab.ui.control.Label
        ExpListCurrentNum               matlab.ui.control.NumericEditField
        RepeatStimBtn                   matlab.ui.control.Button
        NoffirstexpEditFieldLabel       matlab.ui.control.Label
        ExpListFirstNum                 matlab.ui.control.NumericEditField
        PupilWhileStimulating           matlab.ui.control.CheckBox
        PcoCDataStimMaxEditField        matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        CDataMinEditField_2Label        matlab.ui.control.Label
        PcoCDataStimMinEditField        matlab.ui.control.NumericEditField
        PcoAutoCLimStimButton           matlab.ui.control.Button
        StimulationandPCOPanel          matlab.ui.container.Panel
        PcoWhileStimulusCk              matlab.ui.control.CheckBox
        DeadtimebetweeneachtrialmsEditFieldLabel  matlab.ui.control.Label
        DeadTBeforeTrialEditField       matlab.ui.control.NumericEditField
        PcoHAxesStim                    matlab.ui.control.UIAxes
        PupilTab                        matlab.ui.container.Tab
        CameraPanel                     matlab.ui.container.Panel
        RefreshDevicesButton            matlab.ui.control.Button
        CameraDropDownLabel             matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        ImageSizeDropDownLabel          matlab.ui.control.Label
        ImageSizeDropDown               matlab.ui.control.DropDown
        CameraUnconnectedLampLabel      matlab.ui.control.Label
        CameraUnconnectedLamp           matlab.ui.control.Lamp
        PreviewPanel                    matlab.ui.container.Panel
        NumberofPointsinPreviewGraphEditFieldLabel  matlab.ui.control.Label
        NumberofPointsinPreviewGraphEditField  matlab.ui.control.NumericEditField
        StartPreviewButton              matlab.ui.control.Button
        skipPupilPrevCk                 matlab.ui.control.CheckBox
        ResetAxesButton                 matlab.ui.control.Button
        ThresholdSliderLabel            matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        AcquisitionPanel                matlab.ui.container.Panel
        DurationsEditFieldLabel         matlab.ui.control.Label
        DurationsEditField              matlab.ui.control.NumericEditField
        PupilStartAcquisitionButton     matlab.ui.control.Button
        PreviewWhileAcquiringCheckBox   matlab.ui.control.CheckBox
        SaveFolderEditFieldLabel        matlab.ui.control.Label
        SaveFolderEditField             matlab.ui.control.EditField
        FilenEditFieldLabel             matlab.ui.control.Label
        OutputFileN                     matlab.ui.control.NumericEditField
        StatusEditFieldLabel            matlab.ui.control.Label
        acqStatusTxt                    matlab.ui.control.EditField
        skipPupilAcqCk                  matlab.ui.control.CheckBox
        acqLamp                         matlab.ui.control.Lamp
        FilenameEditFieldLabel_2        matlab.ui.control.Label
        PupilFilenameEditField          matlab.ui.control.EditField
        PupilImage1UIAxes               matlab.ui.control.UIAxes
        PupilImage2UIAxes               matlab.ui.control.UIAxes
        PupilGraphUIAxes                matlab.ui.control.UIAxes
        PCOImagingTab                   matlab.ui.container.Tab
        FindPCOButton                   matlab.ui.control.Button
        PCOLabel                        matlab.ui.control.Label
        PcoDropDown                     matlab.ui.control.DropDown
        AcquisitionPanel_2              matlab.ui.container.Panel
        DurationsEditField_2Label       matlab.ui.control.Label
        PcoDurationEditField            matlab.ui.control.NumericEditField
        PcoStartAcquisitionButton       matlab.ui.control.Button
        SaveFolderEditField_2Label      matlab.ui.control.Label
        PcoSaveFolderEditField          matlab.ui.control.EditField
        FilenEditFieldLabel_2           matlab.ui.control.Label
        PcoOutputFileN                  matlab.ui.control.NumericEditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        PcoFilenameEditField            matlab.ui.control.EditField
        MaxFrequencyHzEditFieldLabel    matlab.ui.control.Label
        PcoMaxFreqEditField             matlab.ui.control.NumericEditField
        SelectedFrequencyHzEditFieldLabel  matlab.ui.control.Label
        PcoSelectedFreqEditField        matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel    matlab.ui.control.Label
        PcoNumberFramesEditField        matlab.ui.control.NumericEditField
        PcoSavefileCk                   matlab.ui.control.CheckBox
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        PcoTimeoutEditField             matlab.ui.control.NumericEditField
        HermesInitialDelaysEditFieldLabel  matlab.ui.control.Label
        HermesInitialDelaysEditField    matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel    matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.NumericEditField
        TimestampLabel                  matlab.ui.control.Label
        TimestampDropDown               matlab.ui.control.DropDown
        RecordingsubmodeDropDownLabel   matlab.ui.control.Label
        RecordingsubmodeDropDown        matlab.ui.control.DropDown
        SynchronizationtypeDropDownLabel  matlab.ui.control.Label
        PcoSyncDropDown                 matlab.ui.control.DropDown
        PreviewPanel_2                  matlab.ui.container.Panel
        PcoStartPreviewButton           matlab.ui.control.Button
        PcoCDataMaxEditField            matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        CDataMinLabel                   matlab.ui.control.Label
        PcoCDataMinEditField            matlab.ui.control.NumericEditField
        PreviewoneframeeveryLabel       matlab.ui.control.Label
        PcoNFramesInPreview             matlab.ui.control.NumericEditField
        PcoAutoCLimButton               matlab.ui.control.Button
        PCOunarmedLabel                 matlab.ui.control.Label
        PCOarmedLamp                    matlab.ui.control.Lamp
        ArmPCOButton                    matlab.ui.control.Button
        BuffernumberEditFieldLabel      matlab.ui.control.Label
        PcoBufferNumEditField           matlab.ui.control.NumericEditField
        OpenFieldAnalyzerButton         matlab.ui.control.StateButton
        PcoHAxes                        matlab.ui.control.UIAxes
        OptogeneticsTab                 matlab.ui.container.Tab
        TypeButtonGroup                 matlab.ui.container.ButtonGroup
        ContinuousButton                matlab.ui.control.RadioButton
        PulsedButton                    matlab.ui.control.RadioButton
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        DuntycycleLabel                 matlab.ui.control.Label
        DuntycycleEditField             matlab.ui.control.NumericEditField
        TimingPanel                     matlab.ui.container.Panel
        StartmsEditFieldLabel           matlab.ui.control.Label
        optoStartEditField              matlab.ui.control.NumericEditField
        afterstimulusonsetLabel         matlab.ui.control.Label
        DurationmsEditFieldLabel        matlab.ui.control.Label
        optoDurEditField                matlab.ui.control.NumericEditField
        HermesTab                       matlab.ui.container.Tab
        calblack_btn                    matlab.ui.control.Button
        calwhite_btn                    matlab.ui.control.Button
        TTLdurationmsLabel              matlab.ui.control.Label
        HermesTtlDurationEditField      matlab.ui.control.NumericEditField
        HermesIdletimemsLabel           matlab.ui.control.Label
        HermesIdleTimeEditField         matlab.ui.control.NumericEditField
        SerialPanel                     matlab.ui.container.Panel
        RefreshCOMButton                matlab.ui.control.Button
        SerialCOMDropDownLabel          matlab.ui.control.Label
        SerialCOMDropDown               matlab.ui.control.DropDown
        PrintSerialCommCk               matlab.ui.control.CheckBox
        WorkingconfigurationPanel       matlab.ui.container.Panel
        ModeDropDownLabel               matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        TriggerDropDownLabel            matlab.ui.control.Label
        TriggerDropDown                 matlab.ui.control.DropDown
        ApplyButton                     matlab.ui.control.Button
        BusyStatusLampLabel             matlab.ui.control.Label
        BusyStatusLamp                  matlab.ui.control.Lamp
        DeBusyButton                    matlab.ui.control.Button
        UDPConnectionLampLabel          matlab.ui.control.Label
        UDPConnectionLamp               matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        %         For the most common values (dimension of the screen, black and
        %         white values, ...)
        
        %         ComHandle % Former synchronization TTL signal
        w
        screenRect
        ifi
        white
        black
        ScreenBitDepth % is either 'uint8' or 'uint16'
        HermesRect % Like screenRect but for Hermes
        OnePxAngle % Angle subtended by a single pixel
        ScreenFunc % Screen function (from 0-1 value to luminance)
        
        HermesSerialFlag % flag of serial communication with Hermes
        HermesSerialObj % object of serial communication with Hermes
        HermesReplyCheck
        
        %         For UDP connection:
        UdpObj % the actual UDP object
        UdpTimeoutTimer % the timer for making the UDP connection time out
        UdpConnectionFlag % When this is 1, this app works as master
        SendToAllTerminator % When this is 1, the send-to-all sort-of-broadcast is terminated.
        
        %         This is the structure containing all the variables for the pupil
        %         recording.
        PupilStruct
        PcoStruct
        
        ScreenOpenFlag % is 1 when the screen is open, it's 0 when it's not open
        
        %         folder of current escher app
        escherAppFolder
        PathSeparator
        %         For logs
        runNumberFile % the log file where the run number is stored.
        logDir % the directory of log files
        runNumber % The run number, progressive number of the next stimulation.
        CurrentLogStruct % structure containing .T (a table) and .logName, the name for current log.
        settingsDir % Directory of settings.
        
        %         Label for luminance of the vector of objects
        LabeLumiVector
        
        %         Store the indices of selected cells of the experiment list
        ExpListSelectedIndices
        %         The colors of the experiment table. First is the background, then
        %         the current exp, then the running exp, then the completed exp.
        ExpListColors
        
        %         This is the index of current experiment on the Experiment List
        %         table. Not to be confused with ExpListCurrentNum (which is the
        %         number assigned to the current experiment).
        ExpListCurrentIdx
        
        VisualFieldAnalyzerApp
    end
    
    methods (Access = public)        
        function saveParams(app, filename)
            paramsStruct = struct;
            
            propList = string(properties(app)); % get all the properies of the main app as string vector
            propListLogic = false(size(propList)); % initialize a logic vector, that will be used to tell apart checkboxes and edit fields from the rest
            
            for i=1:length(propList)
                if isobject(app.(propList(i))) && isprop(app.(propList(i)),'Type') % only isprop is not enough
                    if strcmp(app.(propList(i)).Type,'uinumericeditfield') || strcmp(app.(propList(i)).Type,'uicheckbox') || (strcmp(app.(propList(i)).Type,'uieditfield') && app.(propList(i)).Editable==1)
                        % logic 1 for all properties that are either
                        % numeric edit fields or checkboxes. Also added
                        % edit field (non numeric) but only if they are
                        % editable
                        propListLogic(i) = true;
                    end
                end
            end
            
            % paramsTable is 2-column table. First column is for names,
            % second is for values
            paramsTable = table('size', [sum(propListLogic), 2],'VariableTypes', {'string', 'cell'}, 'VariableNames', {'Name', 'Value'});
            paramsTable.Name = propList(propListLogic); % list of only checkboxes and editable textboxes
            
            % Extract all the names, values and callbacks of the checkboxes and editable textboxes of the main App
            for i=1:height(paramsTable)
                paramsTable.Value{i} = app.(paramsTable.Name(i)).Value;
            end
            
            paramsStruct.Table = paramsTable;
            
            save(filename, 'paramsStruct')
        end
        
        function loadParams(app,filename)
            %             This function load the parameters saved in a .mat file. The
            %             parameters are in a structure called paramsStruct.
            
            paramsStruct = [];
            
            %             All parameters from the gui are saved separately in a table,
            %             called paramsTable.
            
            open(filename) % from the filename location, a variable paramsStruct will be loaded
            for i = 1 : height(paramsStruct.paramsTable)
                %                 i
                %                 if i==24
                %                     disp('eccoci')
                %                 end
                app.(paramsStruct.paramsTable.Name(i)).Value = paramsStruct.paramsTable.Value{i};
                if ~isempty(app.(paramsStruct.paramsTable.Name(i)).ValueChangedFcn)
                    try
                        app.(paramsStruct.paramsTable.Name(i)).ValueChangedFcn(app.(paramsStruct.paramsTable.Name(i)),[])
                    catch ME
                        warning('off','backtrace')
                        warning(strcat("Error while executing ", paramsStruct.paramsTable.Name(i), "'s Callback"))
                        warning(ME.message)
                        disp(newline)
                    end
                end
            end
            
            warning('on','backtrace')
            % load handel
            % sound(y,Fs)
            disp('params loaded!')
        end
        
        function EnableGoBtn(app,flag)
            switch flag
                case 1
                    app.FSFlashGo.Enable = 1;
                    app.CheckGo.Enable = 1;
                    app.GoGrid.Enable = 1;
                    app.ExpListGo.Enable = 1;
                    app.BusyStatusLamp.Color = [0 1 0];
                    if app.UdpConnectionFlag
                        app.StartUdpBtn.Enable = 1;
                    end
                case 0
                    app.FSFlashGo.Enable = 0;
                    app.CheckGo.Enable = 0;
                    app.GoGrid.Enable = 0;
                    app.ExpListGo.Enable = 0;
                    app.BusyStatusLamp.Color = [1 0 0];
                    if app.UdpConnectionFlag
                        app.StartUdpBtn.Enable = 0;
                    end
            end
        end
        
        function CheckUpdateLumi(app,updateContrastFlag)
            switch updateContrastFlag
%                 If updateContrastFlag is 1, updates mean and contrast.
%                 Otherwise it updates black and white lumi.
                
%                 So, usually when black and white luminance are changed,
%                 the CheckUpdateLumi is called to update contrast and mean
%                 luminance.
%                 Conversely, if contrast or mean luminance are changed,
%                 CheckUpdateLumi is called to update black and white
%                 luminance.

                case 1
%                     This function updates the mean luminance value and contrast
%                     value according to the black and white luminances that are
%                     set.
                    if app.CalibratedButton.Value
%                         updates both the escher values and the luminance
%                         values for black and white. This is because the true luminance
%                         sometimes is not exactly what is set but it can
%                         be a bit different, according to what the screen
%                         can portray.
                        [app.CheckLumiBEsch.Value,app.CheckLumiB.Value] = Lumi2Escher(app.CheckLumiB.Value,app.white,app.ScreenFunc);
                        [app.CheckLumiWEsch.Value,app.CheckLumiW.Value] = Lumi2Escher(app.CheckLumiW.Value,app.white,app.ScreenFunc);
                        [app.CheckLumiMeanEsch.Value, app.CheckLumiMean.Value] = Lumi2Escher(app.CheckLumiMean.Value,app.white,app.ScreenFunc);
                    end
                    Blumi = app.CheckLumiB.Value;
                    Slumi = app.CheckLumiW.Value;
                    app.CheckLumiMean.Value = (Blumi+Slumi)/2;
                    app.CheckContrast.Value = (Slumi-Blumi)/(Blumi+Slumi)*100;
                case 0
                    %                     Update the black luminance and white luminance
                    %                     according to the mean luminance and contrast.
                    
                    Mlumi = app.CheckLumiMean.Value;
                    contrast = app.CheckContrast.Value/100;
                    
                    Blumi = Mlumi*(1-contrast);
                    Slumi = Mlumi*(1+contrast);
                    if app.CalibratedButton.Value
%                         updates both the escher values and the luminance
%                         values.
                        [app.CheckLumiBEsch.Value,Blumi] = Lumi2Escher(Blumi,app.white,app.ScreenFunc);
                        [app.CheckLumiWEsch.Value,Slumi] = Lumi2Escher(Slumi,app.white,app.ScreenFunc);
%                         Since the luminance values have now been changed,
%                         also the mean and contrast have to be changed.
                        Mlumi = (Blumi+Slumi)/2;
                        [app.CheckLumiMeanEsch.Value, Mlumi] = Lumi2Escher(Mlumi,app.white,app.ScreenFunc);
                        app.CheckLumiMean.Value = Mlumi;
                        app.CheckContrast.Value = (Slumi-Blumi)/(Blumi+Slumi)*100;
                    end
                        app.CheckLumiB.Value = Blumi;
                        app.CheckLumiW.Value = Slumi;
            end
        end
        
        function HermesRectCalc(app)
            sR = app.screenRect;
            ww = app.wwTxt.Value; %width of the optical DTR
            hh = app.hhTxt.Value; %height of the optical DTR
            %             The optical DTR must be placed according to the selection
            %             of the position
            % position of DtrRect (x0, y0, x4, y4)
            
            app.HermesRect = [app.CueRightRB.Value*(sR(3)-ww),...
                app.CueBotRB.Value*(sR(4)-hh),...
                sR(3)-app.CueLeftRB.Value*(sR(3)-ww),...
                sR(4)-app.CueTopRB.Value*(sR(4)-hh)];
        end
        
        function PixelAngleCalc(app)
            % Compute angular size of single pixel. This is the angle
            % subtended by a single pixel.
            Wpx = app.WidthPix.Value; % Number of pixels in the screen width
            ScWidth = app.ScreenWidth.Value; % Screen width in centimeters
            ScDist = app.ScreenDistance.Value; % Distance of the screen in centimeters
%             The angle of a single pixel is returned.
            app.OnePxAngle = atand((ScWidth/Wpx)/ScDist);    % atand returns the angle in degrees
        end
    end
    
    methods (Access = private)
        function refreshcamera(app)
            % Closing the device
            closedevice(app)
            
            app.PupilStruct.imaqhwinfoStruct = imaqhwinfo('winvideo'); % Open the structure imaqhwinfo with the adaptor 'winvideo'
            app.PupilStruct.camlist = cell(zeros(size(app.PupilStruct.imaqhwinfoStruct.DeviceInfo)));
            for i=1:length(app.PupilStruct.imaqhwinfoStruct.DeviceInfo) % Extract the camera list
                app.PupilStruct.camlist{i} = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(i).DeviceName;
            end
            
            % Refreshing the popup menu
            app.CameraDropDown.Items = app.PupilStruct.camlist;
            
            % If possible, find the index of the USB camera
            usbcamIdx = find(contains(app.PupilStruct.camlist,'usb','IgnoreCase',true),1,'first'); % usb camera
            if ~isempty(usbcamIdx)
                app.PupilStruct.selcamIdx = usbcamIdx; % if there is a usb camera, select it
            elseif ~isempty(app.PupilStruct.camlist)
                app.PupilStruct.selcamIdx = 1; %if a usb camera is not there, select the first camera
            else
                app.PupilStruct.selcamIdx = [];
            end
            % app.selcamIdx will now contain the index of the selected camera
            
            selectcam(app)
        end
        
        function closedevice(app)
            %closepreview
            %             Check if the camera object is there
            if isfield(app.PupilStruct,'camobj')
                delete(app.PupilStruct.camobj)
            end
            
            app.CameraUnconnectedLamp.Color = [1 0 0];
            app.CameraUnconnectedLampLabel.Text = 'Camera Unconnected';
        end
        
        function selectcam(app)
            closedevice(app)
            
            if isempty(app.PupilStruct.selcamIdx)
                return
            end
            
            app.CameraDropDown.Value = app.PupilStruct.camlist{app.PupilStruct.selcamIdx};
            
            % Refreshing the formats
            camformats = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(app.PupilStruct.selcamIdx).SupportedFormats;
            
            % Parsing of the formats
            camformats_nCols = zeros(size(camformats)); % double array containing the number of columns (pixel size of X Axis)
            camformats_nRows = zeros(size(camformats)); % y
            camformats_CA = cell(size(camformats));
            
            for i = 1:length(camformats)
                underscore_pos = find(camformats{i} == '_',1,'last');
                x_pos = find(camformats{i} == 'x',1,'last');
                camformats_nCols(i) = str2double(camformats{i}(x_pos+1:end));
                camformats_nRows(i) = str2double(camformats{i}(underscore_pos+1:x_pos-1));
                
                % camformats is a cell array that contains one column: the cell
                % array of strings describing all the resolution as "Xdimension x
                % Ydimenion"
                camformats_CA{i} = [num2str(camformats_nRows(i)),'x',num2str(camformats_nCols(i))];
            end
            
            % Let's refresh the list of formats
            app.ImageSizeDropDown.Items = camformats_CA;
            
            % Let's take the format corresponging to the smallest image size
            [~,app.PupilStruct.selformatIdx] = min(camformats_nCols .* camformats_nRows);
            app.PupilStruct.selformat = [camformats_nRows(app.PupilStruct.selformatIdx), camformats_nCols(app.PupilStruct.selformatIdx)];
            
            selectformat(app)
        end
        
        function selectformat(app)
            %% If the format changes, the device needs to be closed and connected again
            closedevice(app)
            
            % Recreate a format list
            camID = app.PupilStruct.selcamIdx;
            camformats = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(camID).SupportedFormats;
            
            %% (Re-)Initializing the axes
            % The preview image is initialized
            app.PupilStruct.hPreviewImage = image(app.PupilImage1UIAxes, uint8(zeros(app.PupilStruct.selformat(1),app.PupilStruct.selformat(2), 3)));
            app.PupilImage1UIAxes.CameraUpVectorMode = 'manual';
            app.PupilImage1UIAxes.CameraUpVector = [0 1 0];
            set(app.PupilImage1UIAxes,'XTick',[], 'YTick', []) % Removes the ticks and labels from the axes
            
            % Draw the "rectangle" (actually an ellipse) on image 1
            app.PupilStruct.hRoi = rectangle(app.PupilImage1UIAxes,'Position',[1,1,1,1],'EdgeColor',[1 0 0],...
                'Curvature', [1,1],'LineWidth',2);
            app.PupilStruct.hRoi.Visible = 'off';
            
            % % ------- THIS CAN BE UNCOMMENTED TO SPEED THE REFRESHING UP A LITTLE BIT
            % handles.image1_ax.Units = 'pixels';
            % % The axis dimensions are set to the same as the image size to speed
            % % everything up
            % handles.image1_ax.Position = [handles.image1_ax.Position(1),handles.image1_ax.Position(2),handles.selformat(1),handles.selformat(2)];
            % set(handles.image1_ax, 'xlimmode','manual','ylimmode','manual','zlimmode','manual','climmode','manual','alimmode','manual');
            % % -------
            
            % Axis 2 is for the binary image
            app.PupilStruct.hImage2 = image(app.PupilImage2UIAxes, uint8(zeros(app.PupilStruct.selformat(1),app.PupilStruct.selformat(2))));
            app.PupilImage2UIAxes.CameraUpVectorMode = 'manual';
            app.PupilImage2UIAxes.CameraUpVector = [0 1 0]; % The Y axis must not be reversed
            set(app.PupilImage2UIAxes,'XTick',[], 'YTick', []) % Removes the ticks and labels from the axes
            
            % % ------- THIS CAN BE UNCOMMENTED TO SPEED THE REFRESHING UP A LITTLE BIT
            % handles.image2_ax.Units = 'pixels';
            % % The axis dimensions are set to the same as the image size to speed
            % % everything up
            % handles.image2_ax.Position = [handles.image2_ax.Position(1),handles.image2_ax.Position(2),handles.selformat(1),handles.selformat(2)];
            % set(handles.image2_ax, 'xlimmode','manual','ylimmode','manual','zlimmode','manual','climmode','manual','alimmode','manual');
            % % -------
            
            %% Camera Object Creation
            % Select webcam and the correct format, and connect
            app.PupilStruct.camobj = videoinput('winvideo',camID,camformats{app.PupilStruct.selformatIdx});
            
            app.CameraUnconnectedLamp.Color = [0 1 0];
            app.CameraUnconnectedLampLabel.Text = 'Camera Connected!';
        end
        
        % This is for the segmentation of the pupil
        function [diametros,boundingBox,piel] = pupilSegment(app, rgbImage)
            la_imagen = rgb2gray(rgbImage);
            
            piel = ~imbinarize(la_imagen,app.PupilStruct.threshold);
            %     --
            piel = bwmorph(piel,'close');
            piel = bwmorph(piel,'open');
            piel = bwareaopen(piel,200);
            piel = imfill(piel,'holes');
            
            % Tagged objects in BW image
            L = logical(bwlabel(piel));
            % Get areas and tracking rectangle
            out_a = regionprops(L);
            % Count the number of objects
            N = size(out_a,1);
            
            if N>=1 && ~isempty(out_a)
                % ---
                % Select larger area
                areas = [out_a.Area];
                [~, pam] = max(areas); % [area_max, pam] = max(areas);
                
                diametros = sqrt(out_a(pam).BoundingBox(3)*out_a(pam).BoundingBox(4));
                
                boundingBox = out_a(pam).BoundingBox;
            else
                diametros = NaN;
                boundingBox = NaN;
            end
        end
        
        % This is the timerFcn for the acquisition
        function AcquisitionTimerFcn(app)
            switch app.PupilStruct.acqTimerStatus
                case 0 % First time the callback is executing
                    app.PupilStruct.camobj.FramesPerTrigger = inf; % acquire until execution of method stop
                    %                     app.PupilStruct.camobj.FrameGrabInterval = app.frameGrabInterval; % acquire one frame every n frames
                    
                    % The start time of the acquisition, at the moment it's not used because the getdata function already gets the relative time of each frame from the beginning of the acquisition
                    %                    app.acqStartTime = clock;
                    
                    start(app.PupilStruct.camobj)
                    app.PupilStruct.acqTimerStatus = 1;
                    
                case 1 % Second time the callback is executing
                    stop(app.PupilStruct.camobj)
                    
                    if app.PreviewWhileAcquiringCheckBox.Value
                        PreviewStop(app)
                    end
                    
                    app.acqStatusTxt.Value = 'Retrieving data...';
                    
                    [app.PupilStruct.acqFrames, app.PupilStruct.acqtimeStamp] = getdata(app.PupilStruct.camobj);
                    
                    % analysis of the pupil size (can be skipped)
                    if app.skipPupilAcqCk.Value == 0
                        
                        diametros = zeros(size(app.PupilStruct.acqFrames,4),1);
                        
                        % Loop to extract the pupil segment from each acquired frame
                        for i=1:size(app.PupilStruct.acqFrames,4)
                            [diametros(i),~,~] = pupilSegment(app, app.PupilStruct.acqFrames(:,:,:,i));
                        end
                        
                        % At the end, the graph is updated with the plot of the pupil dimension
                        app.PupilStruct.hPupilSizePlot = plot(app.PupilGraphUIAxes, app.PupilStruct.acqtimeStamp, diametros);
                    end
                    
                    app.PupilStruct.acqStatus = 0;
                    
                    % Save file
                    app.acqStatusTxt.Value = 'Saving data...';
                    outputPath = [app.SaveFolderEditField.Value,'\',app.PupilFilenameEditField.Value,'_',num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                    while exist(outputPath,'file') == 2
                        app.OutputFileN.Value = app.OutputFileN.Value+1;
                        outputPath = [app.SaveFolderEditField.Value,'\',app.PupilFilenameEditField.Value,'_',num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                        
                    end
%                     outputPath = [app.SaveFolderEditField.Value,'\',app.PupilFilenameEditField.Value,'_',num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                    outputArray = app.PupilStruct.acqFrames;
                    timeStamps = app.PupilStruct.acqtimeStamp;
                    save(outputPath, 'outputArray','timeStamps')
                    app.OutputFileN.Value = app.OutputFileN.Value+1;
                    app.acqStatusTxt.Value = 'Data saved...';
                    % restore the acquisition button
                    app.acqLamp.Color = [0 1 0];
                    app.PupilStartAcquisitionButton.Enable = 1;
            end
        end
        
        function PreviewTimerFcn(app)
            app.PupilStruct.hPupilSizePlot.XData;
            
            % Let's take the approximate time
            timefromstart = etime(clock,app.PupilStruct.previewstarttime); % etime gives the time in seconds
            
            
            if app.skipPupilPrevCk.Value == 0
                app.PupilStruct.hRoi.Visible = 'on';
                [diametros,boundingBox,piel] = pupilSegment(app, app.PupilStruct.hPreviewImage.CData);
                
                app.PupilStruct.hImage2.CData = uint8(piel);
                
                if ~isnan(diametros)
                    %     colormap(handles.image1_ax,gray) % (eye,hsv)
                    app.PupilStruct.hRoi.Position = boundingBox;
                    app.PupilStruct.hPupilSizePlot.XData = [app.PupilStruct.hPupilSizePlot.XData(2:end),timefromstart];
                    xlim(app.PupilGraphUIAxes, [-inf inf])
                    app.PupilStruct.hPupilSizePlot.YData = [app.PupilStruct.hPupilSizePlot.YData(2:end),diametros];
                    %ylim(handles.graph_ax, [0 inf])
                end
            else
                app.PupilStruct.hRoi.Visible = 'off';
            end
        end
        
        function PcoPreviewTimerFcn(app)
            PcoAcquire(app,app.PcoStruct.imah,[],0)
            app.PcoStruct.preview_status = 0;
        end
        
        function PcoAcqTimerFcn(app,hImage,HermesMsg)
            PcoAcquire(app,hImage,HermesMsg,1)
            app.PcoStruct.acq_status = 0;
            app.PcoStartAcquisitionButton.Text = 'START ACQUISITION';
            drawnow
        end
        
        function PreviewInitialize(app)
            % Initialize the preview plot
            app.PupilStruct.hPupilSizePlot = plot(app.PupilGraphUIAxes, -(0:(app.PupilStruct.previewgraph_size-1))', NaN(app.PupilStruct.previewgraph_size,1)); % Initialize
            app.PupilGraphUIAxes.XLimMode = 'manual';
            app.PupilGraphUIAxes.YLimMode = 'auto';
            xlabel(app.PupilGraphUIAxes,'Time (s)')
            ylabel(app.PupilGraphUIAxes,'Pupil Diameter (pixels)')
            
            preview(app.PupilStruct.camobj, app.PupilStruct.hPreviewImage) % Start the preview on the image "hPreviewImage"
            app.PupilImage2UIAxes.Colormap = [0,0,0; 1,1,1]; % This is a binary image of the pupil. Don't know why, this works only in here (not in selecformat)
            app.PupilStruct.previewstarttime = clock;
            
            % Creating the preview timer
            % This is a timer object for the preview that executes at a high rate.
            % The busymode 'drop' makes sure that if the preview rate is too high, the
            % timer execution is just dropped and it doesn't clog anything
            app.PupilStruct.PreviewTimer = timer('BusyMode','drop','ExecutionMode','fixedRate','Period',0.5,'StartDelay',0.001,'TimerFcn',@(~,~) PreviewTimerFcn(app));
            
            start(app.PupilStruct.PreviewTimer)
        end
        
        function PreviewStop(app)
            closepreview % close the preview image
            stop(app.PupilStruct.PreviewTimer) % Stop the preview timer
            delete(app.PupilStruct.PreviewTimer) % Delete the preview timer
        end
        
        function UdpDataReceivedCallback(app,obj)
            MsgIn = fscanf(obj);
%             Remove any white-space character.
            MsgIn = regexprep(MsgIn,'\s','');

            MsgCellArray = strsplit(MsgIn,',');
%             The first element of the cell array contains usually the MAC
%             address of the slave, but when the connection is started it
%             contains the word 'start'.
            if strcmp(MsgCellArray{1},'start')
                if strcmp(MsgCellArray{5},'0') && strcmp(MsgCellArray{2},app.SlaveIp.Value) &&...
                        strcmp(MsgCellArray{4},num2str(app.SlavePort.Value))
%                     This is the MsgCellArray{5} == 0 means that no
%                     confirmation is required for the master. The
%                     connection will be started as it is. Unless these
%                     checks are false.
    %                 The timeout timer must be stopped and deleted.
                    stop(app.UdpTimeoutTimer)
                    delete(app.UdpTimeoutTimer)
                elseif strcmp(MsgCellArray{5},'1') && strcmp(MsgCellArray{4},num2str(app.SlavePort.Value)) &&...
                        ValidateIPv4(MsgCellArray{2}) && ValidateMac(MsgCellArray{3})
%                     This is if the MsgCellArray{5} == 1, which means that
%                     a confirmation is required for the master to start
%                     this connection.
                    selection = uiconfirm(app.EscherMasterFig,['Accept connection from IP ',...
                        MsgCellArray{2},' and MAC ',MsgCellArray{3},'?'],'Connection confirmation');
                    if strcmp(selection,'Cancel')
                        return
                    end
                    app.SendToAllTerminator = 1;
    %                 The timeout timer must be stopped and deleted.
                    stop(app.UdpTimeoutTimer)
                    delete(app.UdpTimeoutTimer)
    %                 The second and third word contain the IP and MAC
    %                 respectively.
                    app.SlaveIp.Value = MsgCellArray{2};
                    
                    app.UdpObj.RemoteHost = MsgCellArray{2};
                else
                    return
                end
%                 The second and third word contain the IP and MAC
%                 respectively.
                app.SlaveMac.Value = MsgCellArray{3};
                
%                 The connection is set, so the buttons and other stuff must be changed
%                 accordingly.
                app.ConnectionStatus.Value = 'Connected.';
                app.ResendScreenParams.Enable = 1;
                app.UdpConnectionFlag = 1;
                app.UDPConnectionLamp.Color = [0 1 0];
%                 The button used for starting the connection now is used
%                 for canceling the connection.
                app.StartUdpBtn.Text = 'Stop Connection';
                EnableGoBtn(app,1)
                
            elseif strcmp(MsgCellArray{1},app.SlaveMac.Value)
%                 The timeout timer must be stopped and deleted.
                stop(app.UdpTimeoutTimer)
                delete(app.UdpTimeoutTimer)
                
                switch MsgCellArray{2}
                    %                     The second word in the message contains the
                    %                     instruction.
                    case 'screenok'
                      % Remember to set the fields relative to the screen size on the GUI and the
                      % relative app.variable.
                        app.WidthPix.Value = str2double(MsgCellArray{3});
                        app.HeightPix.Value = str2double(MsgCellArray{4});
                        
                        app.OpenScreenBtn.Text = 'Close Screen';
                        app.ScreenOpenFlag = 1;
                    case 'wait'
%                         A timer for timeout has to be called. Before that, the
%                         current timer is stopped and deleted.
                        if isvalid(app.UdpTimeoutTimer)
                            stop(app.UdpTimeoutTimer)
                        end
                        delete(app.UdpTimeoutTimer) % maybe it's an overkill?
                        app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                            str2double(MsgCellArray{3})+app.TimeoutTime.Value,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
                    case 'stimok'
%                         Receive timestamps of all stimuli and prints them
%                         into current log file.
                        app.CurrentLogStruct.T.Timestamps(1)=MsgCellArray{3};
                        writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%                         The stimulus has been performed, therefore the
%                         master will stop the timer and another stimulation can start.
                        EnableGoBtn(app,1)
                    case 'busy'
%                         If the slave is busy, the timer for timeout must
%                         be stopped and the master can continue listening.
                        disp('Slave is busy.')
                    case 'ok'
%                         If the slave is busy, the timer for timeout must
%                         be stopped and the master can continue listening.
                        disp('Slave is ok.')
                    case 'calibrationok'
            %             If Hermes is connected to Escher with serial communication
                        if app.HermesSerialFlag
%                             Sends a message to Hermes, that is either
%                             'c,b' or 'c,w' for black or white
%                             calibration.
                            writeline(app.HermesSerialObj,['c,',MsgCellArray{3}]);
                        end
                    otherwise
                        warning(['Message not recognized: ', MsgIn])
                end
            end
        end
        
        function UdpTimeoutCallback(app,obj)
%             This is called only when a message is sent through UDP but no
%             answer is received.
            uialert(app.EscherMasterFig,'UDP connection lost.','Error')
%             The connection status is set to 1, so that the button to
%             cancel the connection is called.
            app.UdpConnectionFlag = 1;
            StartUdpBtnButtonPushed(app,[])
%             Similarly, the ScreenOpenFlag is set to 1 and "Close Screen"
%             button is pushed.
            app.ScreenOpenFlag = 1;
            OpenScreenBtn_Callback(app, [])
            
%             Then, the timer is stopped and deleted.
            stop(obj)
            delete(obj)
        end
        
        function SendUdpMsg(app,msg,TimeoutTime)
%             A timer for timeout has to be called. Before that, the
%             current timer is stopped and deleted.
            if isvalid(app.UdpTimeoutTimer)
                stop(app.UdpTimeoutTimer)
            end
            delete(app.UdpTimeoutTimer) % maybe it's an overkill?
            app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                TimeoutTime,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
%             The timer is started before sending the UDP message. This is
%             to avoid that a UDP answer arrives (and stops the timer)
%             before the timer is started, since the two objects are
%             asynchronous.
            start(app.UdpTimeoutTimer)
            readasync(app.UdpObj)
            app.UdpObj.ReadAsyncMode = 'continuous';
            fprintf(app.UdpObj,msg);
        end
        
        function msg = ScreenParameterMsg(app)
            msg=strjoin([{app.MasterMac.Value},{'screen'},{'1'},...
                arrayfun(@num2str,[app.ScreenDistance.Value,app.ScreenWidth.Value,...
                app.ScreenHeight.Value,app.CalibratedButton.Value],'un',0),...
                {app.ScreenFuncTxt.Value},arrayfun(@num2str,[app.ard_ck.Value,app.wwTxt.Value,...
                app.hhTxt.Value,app.optDtrTimeTxt.Value,app.CueTopRB.Value,...
                app.CueLeftRB.Value,app.OneScreenMode_ck.Value,app.oculusCk.Value],'un',0)],',');
        end
        
        function PcoInitialize(app)
%             Close all PCO connections
            PcoClose(app)
            
            exposure_time = app.ExposureTimeEditField.Value;
            
            app.PcoStruct.glvar=struct('do_libunload',1,'do_close',0,'camera_open',0,'out_ptr',[]);
                        
            pco_camera_load_defines();
            
            [errorCode,app.PcoStruct.glvar] = pco_camera_open_close(app.PcoStruct.glvar);
            pco_errdisp('pco_camera_setup',errorCode);
            % disp(['camera_open should be 1 is ',int2str(glvar.camera_open)]);
            if errorCode~=PCO_NOERROR
            %  commandwindow;
             return;
            end
            
            app.PcoStruct.out_ptr=app.PcoStruct.glvar.out_ptr;
            
            app.PcoStruct.subfunc=pco_camera_mysubfunction();
            
            app.PcoStruct.act_recstate = uint16(10);
            [errorCode,~,app.PcoStruct.act_recstate] = calllib('PCO_CAM_SDK', 'PCO_GetRecordingState',...
                app.PcoStruct.out_ptr,app.PcoStruct.act_recstate);
            if errorCode
              pco_errdisp('PCO_GetRecordingState',errorCode);
            % else
            %  disp(['actual recording state is ',int2str(act_recstate)]);
            end
            
            %stop camera
            app.PcoStruct.subfunc.fh_stop_camera(app.PcoStruct.out_ptr);
            
            app.PcoStruct.cam_desc = libstruct('PCO_Description');
            set(app.PcoStruct.cam_desc,'wSize',app.PcoStruct.cam_desc.structsize);
            [errorCode,~,app.PcoStruct.cam_desc] = calllib('PCO_CAM_SDK', 'PCO_GetCameraDescription', app.PcoStruct.out_ptr,app.PcoStruct.cam_desc);
            pco_errdisp('PCO_GetCameraDescription',errorCode);
            app.PcoStruct.bitpix = uint16(app.PcoStruct.cam_desc.wDynResDESC);
            
            %set bitalignment LSB
            app.PcoStruct.bitalign = uint16(BIT_ALIGNMENT_LSB);
            errorCode = calllib('PCO_CAM_SDK', 'PCO_SetBitAlignment', app.PcoStruct.out_ptr,app.PcoStruct.bitalign);
            pco_errdisp('PCO_SetBitAlignment',errorCode);
            
%             Set the recorder submode
%             It is either sequence or ring buffer
            errorCode = calllib('PCO_CAM_SDK', 'PCO_SetRecorderSubmode',app.PcoStruct.out_ptr,app.PcoStruct.SelRecSubmode);
            pco_errdisp('PCO_SetRecorderSubmode',errorCode);
            
%             Removing noise filter
            errorCode = calllib('PCO_CAM_SDK', 'PCO_SetNoiseFilterMode',app.PcoStruct.out_ptr,uint16(0));
            pco_errdisp('PCO_SetNoiseFilterMode',errorCode);
            
%             Removing hot pixel correction
            errorCode = calllib('PCO_CAM_SDK', 'PCO_SetHotPixelCorrectionMode',app.PcoStruct.out_ptr,uint16(0));
            pco_errdisp('PCO_SetHotPixelCorrectionMode',errorCode);
            
            app.PcoStruct.GENERALCAPS1_NO_TIMESTAMP = GENERALCAPS1_NO_TIMESTAMP;
            
            % Enable ASCII and binary timestamp
            if bitand(app.PcoStruct.cam_desc.dwGeneralCapsDESC1,app.PcoStruct.GENERALCAPS1_NO_TIMESTAMP)==0
             app.PcoStruct.subfunc.fh_enable_timestamp(app.PcoStruct.out_ptr,TIMESTAMP_MODE_BINARYANDASCII);
            end
            
            % Set default Pixelrate.
            app.PcoStruct.subfunc.fh_set_pixelrate(app.PcoStruct.out_ptr,1);
            
            % Set acquisition mode to 0.
%             0 is auto, 1 is external, 2 is external modulate.
            ReservedPtr = libpointer('voidPtr');
            errorCode = calllib('PCO_CAM_SDK', 'PCO_SetAcquireModeEx',app.PcoStruct.out_ptr,uint16(0),uint32(0),ReservedPtr);
            pco_errdisp('PCO_SetAcquireModeEx',errorCode);
            
%             For the initialization, triggermode must be zero.
            triggermode = uint16(0);
            %set triggermode.
            app.PcoStruct.subfunc.fh_set_triggermode(app.PcoStruct.out_ptr,triggermode);
            
%             Set exposure time
            app.PcoStruct.subfunc.fh_set_exposure_times(app.PcoStruct.out_ptr,exposure_time,2,0,2);
            
            % PCO recommends the following order of commands:
            % PCO_SetBinning, PCO_SetROI, PCO_ArmCamera, PCO_GetSizes and PCO_AllocateBuffer
            calllib('PCO_CAM_SDK','PCO_SetBinning',app.PcoStruct.out_ptr,uint16(4),uint16(4)); %bin to 4
            calllib('PCO_CAM_SDK','PCO_SetROI',app.PcoStruct.out_ptr,uint16(1),uint16(1),uint16(512),uint16(512)); %resolution 512x512
            
            errorCode = calllib('PCO_CAM_SDK', 'PCO_ArmCamera', app.PcoStruct.out_ptr);
            pco_errdisp('PCO_ArmCamera',errorCode);
            
            % If PCO_ArmCamera does fail no images can be grabbed
            if(errorCode~=PCO_NOERROR)
             return;
            end 
            
            %adjust transfer parameter if necessary
            app.PcoStruct.subfunc.fh_set_transferparameter(app.PcoStruct.out_ptr);
            
            %get actual image time and maximal frequency
            imatime=app.PcoStruct.subfunc.fh_get_frametime(app.PcoStruct.out_ptr);
            
%             triggermode=app.PcoStruct.subfunc.fh_get_triggermode(app.PcoStruct.out_ptr);
            
            app.PcoStruct.act_xsize=uint16(0);
            app.PcoStruct.act_ysize=uint16(0);
            ccd_xsize=uint16(0);
            ccd_ysize=uint16(0);
            
            %use PCO_GetSizes because this always returns accurate image size for next recording
            [errorCode,~,app.PcoStruct.act_xsize,app.PcoStruct.act_ysize]  = calllib('PCO_CAM_SDK', 'PCO_GetSizes',...
                app.PcoStruct.out_ptr,app.PcoStruct.act_xsize,app.PcoStruct.act_ysize,ccd_xsize,ccd_ysize);
            if errorCode
             pco_errdisp('PCO_GetSizes',errorCode);
            end
            
            errorCode = calllib('PCO_CAM_SDK', 'PCO_CamLinkSetImageParameters', app.PcoStruct.out_ptr,app.PcoStruct.act_xsize,app.PcoStruct.act_ysize);
            if errorCode
             pco_errdisp('PCO_CamLinkSetImageParameters',errorCode);   
            end
            
            % disp(['sizes: horizontal ',int2str(act_xsize),' vertical ',int2str(act_ysize)]);
            
            
%             Getting ready for the pre-image. Creating the buffers.
            %allocate memory for display, 4 buffers are used 
            app.PcoStruct.bufcount = app.PcoBufferNumEditField.Value;
            imas = uint32(fix((double(app.PcoStruct.bitpix)+7)/8));
            imas = imas*uint32(app.PcoStruct.act_xsize)*uint32(app.PcoStruct.act_ysize); 
            imasize = imas;
            lineadd=0;
            
            image_stack = zeros(app.PcoStruct.act_xsize,(app.PcoStruct.act_ysize+lineadd),app.PcoStruct.bufcount,'uint16');
            
            %Allocate n SDK buffer and set address of buffers from image_stack
            app.PcoStruct.sBufNr = zeros(1,app.PcoStruct.bufcount,'int16');
            ev_ptr(app.PcoStruct.bufcount) = libpointer('voidPtr');
            app.PcoStruct.im_ptr(app.PcoStruct.bufcount) = libpointer('voidPtr');
             
            for n=1:app.PcoStruct.bufcount
             sBufNri = int16(-1);
             app.PcoStruct.im_ptr(n) = libpointer('uint16Ptr',image_stack(:,:,n));
             ev_ptr(n) = libpointer('voidPtr');
             
             [errorCode,~,sBufNri] = calllib('PCO_CAM_SDK','PCO_AllocateBuffer',...
                 app.PcoStruct.out_ptr,sBufNri,imasize,app.PcoStruct.im_ptr(n),ev_ptr(n));
             if errorCode
              pco_errdisp('PCO_AllocateBuffer',errorCode);
              clear image_stack;
              return;
             end
             app.PcoStruct.sBufNr(n)=sBufNri;
            end
            
            figure(app.EscherMasterFig)
            
            %show figure
            ima=image_stack(:,:,1);
            ima=ima';
            
            colormap(app.PcoHAxes,'gray');
            colormap(app.PcoHAxesStim,'gray');
            set(app.PcoHAxes,'YDir','reverse','DataAspectRatio',[1 1 1]);
            set(app.PcoHAxesStim,'YDir','reverse','DataAspectRatio',[1 1 1]);
            app.PcoStruct.imah = image(ima,'Parent',app.PcoHAxes,'CDataMapping','scaled');
            app.PcoStruct.imahStim = image(ima,'Parent',app.PcoHAxesStim,'CDataMapping','scaled');
            
            set(app.PcoHAxes,'CLim',[app.PcoCDataMinEditField.Value, app.PcoCDataMaxEditField.Value]);
            set(app.PcoHAxesStim,'CLim',[app.PcoCDataStimMinEditField.Value, app.PcoCDataStimMaxEditField.Value]);
            
            pause(0.5);
            
%             Start the PCO for the pre-image
            %grab preimage to get actual image value range and set limits
            app.PcoStruct.subfunc.fh_start_camera(app.PcoStruct.out_ptr);
            
            errorCode  = calllib('PCO_CAM_SDK','PCO_AddBufferEx',...
                app.PcoStruct.out_ptr,0,0,app.PcoStruct.sBufNr(1),app.PcoStruct.act_xsize,app.PcoStruct.act_ysize,app.PcoStruct.bitpix);
            if errorCode
             pco_errdisp('PCO_AddBufferEx',errorCode);
            end
            
            % disp('get pre images');
            trigdone=int16(1);
            % This will be skipped because the triggermode is zero in preview.
            if triggermode==1 || triggermode==2
             [errorCode,~,trigdone]  = calllib('PCO_CAM_SDK','PCO_ForceTrigger',app.PcoStruct.out_ptr,trigdone);
             if errorCode
              pco_errdisp('PCO_ForceTrigger',errorCode);
            %  else
            %   disp([int2str(trigdone),' trigger done return: ',int2str(trigdone)]);
             end
            elseif triggermode>2
            %  disp('send external trigger pulse within 1 second');
             pause(0.001);
            end
            
            app.PcoStruct.buflist_1=libstruct('PCO_Buflist');
            set(app.PcoStruct.buflist_1,'sBufnr',int16(app.PcoStruct.sBufNr(1)));
            
            [errorCode,~,app.PcoStruct.buflist_1]  = calllib('PCO_CAM_SDK','PCO_WaitforBuffer', app.PcoStruct.out_ptr,1,app.PcoStruct.buflist_1,1000);
            if errorCode
             pco_errdisp('PCO_WaitforBuffer',errorCode);
            % else
            %  disp(['PCO_WaitforBuffer done bufnr: ',int2str(sBufNr(1))]);   
            end 
            
            if((bitand(app.PcoStruct.buflist_1.dwStatusDll,hex2dec('00008000')))&&(app.PcoStruct.buflist_1.dwStatusDrv==0))
            %  disp(['Event buf',int2str(1),' pre image done, StatusDrv ',num2str(buflist_1.dwStatusDrv,'%08X')]);
            %get data and show image   
             ima=get(app.PcoStruct.im_ptr(1),'Value');
             if(bitand(app.PcoStruct.cam_desc.dwGeneralCapsDESC1,app.PcoStruct.GENERALCAPS1_NO_TIMESTAMP)==0)
              app.PcoStruct.subfunc.fh_print_timestamp(ima,app.PcoStruct.bitalign,app.PcoStruct.bitpix);
             end
             ima=ima';
%              m=max(max(ima(10:end-10,10:end-10)));
             set(app.PcoHAxes,'CLim',[app.PcoCDataMinEditField.Value, app.PcoCDataMaxEditField.Value]);
            %  disp(['pre image done maxvalue: ',int2str(m)]);   
             set(app.PcoStruct.imah,'CData',ima,'CDataMapping','scaled'); 
             pause(0.0001);
            end
            
            app.PcoStruct.subfunc.fh_stop_camera(app.PcoStruct.out_ptr);
            
            %this will remove all pending buffers in the queue and does reset grabber
            errorCode = calllib('PCO_CAM_SDK', 'PCO_CancelImages', app.PcoStruct.out_ptr);
            pco_errdisp('PCO_CancelImages',errorCode);
            
%             When PCO camera is armed, the corresponding flag is set.
            app.PcoStruct.ArmedFlag = 1;
            app.PCOarmedLamp.Color = [0,1,0];
            app.PCOunarmedLabel.Text = 'PCO armed';
%             And the preview and acquire button are enabled
            app.PcoStartAcquisitionButton.Enable = 1;
            app.PcoStartPreviewButton.Enable = 1;
            
%             Now, set the possible frequencies.
            app.PcoMaxFreqEditField.Value = 1/imatime;
            
%             This will set also the selected frequency according to the
%             maximum possible frequency.
            PcoSyncDropDownValueChanged(app, [])
        end
        
        
        function PcoAcquire(app,hImage,AcqFlag,AcqMode)
            % AcqFlag == 0 => preview
            % AcqFlag == 1 => acquisition
                        
            if AcqFlag==1
                %  If this is in recording mode, initialize an array to save the image
                %  stack.
                 switch app.PcoStruct.bitpix
                     case 8
                         pixeltype = 'uint8';
                     case 16
                         pixeltype = 'uint16';
                     case 32
                         pixeltype = 'uint32';
                 end
                 %calculate images to grab
                 nr_of_images = app.PcoNumberFramesEditField.Value;
                % disp(['maximal ',num2str(nr_of_images),' images will be grabbed in ',num2str(looptime),' seconds' ]);
                 app.PcoStruct.ImageStack = zeros(app.PcoStruct.act_xsize,app.PcoStruct.act_ysize,nr_of_images,pixeltype);
    %              The preview during the acquisition will only show one frame
    %              every "PreviewFrameNum" number of frames. This is to make
    %              the acquisition faster.
                 PreviewFrameNum = app.PcoNFramesInPreview.Value;
    %              Trigger Mode is set with the value selected in the
    %              synchronization mode of the PCO: Escher-only means trigger
    %              mode 0, Hermes means trigger mode 2.
                 triggermode = app.PcoStruct.SelTriggerMode;
                 
    %              If Hermes is selected as sync-mode, then a message is sent.
                 switch app.PcoStruct.SelSyncMode
%                      Timeout time for camera is different if Hermes is
%                      selected.
                     case 1
                         timeouttime = app.PcoDurationEditField.Value+app.PcoTimeoutEditField.Value;
                     case 2
    %                      If Hermes is used, the timeout time is longer.
                         timeouttime = app.HermesInitialDelaysEditField.Value+...
                             app.PcoDurationEditField.Value+app.PcoTimeoutEditField.Value;
                 end

                 
            else
    %                  If this is a preview, trigger mode is set to zero.
                 triggermode = uint16(0);
    %                  Number of images is set to infinite.
                 nr_of_images = Inf;
    %                  Acquire one frame every PreviewFrameNum frames
                 PreviewFrameNum = app.PcoNFramesInPreview.Value;
            end
%             set triggermode
            app.PcoStruct.subfunc.fh_set_triggermode(app.PcoStruct.out_ptr,triggermode);
%             Arm camera and transfer parameters. This is very important.
            errorCode = calllib('PCO_CAM_SDK', 'PCO_ArmCamera', app.PcoStruct.out_ptr);
            pco_errdisp('PCO_ArmCamera',errorCode);
            %if PCO_ArmCamera does fail no images can be grabbed
            %adjust transfer parameter if necessary
            app.PcoStruct.subfunc.fh_set_transferparameter(app.PcoStruct.out_ptr);
            
%             Start the camera. This starts the recoding.
            app.PcoStruct.subfunc.fh_start_camera(app.PcoStruct.out_ptr);
                        
%             setup loop
            for n=1:app.PcoStruct.bufcount
             errorCode = calllib('PCO_CAM_SDK','PCO_AddBufferEx',...
                 app.PcoStruct.out_ptr,0,0,app.PcoStruct.sBufNr(n),app.PcoStruct.act_xsize,app.PcoStruct.act_ysize,app.PcoStruct.bitpix);
             if errorCode
              pco_errdisp('PCO_AddBufferEx',errorCode);
             end
            end
            
            [errorCode,~,app.PcoStruct.act_recstate] = calllib('PCO_CAM_SDK', 'PCO_GetRecordingState',app.PcoStruct.glvar.out_ptr,app.PcoStruct.act_recstate);
            if errorCode
              pco_errdisp('PCO_GetRecordingState',errorCode);
            % else
            %  disp(['Actual recording state is ',int2str(act_recstate)]);
            end
            
            if app.PcoStruct.act_recstate==1
            %  disp('get images');
            
%              This part for the moment is useless: trigger mode can only
%              be 0 or 2, for the moment.
             trigdone=int16(1);
             trigcount=0;
             if triggermode==1
              [errorCode,~,trigdone]  = calllib('PCO_CAM_SDK','PCO_ForceTrigger',app.PcoStruct.out_ptr,trigdone);
              if errorCode
               pco_errdisp('PCO_ForceTrigger',errorCode);
              else
               trigcount=trigcount+1;
               disp(['first trigger done return: ',int2str(trigdone)]);
              end
             elseif triggermode>=2
%               disp('send external trigger pulses within 1 second');
             end
             
             pause(0.0001);
             
%              If Hermes is selected as sync-mode, then a message is sent.
             if app.PcoStruct.SelSyncMode == 2
                 if app.HermesSerialFlag
                    HermesMsgMake(app,'parameters','synch')
                 end
             end
             
%              Start of the acquisition.
             tic
             ima_nr=1;
            %grab and display loop
             while ima_nr<=nr_of_images
%               n is the index of the current buffer.
              n = mod(ima_nr-1,app.PcoStruct.bufcount)+1;
            %wait for buffers
              app.PcoStruct.buflist_1.sBufNr = app.PcoStruct.sBufNr(n);
              [errorCode,~,app.PcoStruct.buflist_1]  = calllib('PCO_CAM_SDK','PCO_WaitforBuffer', app.PcoStruct.out_ptr,1,app.PcoStruct.buflist_1,6000);
              if errorCode
%                pco_errdisp('PCO_WaitforBuffer',errorCode); this needs to
%                be fixed
               disp(errorCode)
               break
              end
               
            %first image done trigger next. This only works for trigger
            %mode 1, so for the moment it may be useless.
              if triggermode==1
               [errorCode,~,trigdone]  = calllib('PCO_CAM_SDK','PCO_ForceTrigger',app.PcoStruct.out_ptr,trigdone);
               if errorCode
                pco_errdisp('PCO_ForceTrigger',errorCode);
               else
                trigcount = trigcount+1;
               end
              end
              
            %test and display buffer
              if bitand(app.PcoStruct.buflist_1.dwStatusDll,hex2dec('00008000'))&&app.PcoStruct.buflist_1.dwStatusDrv==0
            %get data and show image
               ima = get(app.PcoStruct.im_ptr(n),'Value');
                
            %     if(rem(ima_nr,d)==0)
            %      disp(['Event buf',int2str(n),' image ',int2str(ima_nr),' done, StatusDrv ',num2str(buflist_1.dwStatusDrv,'%08X')]);
            %      if(bitand(cam_desc.dwGeneralCapsDESC1,GENERALCAPS1_NO_TIMESTAMP)==0)
            %       subfunc.fh_print_timestamp(ima,bitalign,bitpix);
            %      end
            %     end
               ima = ima';
                
%                 Reducing the number of frames that are actually
%                 displayed.
               if mod(ima_nr,PreviewFrameNum) == 0
                   set(hImage,'CData',ima,'CDataMapping','scaled');
               end
                
               pause(0.0001);
               errorCode = calllib('PCO_CAM_SDK','PCO_AddBufferEx',...
                   app.PcoStruct.out_ptr,0,0,app.PcoStruct.sBufNr(n),app.PcoStruct.act_xsize,app.PcoStruct.act_ysize,app.PcoStruct.bitpix);
               if errorCode
                pco_errdisp('PCO_AddBufferEx',errorCode);
                break;
               end
               if AcqFlag == 1
            %         If it is in acquisition mode, save the images to an image stack.
                   app.PcoStruct.ImageStack(:,:,ima_nr) = ima;
                   t=toc;
                   if t>timeouttime || app.PcoStruct.acq_status == 0
%                      If the maximum time is reached, exits the loop. Only
%                      for acquisition mode.
                    break;
                   end
               else
%                    AcqFlag is equal to 0.
%                    If the preview button has been pushed, the preview is
%                    stopped.
                    if app.PcoStruct.preview_status == 0
                        break
                    end
               end
              else
               break;
              end
              ima_nr = ima_nr+1;
             end % end of the outer loop (while)
%             Acquisition is finished!
             
             app.PcoStruct.subfunc.fh_stop_camera(app.PcoStruct.out_ptr);
            %this will remove all pending buffers in the queue
             errorCode = calllib('PCO_CAM_SDK', 'PCO_CancelImages', app.PcoStruct.out_ptr);
             pco_errdisp('PCO_CancelImages',errorCode);
            
%              This was in the example code. It might be not necessary.
%              Probably to be sure that the buffer queue was really empty.
             errorCode = calllib('PCO_CAM_SDK','PCO_WaitforBuffer', app.PcoStruct.out_ptr,1,app.PcoStruct.buflist_1,100);
             pco_errdisp('PCO_WaitforBuffer',errorCode);
             
             pause(1)
             
%              This also was in the example. Better safe than sorry.
             app.PcoStruct.subfunc.fh_stop_camera(app.PcoStruct.out_ptr);
             
            %  disp(['Last image ',int2str(ima_nr),' done ']);
            %  disp([int2str(ima_nr),' images done in ',num2str(t),' seconds. time per image is ',num2str(t/double(ima_nr),'%.3f'),'s']);  
             if bitand(app.PcoStruct.cam_desc.dwGeneralCapsDESC1,app.PcoStruct.GENERALCAPS1_NO_TIMESTAMP)==0
%               app.PcoStruct.subfunc.fh_print_timestamp_t(ima,app.PcoStruct.bitalign,app.PcoStruct.bitpix);
              if AcqFlag==1
                  TimestampsTxt = cell(size(app.PcoStruct.ImageStack,3),1);
                  TimestampsDate = NaT(size(app.PcoStruct.ImageStack,3),1);
                  app.PcoStruct.ImgCounter = zeros(size(app.PcoStruct.ImageStack,3),1);
                  for i = 1:size(app.PcoStruct.ImageStack,3)
                      [TimestampsTxt{i},TimestampsDate(i),app.PcoStruct.ImgCounter(i)]=app.PcoStruct.subfunc.fh_print_timestamp_t(app.PcoStruct.ImageStack(:,:,i),...
                          app.PcoStruct.bitalign,app.PcoStruct.bitpix);
                  end
                  app.PcoStruct.TimestampsTxt = TimestampsTxt;
                  app.PcoStruct.Timestamps = seconds(TimestampsDate - TimestampsDate(1));
                  
                  if app.PcoSavefileCk.Value
                    % Save file
                    outputPath = [app.PcoSaveFolderEditField.Value,'\',app.PcoFilenameEditField.Value,'-',num2str(app.PcoOutputFileN.Value,'%03.f') '.mat'];
                    while exist(outputPath,'file') == 2
                        app.PcoOutputFileN.Value = app.PcoOutputFileN.Value+1;
                        outputPath = [app.PcoSaveFolderEditField.Value,'\',app.PcoFilenameEditField.Value,'-',num2str(app.PcoOutputFileN.Value,'%03.f') '.mat'];
                        
                    end
                    
                    OutPcoStruct = struct;
                    OutPcoStruct.Images = app.PcoStruct.ImageStack;
                    OutPcoStruct.ImgCounter = app.PcoStruct.ImgCounter;
                    OutPcoStruct.Timestamps = app.PcoStruct.Timestamps;
                    OutPcoStruct.TimestampsTxt = TimestampsTxt;
                    
                    save(outputPath, 'OutPcoStruct')
                    app.PcoOutputFileN.Value = app.PcoOutputFileN.Value+1;
                  end
                  
              else
                  [txt,~]=app.PcoStruct.subfunc.fh_print_timestamp_t(ima,...
                      app.PcoStruct.bitalign,app.PcoStruct.bitpix);
                  disp(txt)
              end
             end


            %  disp('Press "Enter" to close window and proceed')
            %  pause();
            %  close();
             pause(0.1);
            end
            
        end
        
        function PcoClose(app)
            if isfield(app.PcoStruct,'bufcount')
                try
                    for n=1:app.PcoStruct.bufcount
                     errorCode = calllib('PCO_CAM_SDK','PCO_FreeBuffer',app.PcoStruct.out_ptr,app.PcoStruct.sBufNr(n));
                     if(errorCode)
                      pco_errdisp('PCO_FreeBuffer',errorCode);
                     else
                      disp(['PCO_FreeBuffer',num2str(n),' done ']);
                     end
                    end
                catch ME
                    disp(ME)
                end
            end
            
            if isfield(app.PcoStruct,'glvar')
                if(app.PcoStruct.glvar.camera_open==1)
                 app.PcoStruct.glvar.do_close=1;
                 app.PcoStruct.glvar.do_libunload=1;
                 try
                    pco_camera_open_close(app.PcoStruct.glvar);
                 catch ME
                     disp(ME)
                 end
                end
            end
%             When PCO camera is armed, the corresponding flag is set.
            app.PcoStruct.ArmedFlag = 0;
            app.PCOarmedLamp.Color = [1,0,0];
            app.PCOunarmedLabel.Text = 'PCO unarmed';
%             And the acquire button is disabled
            app.PcoStartAcquisitionButton.Enable = 0;
            
%             Frequency of acquisition is reset.
            app.PcoMaxFreqEditField.Value = 0;
            app.PcoSelectedFreqEditField.Limits = [0,Inf];
        end
                                
        function SetScreenSizeAngle(app)
            % called after any changes of the screen geometry or position.
            WAngle = round(atand(app.ScreenWidth.Value/app.ScreenDistance.Value));
            HAngle = round(atand(app.ScreenHeight.Value/app.ScreenDistance.Value));
            Sz = [int2str(WAngle) '° x ' int2str(HAngle) '°'];
            app.ScreenSizeAngle.Value = Sz;
        end
        
        function HermesConnect(app,COMport)
            if ~strcmp(COMport,'NONE')
                app.HermesSerialObj = serialport(COMport,57600);
                app.HermesSerialObj.Timeout = 1; % the default value is 10 seconds.
%                 Set and read
                app.HermesSerialObj.DataBits = 8; % default is 8
                app.HermesSerialObj.StopBits = 1; % default is 1
                app.HermesSerialObj.Parity = 'none'; % default is none
                app.HermesSerialObj.BytesAvailableFcnMode = 'terminator'; % default is off
                app.HermesSerialObj.BytesAvailableFcn = @(obj,~) HermesMsgReceivedFcn(app,obj);
                app.HermesReplyCheck = 0;
% %                 Open the communication
%                 fopen(app.HermesSerialObj);
% %                 readasync(app.HermesSerialObj)
%                 app.HermesSerialObj.ReadAsyncMode = 'continuous';
                
                tic
                t=0;
    %             Now wait up to 3 seconds for a reply from Hermes.
    %             A reply will set app.HermesReplyCheck to 1 vAia the
    %             ByteAvailableFcn of the Hermes Object.
                while t<3 && ~app.HermesReplyCheck
                    t=toc;
                end
                if ~app.HermesReplyCheck
                    warning(['No reply received for the initialization.'])
                end
                
                HermesMsgMake(app,'parameters',{'TTL_time','frameP','idleTime','serial_delay'})
                % the default mode for Hermes is classic mode triggered by
                % the phototransistor
                HermesMsgMake(app,'mod','classic','p')
            end
        end
        
        function HermesMsgMake(app,MsgName,MsgValue,varargin)
            
%                 Input analysis:
%                 1)app
%                 2)MsgType  The type of message to be sent to arduino.
%                       It works in 'Name','Value' pairs. Names:
%                    'mod' The value for this is either 'camAc', 'camPr', 'cl' or 'opto'
%                            respectively for: camera acquisition, camera preview, classic or optogenetics
%                    'trig' The value for this is either 'p' or 's'
%                            respectively for 'phototransistor' and 'serial'
%                    'cal' The value for this is either 'w' or 'b'
%                    'stopTrig' Value: 1
%                    'endVisualStim' Value: 1
%                    'parameters' Parameters will be sent. Value is a character
%                      array or cell array containing the name of parameters
%                      to pass.
%                 3)Only when the first name is 'mod' additional parameters are required:
%                    1.The first additional parameter is 'trig' (the kind of synchronization),
%                           either 'p' (phototransistor) or 's' (serial).
%                    The other parameters can be optional and depend on the mod and trig combination
%                    
%                    -if mod = camAc and trig = p
%                         2. The second additional parameter is the number of frames
%                           for each trial.
%
%                    -if mod = camAc and trig = s
%                         2. The second additional parameter is the number of frames
%                             for each trial.
%                         3. The third additional parameter is the number of
%                             trials.
%                         4. The fourth additional parameter is the period between
%                             one trial and the next one.
% 
%                    -if mod = camPr and trig = s
%                         2. The second additional parameter is the number of frames
% 
%                    -if mod = opto and trig = p no additional param is needed    
% 
%                    -if mod = classic and trig = p no additional param is needed  
%
%             Reactivate the asynchronous reading of serial communication.
%             readasync(app.HermesSerialObj)
%             app.HermesSerialObj.ReadAsyncMode = 'manual';
            
            if strcmp(MsgName, 'parameters')
                if ~iscell(MsgValue)
%                     If the parameter is not a cell, then it is just one
%                     parameter.
                    MsgValue = {MsgValue};
                end
                MsgOut = '';
                for i=1:length(MsgValue)
                    switch MsgValue{i}
                        case 'TTL_time'
                            MsgOut = [MsgOut, MsgValue{i},'=',num2str(round(1000*app.HermesTtlDurationEditField.Value))];
                        case 'frameP'
                            MsgOut = [MsgOut, MsgValue{i},'=',num2str(round(1000000/app.PcoSelectedFreqEditField.Value))];
                        case 'idleTime'
                            MsgOut = [MsgOut, MsgValue{i},'=',num2str(round(app.HermesIdleTimeEditField.Value))];
                        case 'serial_delay'
                            MsgOut = [MsgOut, MsgValue{i},'=',num2str(round(1000*app.HermesInitialDelaysEditField.Value))];
                        case 'optoStart'
                            MsgOut = [MsgOut, MsgValue{i},'=',num2str(round(app.optoStartEditField.Value))];
                        case 'optoDur'
                            MsgOut = [MsgOut, MsgValue{i},'=',num2str(round(app.optoDurEditField.Value))];
                            
                        case {'synch', 'stopTrig', 'endVisualStim'}
%                             In case the acquisition has to start.
                            MsgOut = [MsgValue{i},'=1'];
                    end
%                     HermesMsgSend(app,MsgOut)
                end
                %remove the first character, which is a comma
                MsgOut = MsgOut(2:end);
            else
                MsgOut = [MsgName,'=',MsgValue];
                if strcmp(MsgName, 'mod')
                    % the type of trigger is also specified in varargin{1}.
                    tr = varargin{2};
                    % a complete list of combinations follows:
                    switch MsgValue %mode
                        case 'camAc'
                            % acquire with the pco camera.
                            switch tr
                                case 'p' % triggered by the phototransistor
                                    % varargin{2} contains nFrames
                                    % nTrials and trialPeriod depends on the visual stimulus
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin > 3
                                        MsgOut = sprintf(['nFrames=%s,',MsgOut],num2str(varargin{2}));
                                    end
                                case 's' % triggered by serial communication
                                    % varargin{2} contains nFrames
                                    % varargin{3} contains nTrials
                                    % varargin{4} contains trialPeriod
                                    %                                     MsgOut = ['nFrames=',varargin{2},',','nTrials=',varargin{3},',',MsgOut,...
                                    %                                             'trialPeriod=',varargin{4}];
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin>3
                                        MsgOut = sprintf(['nFrames=%s,nTrials=%s,',MsgOut,'trialPeriod=%s'],...
                                            num2str(varargin{2}),num2str(varargin{3}),num2str(varargin{4}));
                                    end
                            end
                            
                        case 'camPr'
                            % preview with the pco camera. Can't be
                            % triggered by the phototransistor: only starts
                            % upon serial communication.
                            if strcmp(tr,'s')
%                                 MsgOut = sprintf(['nFrames=%s,trig=%s,' MsgOut],tr,varargin{2});
                                MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                            else
                                error('Phototransistor synch with preview. ARE YOU SERIOUS?')
                            end
                            
                        case 'opto'
                            % optogenetic stimulation. 
                            switch tr
                                case 'p' % triggered by the phototransistor
                                    % nTrials and trialPeriod depends on the visual stimulus
                                    MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                                case 's' % triggered by serial communication
                                    % varargin{2} contains nTrials
                                    % varargin{3} contains trialPeriod
%                                     MsgOut = ['nFrames=',varargin{2},',','nTrials=',varargin{3},',',MsgOut,...
%                                             'trialPeriod=',varargin{4}];
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin>3
                                        MsgOut = sprintf(['nTrials=%s,',MsgOut,'trialPeriod=%s'],...
                                                        num2str(varargin{2}),num2str(varargin{3}));
                                    end
                            end
                            
                        case 'classic'
                            if strcmp(tr,'o')
                                MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                            else
                                error('Serial synch with classic mode. ARE YOU SERIOUS?')
                            end
                    end
                end
%                 HermesMsgSend(app,MsgOut)
            end
            HermesMsgSend(app,MsgOut)
%             app.HermesSerialObj.BytesAvailableFcn = @(obj,~) ArduinoMsgReceivedFcn(app,obj);
%             readasync(app.HermesSerialObj)
%             app.HermesSerialObj.ReadAsyncMode = 'continuous';
        end
        
        function HermesMsgSend(app,MsgOut)
            % arduino serial buffer is limited to 64 bytes = 32 characters.
            % If MsgOut exceeds 30 characters (better being safe than
            % sorry) we have to splice it
            while length(msgOut)>30
                commas = regexp(MsgOut,',');
                currcomma = fgind(commas<=30,'last');
                currMsgOut = MsgOut(1:currcomma-1);
%------------%%%% HermesMsgSend core function %%%%------------------------%
                app.HermesReplyCheck = 0;
                if app.PrintSerialCommCk.Value
                    disp(['MsgOut = ''', currMsgOut,''''])
                end
                
                writeline(app.HermesSerialObj, currMsgOut);
                
                tic
                t=0;
                %  Now wait up to 1 second for a reply from Hermes.
                %  A reply will set app.HermesReplyCheck to 1 vAia the
                %  ByteAvailableFcn of the Hermes Object.
                while t<2 && ~app.HermesReplyCheck
                    t=toc;
                end
                if ~app.HermesReplyCheck
                    warning(['No reply received for message = ''',currMsgOut,''''])
                end
%------------%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%------------------------%
                MsgOut = MsgOut(currcomma+1:end);
            end
        end
        
        function HermesMsgReceivedFcn(app,obj)
%             Here goes stuff.
            MsgIn = readline(obj);
            MsgIn = regexprep(MsgIn,'\r\n','');
            if app.PrintSerialCommCk.Value
                disp(['MsgIn = ''', MsgIn,''''])
            end
            if strcmp(MsgIn,'<Arduino is ready>') || strcmp(MsgIn,'Roger roger')
                app.HermesReplyCheck = 1;
            end
        end
        
        function PcoSetForStimulation(app,p,n,Bt)
%             The number of frames is calculated considering the frame
%             rate of the camera, the period between each trial, the number
%             of trials and the dead time before the start of each trial.
            app.PcoNumberFramesEditField.Value = fix((p-app.DeadTBeforeTrialEditField.Value/1000)*...
                app.PcoSelectedFreqEditField.Value)*n;
%             Duration is used only for calculating timeout time and for
%             showing.
            app.PcoDurationEditField.Value = app.HermesInitialDelaysEditField.Value+Bt+p*n;
        end
        
        function PcoStartAcquisition(app,hImage,HermesMsg)
            if app.PcoStruct.acq_status==0
                
%                 if app.PcoStruct.ArmedFlag == 0
%                     PcoPreImage(app)
%                 end
                
                app.PcoStruct.acq_status = 1;
                
                % Creating the preview timer
                % This is a timer object for the preview that executes at a high rate.
                % The busymode 'drop' makes sure that if the preview rate is too high, the
                % timer execution is just dropped and it doesn't clog anything
                app.PcoStruct.AcqTimer = timer('BusyMode','drop','ExecutionMode','singleShot','StartDelay',...
                    0.001,'TimerFcn',@(~,~) PcoAcqTimerFcn(app,hImage,HermesMsg),'StopFcn',@(~,~)delete(app.PcoStruct.AcqTimer));
                start(app.PcoStruct.AcqTimer)
                
                app.PcoStartAcquisitionButton.Text = 'Stop Acquisition';
                
%                 If the sync-mode is Hermes, then send a message to Hermes
%                 to start the TTL sequence.
                if app.PcoStruct.SelSyncMode == 1
                    
                end
            else
                HermesMsgMake(app,'parameters','stopTrig')
                app.PcoStruct.acq_status = 0;
            end
        end
        
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AppFilePath = mfilename('fullpath');
%             According to the operating system, sets a different separator
%             used for folder paths.
            if ispc
                app.PathSeparator = '\';
            elseif isunix
                app.PathSeparator = '/';
            else
                app.PathSeparator = '';
                error('Platform not recognized. Please check the startup function of the app.\n')
            end
%             Take all the characters up to the last path separator. This
%             is the Escher App Folder.
            AppFolder = regexp(AppFilePath, ['^.*\',app.PathSeparator],'match');
            app.escherAppFolder = AppFolder{:};
            
%             INITIALIZE ALL FLAGS
            %             The screen is not open in the beginning, so the corresponding
            %             flag is set to zero
            app.ScreenOpenFlag = 0;
            app.UdpConnectionFlag = 0;
            
%             The screen function is read:
            app.ScreenFunc = app.ScreenFuncTxt.Value;
            
%             Preparation of the log output
%             Each time Esher is launched check the log directory *\logs for the
%             presence of a directory named with the current date. If it does not
%             exist it is created.
%             Content of the log directory.
%             Each time a stim event is created (either a single episode or an entire
%             sequence) a new entry is created in the log directory.
%             Sintax of the file name:
%             It is formed by two fields, separated by dot.
%             The first field is the run number (4 digits).
%             The second field define the type of stimulus
%             flash      full field flash
%             check      checkerboard
%             grid       presentation of a grid at a given orientaton
%             Finally the extension .dat
%             Each run file includes a line that contains all the parameters relevant
%             to describe the specific stim protocol. Each presentation is described
%             in subsequent lines. The first field is a time tag, followed by
%             parameters desribing the specific stimulus, i.e. the grid orientation.
            logParentDir = [app.escherAppFolder, 'logs', app.PathSeparator];
            if exist(logParentDir, 'dir') ~= 7
                mkdir(logParentDir)
            end
            app.logDir = [logParentDir, date, app.PathSeparator];
            app.runNumberFile = [app.logDir, 'run.number.dat'];
            if exist(app.logDir, 'dir') == 7
%             The log directory has already been created. Determine the next run number.
%             This is contained in the ascii file run.number.log that is created at
%             the time of the directory creation. Each time a new log item is added
%             the run.number is updated. If Escher2 is re-initialisated during a
%             single session, this ensure the unicity of the log file names.
                app.runNumber = readmatrix(app.runNumberFile);
            else
                % Create the ASCII run.number.log file. run number = 1, of course
                mkdir(app.logDir)
                writematrix(1, app.runNumberFile)
            end
            
            app.settingsDir = [app.escherAppFolder, 'settings', app.PathSeparator];
            if exist(app.settingsDir, 'dir') ~= 7
                mkdir(app.settingsDir)
            end
            
%             Erase all UDP connections and initialize a UDP object.
            CloseAllUdp
            app.UdpObj = udp;
            
%             Monitor initialisation and geometry
            SetScreenSizeAngle(app)
            
%             app.white is initialized to 255 and the ScreenBitDepth to 'uint8'.
%             This is the default. If the screen is opened with a different
%             bit depth, these values will be updated.
            app.white = 255;
            app.ScreenBitDepth = 'uint8';
            
%             HARD-CODED SETTINGS AND CALLBACKS
%             The table for fast selection of Flash stimuli has to be set
            app.FlashFastTable.Data = [1, 0, 0; 2, 0.1, 0; 3, 0.139, 0;...
                4, 0.194, 0; 5, 0.268, 0; 6, 0.374, 0; 7, 0.516, 0;...
                8, 0.719, 0; 9, 1, 0];
            app.FlashFastTotN.Value = size(app.FlashFastTable.Data, 1);
%             Then executes the callback of the fast set text box.
            FlashFastN_Callback(app,[])
            
%             The table for fast selection of Checkerboard stimuli has to
%             be set
            app.CheckFastTable.Data = [1, 0, 0.6; 2, 6, 0.6; 3, 12, 0.6;...
                4, 25, 0.6; 5, 50, 0.6; 6, 95, 0.6];
            app.CheckFastTotN.Value = size(app.CheckFastTable.Data, 1);
%             Then executes the callback of the fast set text box.
            CheckFastNValueChanged(app,[])
            
            app.OrientFastTable.Data = [1, 0; 2, 90; 3, 180; 4, 270];
            app.OrientFastTotN.Value = size(app.OrientFastTable.Data, 1);
            OrientFastNValueChanged(app, [])
            
%             The timer for UDP connection timeout must be initialized.
            app.UdpTimeoutTimer = timer;
            
%             Other callbacks to be exectued:
%             The callback for the calculation of Escher values from
%             luminances (in case the calibration is on) OR hiding the
%             escher values (in case calibration is off).
%             All this is achieved by invoking the callback of the
%             calibration radio button.
            CalModeRBGroupSelectionChanged(app,[])
            
%             This is the initialization of the colors of the ExpList.
%             The first is the background color, then the current exp
%             color, then the running exp and the completed exp.
            app.ExpListColors = [1 1 1; 0.9 0.9 0.9; 1 1 0.8; 0.85 1 0.85];
            
%             Creates a vector of graphical objects, containing all the
%             labels of luminance, which have to change between cd/m2 and
%             0-1, depending on whether the absolute luminance or the
%             "Escher Value" (value between 0 and 1) is selected.
            app.LabeLumiVector = [app.LabelLumi_1, app.LabelLumi_2,...
                app.LabelLumi_3, app.LabelLumi_4,app.LabelLumi_5,...
                app.LabelLumi_6];
            
%             Initialize the ExperimentTable so that the stimulus type can
%             be selected with a dropdown menu.
            app.ExperimentTable.ColumnFormat = {'numeric',{'flash','checkerboard','grid'},'numeric','char'};
            
%             Initialize the IP and MAC for UDP connection.
            GetMasterIPandMACBtnPushed(app,[])
            
%             Pupillometry initialization
            app.PupilStruct.preview_status = 0; % This is 1 while the preview is on
            app.PupilStruct.acqStatus = 0; % This is set to 1 when the acquisition has started
            app.PupilStruct.threshold = app.ThresholdSlider.Value;
% set(gcf,'doublebuffer','off') % Set the DoubleBuffer property on for to obtain flicker-free animation. However, to maximize rendering speed, set DoubleBuffer to off.
            app.PupilStruct.previewgraph_size = app.NumberofPointsinPreviewGraphEditField.Value; % number of points composing the preview graph
            app.PupilStruct.acqDuration = app.DurationsEditField.Value;
            
%             refreshcamera(app)

%             PCO initialization
            app.PcoStruct.preview_status = 0; % This is 1 while the preview is on
% set(gcf,'doublebuffer','off') % Set the DoubleBuffer property on for to obtain flicker-free animation. However, to maximize rendering speed, set DoubleBuffer to off.
            app.PcoStruct.acqDuration = app.DurationsEditField.Value;
            app.PcoStruct.acq_status = 0;
            app.PcoStruct.ArmedFlag = 0;
%             Sets the selected submoded from the gui
            RecordingsubmodeDropDownValueChanged(app, [])
%             This will set the selected sync mode (escher-only or Hermes) and will also
%             set the selected frequency according to the maximum possible
%             frequency.
            PcoSyncDropDownValueChanged(app, [])
            
        end

        % Button pushed function: FSFlashGo
        function GoFlash_Callback(app, event)
            % December , 2018
            % The app has been completey redesigned to implement a server-client architecture
            
            % Nov 25, 2013
            % the function has been modified to allow the display of a periodically
            % alternating checkboard.
            % Blumi and Slumi define the luminance of the black and light squares.
            % The spatial frequency is defined by the "spatial frequency" field in the
            % grid generation module.
            % p define the period of the alternation (ON and OFF)
            % Bt defines the baseline_txt spent in the baseline_txt equiluminant gray computed
            % as (Blumi+Slumi)/2 if a calibration LUT is active.
            
            % May 2017.
            % This code has been modified to allow the creation of the checkerboad stim
            % on the VR googles.
            
%             If the "PCO while stimulating" box is checked, Hermes must be
%             also connected with serial communication.
            if app.PcoWhileStimulusCk.Value == 1 && app.HermesSerialFlag == 0
            	uialert(app.EscherMasterFig,['Hermes must be connected in serial mode...',...
                 'to allow PCO recording while presenting visual stimulations.'],'Error')
             return
            end
            
            %             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % load parameters in local variables
            Blumi = app.FSbaselineL.Value; % baseline luminance
            Slumi = app.FSflashL.Value; % flash luminance
            Bt = app.initialDelay.Value; % an initial delay (just once)
            St = app.FlashDuration.Value; % The duration of a single flash
            p = app.FlashPeriod.Value; % time between each flash
            n = app.FlashRepetitions.Value;   % number of repetitions
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.flash', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1 8],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 6]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','Bt','St','p','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [Blumi,Slumi,Bt,St,p,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
            ParameterVector = [Blumi,Slumi,Bt,St,p,n];
            
%             Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
%                 Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,p,n,2*Bt)
                
                IdleTime = round(app.HermesIdleTimeEditField.Value);
                
%                 Create a message for Hermes
%                 HermesMsg = HermesMsgMake(app,'o',IdleTime);
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/n);
                
%                 Send the message to Hermes.
                PcoStartAcquisition(app,app.PcoStruct.imahStim,HermesMsg)
            end
            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'flash'},arrayfun(@num2str,ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                return
            end
            
            OneScreenFlag = app.OneScreenMode_ck.Value;
            CalibrationFlag = app.CalibratedButton.Value;
            ard_flag = app.ard_ck.Value;
            ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,ard_flag]);
%             Calling the function that presents the visual stimulus.
            timestamps = FlashPresent(app,ParameterVector);
            
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
            app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
            %             Enable the buttons "Go" again
            EnableGoBtn(app,1)
        end

        % Button pushed function: GoGrid
        function GoGrid_Callback(app, event)
%             If the "PCO while stimulating" box is checked, Hermes must be
%             also connected with serial communication.
            if app.PcoWhileStimulusCk.Value == 1 && app.HermesSerialFlag == 0
            	uialert(app.EscherMasterFig,['Hermes must be connected in serial mode...',...
                 'to allow PCO recording while presenting visual stimulations.'],'Error')
             return
            end
            
%             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % The two luminance values we need
            Glumi = app.CheckLumiMean.Value; % the average luminance
            inc = (app.CheckLumiW.Value-app.CheckLumiB.Value)/2; % the increment of luminance
%             Other values needed
            sF = app.spatialFrequency.Value; % spatial frequency of the stimulus (in cycles/degree)
            gridType = app.SineGrid.Value; % 1 means sinusoidal, 0 is for square-wave (bar)
            Angle = app.GridOrientation.Value; % angle of the stimulus. 0 means vertical bars.
            dS = app.DriftingSpeed_txt.Value; % drifting speed of the stimulus in cycles/s.
            MaskFlag = app.DrawMask.Value; % 1 means that a gaussian mask is applied.
            GaussSize = app.GaussSize_txt.Value; % size of gaussian mask
            Bt = app.initialDelay.Value; % initial delay
            PreG = app.PreGridDuration.Value; % pre-grid duration
            GridT = app.GridDuration.Value; % grid duration
            PostG = app.PostGridDuration.Value; % post-grid duration
            n = app.GridRepetitions.Value; % number of repetitions of the stimulus
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.grid', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 17],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 15]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','K','sF', 'sine','angle','speed',...
                'maskflag','masksize','Bt','preG','gridT','postG','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [app.CheckLumiB.Value,app.CheckLumiW.Value,...
                Glumi,app.CheckContrast.Value,sF,gridType,Angle,dS,MaskFlag,GaussSize,...
                Bt,PreG,GridT,PostG,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
            ParameterVector = [Glumi,inc,sF,gridType,Angle,dS,MaskFlag,GaussSize,Bt,PreG,GridT,PostG,n];
            
%             Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
%                 Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,PreG+GridT+PostG,n,2*Bt)

%                 Create a message for Hermes
%                 HermesMsg = HermesMsgMake(app,'o');
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/n);
                
%                 Send the message to Hermes.
                PcoStartAcquisition(app,app.PcoStruct.imahStim,HermesMsg)
            end

            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'grid'},arrayfun(@num2str,...
                    ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                return
            end
            
            OneScreenFlag = app.OneScreenMode_ck.Value;
            CalibrationFlag = app.CalibratedButton.Value;
            oculusFlag = app.oculusCk.Value;
            ard_flag = app.ard_ck.Value;
            optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            
            ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,...
                oculusFlag,ard_flag,optDtrTime]);
            timestamps = GridPresent(app,ParameterVector);
            
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
            app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
%             Enables all "Go" buttons
            EnableGoBtn(app,1)
        end

        % Button pushed function: OpenScreenBtn
        function OpenScreenBtn_Callback(app, event)
            switch app.ScreenOpenFlag
                case 0 % Screen in not open
                    if app.UdpConnectionFlag
                        msg = ScreenParameterMsg(app);
                        SendUdpMsg(app,msg,app.TimeoutTime.Value)
                        return
                    end
                    
                    if app.CalibratedButton.Value
                        [background,app.StandbyL.Value] = Lumi2Escher(app.StandbyL.Value,app.white,app.ScreenFunc);
                    else
                        background = app.StandbyL.Value;
                    end
                    
                    % screenRect is a 4 element array xmin, ymin, xmax, ymax
                    % strangely, xmin and ymin are set to zero. Bho?
                    OpenScreen(app, background)
                    
                    % Remember to set the fields relative to the screen size on the GUI and the
                    % relative app.variable.
                    app.WidthPix.Value = app.screenRect(3);
                    app.HeightPix.Value = app.screenRect(4);
                    
                    app.OpenScreenBtn.Text = 'Close Screen';
                    
                    app.ScreenOpenFlag = 1;
                    
%                     Call the standby button.
                    RefreshStByButtonPushed(app, [])
                case 1 % Screen is open
                    CloseScreen
                    app.OpenScreenBtn.Text = 'Open Screen';
                    app.ScreenOpenFlag = 0;
                    EnableGoBtn(app,1)
            end
            
        end

        % Value changed function: SliderOrientation
        function SliderOrientation_Callback(app, event)
            app.GridOrientation.Value = app.SliderOrientation.Value;
        end

        % Button pushed function: calblack_btn
        function calblack_btn_Callback(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'calibration'},{'1'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
                return
            end
            
            % Starts the OpenGL session if in single screen mode
            if app.OneScreenMode_ck.Value
                background = app.StandbyL.Value;
                OpenScreen(background)
            end
            HermesRectCalc(app)
            BaselineColor = cast([[app.white;app.white;app.white], [0;0;0]], app.ScreenBitDepth);
            BaselineScreen = [app.screenRect; app.HermesRect]';
            
            Screen('FillRect', app.w, BaselineColor, BaselineScreen);
            Screen('Flip', app.w);
            
%             If Hermes is connected to Escher with serial communication
            if app.HermesSerialFlag
                writeline(app.HermesSerialObj,'c,b');
            end
        end

        % Button pushed function: calwhite_btn
        function calwhite_btn_Callback(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'calibration'},{'2'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
                return
            end
        
            % Fill the screen with white
            if app.OneScreenMode_ck.Value
                background = app.StandbyL.Value;
                OpenScreen(background)
            end
            
            HermesRectCalc(app)
            BaselineColor = cast([[0;0;0], [app.white;app.white;app.white]], app.ScreenBitDepth);
            BaselineScreen = [app.screenRect; app.HermesRect]';

            Screen('FillRect', app.w, BaselineColor, BaselineScreen);
            Screen('Flip', app.w);
            
%             If Hermes is connected to Escher with serial communication
            if app.HermesSerialFlag
                writeline(app.HermesSerialObj,'c,w');
            end
        end

        % Value changed function: FlashFastN
        function FlashFastN_Callback(app, event)
            FlashFastMat = app.FlashFastTable.Data;
            if app.FlashFastN.Value > size(FlashFastMat,1)
                app.FlashFastN.Value = size(FlashFastMat,1);
            end
            setnumber = app.FlashFastN.Value;
            
%             Set the flash luminance
            app.FSflashL.Value = FlashFastMat(setnumber,2);
%             Call the corresponding callback
            FSflashLValueChanged(app,[])
            
%             Set the baseline luminance
            app.FSbaselineL.Value = FlashFastMat(setnumber,3);
%             Call the corresponding callback
            FSbaselineLValueChanged(app,[])
        end

        % Close request function: EscherMasterFig
        function EscherMasterFigCloseRequest(app, event)
            PcoClose(app)
            
            if isa(app.HermesSerialObj, 'serial')
                try
                    fclose(app.HermesSerialObj);
                catch ME
                    warning(ME.message)
                end
            end
            
            try
            % save parameters in an autosettings file
    %             saveParams(app, [app.escherAppFolder '\autosettings.mat'])
                if isvalid(app.UdpTimeoutTimer)
                    stop(app.UdpTimeoutTimer)
                end
                delete(app.UdpTimeoutTimer)
                CloseAllUdp
            catch ME
%                 warning('off','backtrace') % maybe not necessary
                warning(ME.message)
            end
            delete(app)
        end

        % Button pushed function: LoadScreenFileButton
        function LoadScreenFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.dat;*.csv;*.txt',...
                'Text files (*.dat,*.csv,*.txt)';...
                '*.*',  'All Files (*.*)'}, 'Select a File');
            if file==0
                return
            end
            
            T = readtable([path, file]);
            
            app.ScreenNameTxt.Value = T.name{1};
            app.ScreenWidth.Value = T.width(1);
            app.ScreenHeight.Value = T.height(1);
            app.ScreenFuncTxt.Value = T.formula{1};
            app.ScreenNotesTxt.Value = T.notes{1};
            
            SetScreenSizeAngle(app)
        end

        % Button pushed function: RefreshStBy
        function RefreshStByButtonPushed(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'background'},{num2str(app.StandbyL.Value)}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
                return
            end
            
            ard_flag = app.ard_ck.Value;
            Blumi = app.StandbyL.Value;
            if app.CalibratedButton.Value
                [Blumi,app.StandbyL.Value] = Lumi2Escher(Blumi,app.white,app.ScreenFunc);
            end
            Blumi = app.white*Blumi;
            if ard_flag
                BaselineColor = cast([[Blumi;Blumi;Blumi], [0;0;0]], app.ScreenBitDepth);
                cellRects = [app.screenRect; app.HermesRect]';
                %     This is commented because in flashes, the optical DTR
                %     duration is the same as the flash duration
                %     optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            else
                BaselineColor = cast(Blumi, app.ScreenBitDepth);
               	cellRects = app.screenRect;
            end
            Screen('FillRect', app.w, BaselineColor, cellRects); % paints the rectangle (entire screen)
            Screen('Flip', app.w);
        end

        % Button pushed function: FlashFastLoadTable
        function FlashFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','FlashStimuliTable.csv');
            if file==0
                return
            end
            StimTable = readtable([path, file]);
            app.FlashFastTable.Data = StimTable;
        end

        % Button pushed function: CheckGo
        function GoCheck_Callback(app, event)
            % Nov 25, 2013
            % the function has been modified to allow the display of a periodically
            % alternating checkboard.
            % Blumi and Slumi define the luminance of the black and light squares.
            % The spatial frequency is defined by the "spatial frequency" field in the
            % grid generation module.
            % p define the period of the alternation (ON and OFF)
            % Bt defines the baseline_txt spent in the baseline_txt equiluminant grey computed
            % as (Blumi+Slumi)/2 if a calibration LUT is active.
            
            % May 2017.
            % This code has been modified to allow the creation of the checkerboad stim
            % on the VR googles.
            
%             If the "PCO while stimulating" box is checked, Hermes must be
%             also connected with serial communication.
            if app.PcoWhileStimulusCk.Value == 1 && app.HermesSerialFlag == 0
            	uialert(app.EscherMasterFig,['Hermes must be connected in serial mode...',...
                 'to allow PCO recording while presenting visual stimulations.'],'Error')
             return
            end
            
            %             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % load parameters in local variables
            Blumi = app.CheckLumiB.Value;
            Slumi = app.CheckLumiW.Value;
            %             gray luminance for equiluminant background.
            Glumi = app.CheckLumiMean.Value;
            sF = app.spatialFrequency.Value;
            Bt = app.initialDelay.Value;
            p = app.CheckPeriod.Value/2; % hemi-period. p is the time between a reversal and the next (2 reversals = 1 period)
            n = app.CheckRepetitions.Value; % Repetitions. The number of reversals is 2*n.
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.check', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 10],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 8]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','K','sF', 'Bt','p','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [Blumi,Slumi,Glumi,app.CheckContrast.Value,...
                sF,Bt,p,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
%             ParameterVector is the vector of parameters that is passed as
%             UDP message. If UDP is not used, it is useful anyway as input
%             for the stimulus-presenting function.
            ParameterVector = [Blumi,Slumi,sF,Bt,p,n];
            
%             Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
%                 Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,p,2*n,2*Bt)

%                 Create a message for Hermes
%                 HermesMsg = HermesMsgMake(app,'o');
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/(2*n));
                
%                 Send the message to Hermes.
                PcoStartAcquisition(app,app.PcoStruct.imahStim,HermesMsg)
            end

            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'check'},arrayfun(@num2str,ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                return
            end
            
%             Additional parameters to be passed: flags and synchronization
%             parameters.
            OneScreenFlag = app.OneScreenMode_ck.Value;
            CalibrationFlag = app.CalibratedButton.Value;
            oculusFlag = app.oculusCk.Value;
            ard_flag = app.ard_ck.Value;
            optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            
%             Let's add some parameters to the ParameterVector for the
%             CheckPresent function.
            ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,oculusFlag,ard_flag,optDtrTime]);
%             Now starts the function for the presentation of the stimulus.
            timestamps = CheckPresent(app,ParameterVector);
                        
%             Log writing.
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
            app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
            %             Enables all "Go" buttons
            EnableGoBtn(app,1)
        end

        % Value changed function: CheckFastN
        function CheckFastNValueChanged(app, event)
            CheckFastMat = app.CheckFastTable.Data;
            if app.CheckFastN.Value > size(CheckFastMat,1)
                app.CheckFastN.Value = size(CheckFastMat,1);
            end
            setnumber = app.CheckFastN.Value;
            
%             Set the flash luminance
            app.CheckContrast.Value = CheckFastMat(setnumber,2);
%             Set the baseline luminance
            app.CheckLumiMean.Value = CheckFastMat(setnumber,3);
            
            CheckUpdateLumi(app,0)
        end

        % Button pushed function: CheckFastLoadTable
        function CheckFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','CheckStimuliTable.csv');
            if file==0
                return
            end
            StimTable = readtable([path, file]);
            app.CheckFastTable.Data = StimTable;
        end

        % Selection changed function: CalModeRBGroup
        function CalModeRBGroupSelectionChanged(app, event)
%             Depending on whether the calibration is selected or not, the
%             unit of measurement of the luminance is changed.
            if app.CalibratedButton.Value
%                 If the calibration is there, we need the luminance to be
%                 expressed as cd/m2
                mystring = 'cd/m²';
            else
%                 Otherwise, we set it to be (0-1)
                mystring = '(0-1)';
            end
            
            set(app.LabeLumiVector, 'Text', mystring)
        end

        % Value changed function: CheckLumiB
        function CheckLumiBValueChanged(app, event)
            CheckUpdateLumi(app,1)
        end

        % Value changed function: CheckLumiW
        function CheckLumiWValueChanged(app, event)
            CheckUpdateLumi(app,1)
        end

        % Value changed function: ScreenWidth
        function ScreenWidthValueChanged(app, event)
            SetScreenSizeAngle(app)
            PixelAngleCalc(app)
        end

        % Value changed function: ScreenHeight
        function ScreenHeightValueChanged(app, event)
            SetScreenSizeAngle(app)
        end

        % Value changed function: CheckLumiMean
        function CheckLumiMeanValueChanged(app, event)
            CheckUpdateLumi(app,0)
        end

        % Value changed function: CheckContrast
        function CheckContrastValueChanged(app, event)
            CheckUpdateLumi(app,0)
        end

        % Value changed function: GridOrientation
        function GridOrientationValueChanged(app, event)
            app.SliderOrientation.Value = app.GridOrientation.Value;
        end

        % Value changed function: FSbaselineL
        function FSbaselineLValueChanged(app, event)
            if app.CalibratedButton.Value
                [app.FSbaselineLEsch.Value,TrueFSbaselineL] = Lumi2Escher(app.FSbaselineL.Value,app.white,app.ScreenFunc);
                app.FSbaselineL.Value = TrueFSbaselineL;
            end
        end

        % Value changed function: FSflashL
        function FSflashLValueChanged(app, event)
            if app.CalibratedButton.Value
                [app.FSflashLEsch.Value,TrueFSflashL] = Lumi2Escher(app.FSflashL.Value,app.white,app.ScreenFunc);
                app.FSflashL.Value = TrueFSflashL;
            end
        end

        % Value changed function: OrientFastN
        function OrientFastNValueChanged(app, event)
            OrientFastMat = app.OrientFastTable.Data;
            if app.OrientFastN.Value > size(OrientFastMat,1)
                app.OrientFastN.Value = size(OrientFastMat,1);
            end
            setnumber = app.OrientFastN.Value;
            
%             Set the orientation
            app.GridOrientation.Value = OrientFastMat(setnumber,2);
            GridOrientationValueChanged(app,[])
        end

        % Value changed function: FlashFastTotN
        function FlashFastTotNValueChanged(app, event)
            StimTotN = app.FlashFastTotN.Value;
            TableSize = size(app.FlashFastTable.Data,1);
            
            if StimTotN > TableSize
%             A number of rows are added to the table.
                if app.logspacedFlashCk.Value && app.includezeroFlashCk.Value
                    minL = 0;
                else
                    minL = app.FlashFastMinL.Value;
                end
%                 The extra rows are filled with a blank stimulus.
                app.FlashFastTable.Data = vertcat(app.FlashFastTable.Data,...
                    horzcat((TableSize+1:StimTotN)', repmat(minL,StimTotN-TableSize,2)));
            else
%                 The table is shortened.
                app.FlashFastTable.Data = app.FlashFastTable.Data(1:StimTotN,:);
            end
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
%             If the table is empty, this function is just executed. If
%             it's not empty, a confirmation will be prompted.
            if ~isempty(app.ExperimentTable.Data)
                answ = uiconfirm(app.EscherMasterFig, 'This will delete previously generated table. Do you confirm?', 'Warning');
                switch answ
                    case 'Cancel'
                        return
                end
            end
            
%             Get some dimensions.
            SessionLength = app.ExpListFlashCk.Value*size(app.FlashFastTable.Data,1)+...
                app.ExpListCheckCk.Value*size(app.CheckFastTable.Data,1)+...
                app.ExpListGratingsOrientCk.Value*size(app.OrientFastTable.Data,1);
            tableN = SessionLength * app.ExpListSessionN.Value;
            app.ExperimentTable.Data = cell(tableN,4);
            StartNum = app.ExpListFirstNum.Value;
            
            for i = 1:app.ExpListSessionN.Value
                if app.ExpListFlashCk.Value
                    FlashTmp = cell(size(app.FlashFastTable.Data,1), 2);
                    FlashTmp(:,1) = repmat({'flash'},size(app.FlashFastTable.Data,1),1);
                    FlashTmp(:,2) = num2cell(1:size(app.FlashFastTable.Data,1));
                else
                    FlashTmp = [];
                end
                
                if app.ExpListCheckCk.Value
                    CheckerTmp = cell(size(app.CheckFastTable.Data,1), 2);
                    CheckerTmp(:,1) = repmat({'checkerboard'},size(app.CheckFastTable.Data,1),1);
                    CheckerTmp(:,2) = num2cell(1:size(app.CheckFastTable.Data,1));
                else
                    CheckerTmp = [];
                end
                
                if app.ExpListGratingsOrientCk.Value
                    GridTmp = cell(size(app.OrientFastTable.Data,1), 2);
                    GridTmp(:,1) = repmat({'grid'},size(app.OrientFastTable.Data,1),1);
                    GridTmp(:,2) = num2cell(1:size(app.OrientFastTable.Data,1));
                else
                    GridTmp = [];
                end
                
                sessionMat = [FlashTmp; CheckerTmp; GridTmp];
                
                permutations = randperm(SessionLength);
                sessionMat = sessionMat(permutations,:);
                
                app.ExperimentTable.Data(1+SessionLength*(i-1) : SessionLength*i, [2 3]) = sessionMat;
            end
            
            
%             Give a number to each experiment, starting from
%             ExpListFirstNum.
            app.ExperimentTable.Data(:,1) = num2cell(StartNum : tableN+StartNum-1);
            app.ExpListCurrentIdx=1;
%             The first experiment has to start from the ExpListFirstNum.
            app.ExpListCurrentNum.Value = StartNum;
%             The first row in app.ExpListColors is the background.
            app.ExperimentTable.BackgroundColor = app.ExpListColors(1,:) .* ones(tableN,3);
%             The second row in app.ExpListColors is the current exp.
            app.ExperimentTable.BackgroundColor(1,:) = app.ExpListColors(2,:);
            
            app.ExpListGo.Enable = 1;
            
        end

        % Cell selection callback: ExperimentTable
        function ExperimentTableCellSelection(app, event)
            app.ExpListSelectedIndices = event.Indices;
            app.ExpListDeleteRows.Enable = 1;
            app.ExpListAddRows.Enable = 1;
        end

        % Button pushed function: CopytableButton
        function CopytableButtonPushed(app, event)
            % To copy the data from the table of zebrasays to the clipboard
            tablesize = size(app.ExperimentTable.Data);
            
            % Created a function to copy data to clipboard from outtable.
            % selectionIndices is a 4-element vector containing the
            % upper-left corner and the bottom-right corner indices
            SelTab2Clip(app,app.ExperimentTable,[1 1 tablesize(1) tablesize(2)])
        end

        % Button pushed function: ExpMoveUp
        function ExpMoveUpPushed(app, event)
            %             The whole selected part is moved of one row up
            SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
            %             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1 && (SelRowIndices(1) > app.ExpListCurrentIdx+1 ||...
                    SelRowIndices(2) < app.ExpListCurrentIdx)
                %                 shift a subset of the array circularly to get to the
                %                 app.ExperimentTable.Data(SelRowIndices(1)-1 : SelRowIndices(2), 2:end) =...
                %                     circshift(app.ExperimentTable.Data(SelRowIndices(1)-1 : SelRowIndices(2), 2:end), -1, 1);
                app.ExperimentTable.Data(SelRowIndices(1)-1:SelRowIndices(2), 2:end) =...
                    app.ExperimentTable.Data([SelRowIndices(1):SelRowIndices(2), SelRowIndices(1)-1], 2:end);
                
                %                 The same must be done for colors
                %                 app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :) =...
                %                     circshift(app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :), -1, 1);
                app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :) =...
                    app.ExperimentTable.BackgroundColor([SelRowIndices(1):SelRowIndices(2), SelRowIndices(1)-1], :);
                
                %                 Then the index of current experiment has to be changed.
                %                 Also the number corresponding to current experiment is
                %                 going to change.
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx - 1;
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value - 1;
                elseif app.ExpListCurrentIdx == SelRowIndices(1) - 1
                    app.ExpListCurrentIdx = SelRowIndices(2);
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + (SelRowIndices(2)-SelRowIndices(1)+1);
                end
            end
        end

        % Button pushed function: ExpMoveDown
        function ExpMoveDownButtonPushed(app, event)
            %             The whole selected part is moved of one row up
            SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
            %             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1
                %                 shift a subset of the array circularly to get to the
                %                 app.ExperimentTable.Data(SelRowIndices(1) : SelRowIndices(2)+1, 2:end) =...
                %                     circshift(app.ExperimentTable.Data(SelRowIndices(1) : SelRowIndices(2)+1, 2:end), 1, 1);
                app.ExperimentTable.Data(SelRowIndices(1):SelRowIndices(2)+1, 2:end) = ...
                    app.ExperimentTable.Data([SelRowIndices(2)+1, SelRowIndices(1):SelRowIndices(2)], 2:end);
                
                %                 The same must be done for colors
                app.ExperimentTable.BackgroundColor(SelRowIndices(1) : SelRowIndices(2)+1, :) =...
                    circshift(app.ExperimentTable.BackgroundColor(SelRowIndices(1) : SelRowIndices(2)+1, :), 1, 1);
                
                %                 Then the index of current experiment has to be changed.
                %                 Also the number corresponding to current experiment is
                %                 going to change.
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx + 1;
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + 1;
                elseif app.ExpListCurrentIdx == SelRowIndices(2) + 1
                    app.ExpListCurrentIdx = SelRowIndices(1);
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + (SelRowIndices(2)-SelRowIndices(1)+1);
                end
            end
        end

        % Button pushed function: ExpListDeleteRows
        function ExpListDeleteRowsButtonPushed(app, event)
            answ = uiconfirm(app.EscherMasterFig,'This will delete all the selected columns','Warning','Options',{'Delete','Cancel'});
            if strcmp(answ,'Delete')
                SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
                app.ExperimentTable.Data(SelRowIndices(1):SelRowIndices(2),:) = [];
                app.ExperimentTable.BackgroundColor(SelRowIndices(1):SelRowIndices(2), : ) = [];
                app.ExperimentTable.Data(:,1) = num2cell(app.ExpListFirstNum.Value : ...
                    size(app.ExperimentTable.Data, 1)+app.ExpListFirstNum.Value-1);
                
%                 If the current experiment is in the selected rows
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
%                     If current experiment is not the last row
                    if app.ExpListCurrentIdx < size(app.ExperimentTable.Data, 1)
%                         The index of the first deleted row becomes the
%                         index of the current experiment.
                        app.ExpListCurrentIdx = SelRowIndices(1);
                        app.ExperimentTable.BackgroundColor(app.ExpListCurrentIdx,:) = app.ExpListColors(2,:);
%                         If the current experiment is the last row OR if
%                         the selection encompassing the current
%                         experiment also reaches the end of the table.
                    elseif app.ExpListCurrentIdx == size(app.ExperimentTable.Data, 1) ||...
                            SelRowIndices(2) == size(app.ExperimentTable.Data, 1)
                        app.ExpListCurrentIdx = [];
                        app.ExpListGo.Enable = 0;
                    end
%                     If the selected rows are before the current
%                     experiment.
                elseif app.ExpListCurrentIdx > SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx - (SelRowIndices(2)-SelRowIndices(1)+1);
                end
                app.ExpListCurrentNum.Value = app.ExperimentTable.Data{app.ExpListCurrentIdx,1};
                app.ExpListDeleteRows.Enable = 0;
                app.ExpListAddRows.Enable = 0;
            end
        end

        % Button pushed function: ExpListGo
        function ExpListGoPushed(app, event)
%             Current Index is the index of the current experiment.
            currIdx = app.ExpListCurrentIdx;
%             Current FastNumber is the number of the current experiment on
%             the table (it's the same as Current Index ONLY if the
%             Experiment Table starts from experiment n. 1.
            currFastNum = app.ExperimentTable.Data{currIdx,3};
            
%             If the pupil preview while acquiring is checked
            if app.PupilWhileStimulating.Value && app.UdpConnectionFlag
                StartPreviewButtonPushed(app, [])
            end
            
            switch app.ExperimentTable.Data{currIdx,2}
                case 'flash'
%                     Changes the stimulus
                    app.FlashFastN.Value = currFastNum;
                    FlashFastN_Callback(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);
                    
%                     Starts the stimulation
                    GoFlash_Callback(app,[])
                case 'checkerboard'
%                     Changes the stimulus
                    app.CheckFastN.Value = currFastNum;
                    CheckFastNValueChanged(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);
                    
%                     Starts the stimulation
                    GoCheck_Callback(app,[])
                case {'grid','grating'}
%                     Changes the stimulus
                    app.OrientFastN.Value = currFastNum;
                    OrientFastNValueChanged(app,[])
                    
%                     Set maximum contrast
                    app.CheckFastN.Value = size(app.CheckFastTable.Data,1);
                    CheckFastNValueChanged(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);

%                     Starts the stimulation
                    OrientFastNValueChanged(app,[])
                    GoGrid_Callback(app,[])
            end
            
%             The current row turns of the fourth color (green)
            app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(4,:);
            
%             Updates the index of the new current exp and it's color in
%             the table.
            if currIdx ~= size(app.ExperimentTable.Data,1)+1
                currIdx = currIdx + 1;
                app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(2,:);
            else
                currIdx=[];
            end
            app.ExpListCurrentIdx = currIdx;
            
%             Updates the current number shown in the GUI.
            app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + 1;
        end

        % Button pushed function: ExpListAddRows
        function ExpListAddRowsButtonPushed(app, event)
            if isempty(app.ExperimentTable.Data)
                app.ExperimentTable.Data = cell(1,length(app.ExperimentTable.ColumnEditable));
                app.ExperimentTable.BackgroundColor(1,:) = app.ExpListColors(2,:);
                app.ExpListCurrentIdx = 1;
                app.ExpListCurrentNum.Value = 1 + app.ExpListFirstNum.Value;
            else
%                 The new row is added just below the last selected row.
                AddedRowIdx = max(app.ExpListSelectedIndices(:,1))+1;
%                 Add an empty cell at the end of the table.
                app.ExperimentTable.Data = [app.ExperimentTable.Data; cell(1, size(app.ExperimentTable.Data,2))];
%                 And then moves it to the position of the added row.
                app.ExperimentTable.Data(AddedRowIdx:end,2:end) = app.ExperimentTable.Data([end,AddedRowIdx:end-1],2:end);
    %             Updates the number (column n. 1) of the last row
                app.ExperimentTable.Data{end,1} = app.ExperimentTable.Data{end-1,1} + 1;
%                 Then the color of the table is updated too.
                app.ExperimentTable.BackgroundColor = [app.ExperimentTable.BackgroundColor; app.ExpListColors(1,:)];
%                 And the color is moved to the position of the added row.
                app.ExperimentTable.BackgroundColor(AddedRowIdx:end,:) = app.ExperimentTable.BackgroundColor([end,AddedRowIdx:end-1],:);
                
%                 Then the color must be updated.
                if AddedRowIdx < app.ExpListCurrentIdx
%                     If the index of the added row is the current
%                     experiment, the added row is green.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(4,:);
%                     And the current experiment index and number is
%                     changed by one.
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx + 1;
                    app.ExpListCurrentNum.Value = app.ExpListFirstNum.Value + 1;
                elseif AddedRowIdx == app.ExpListCurrentIdx && AddedRowIdx < size(app.ExperimentTable.Data,1)
%                     If the position of the added row is just before the
%                     current experiment, this added row becomes the new
%                     current experiment.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(2,:);
%                     The current experiment number and index is not
%                     changed.
%                     The previous current experiment row color must become
%                     white.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx+1,:) = app.ExpListColors(1,:);
                elseif AddedRowIdx == size(app.ExperimentTable.Data,1) && isempty(app.ExpListCurrentIdx)
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(2,:);
                    app.ExpListCurrentIdx = AddedRowIdx;
                    app.ExpListCurrentNum.Value = AddedRowIdx + app.ExpListFirstNum.Value;
                end
            end
            
        end

        % Value changed function: ExpListFirstNum
        function ExpListFirstNumValueChanged(app, event)
            StartNum = app.ExpListFirstNum.Value;
            app.ExperimentTable.Data(:,1) = num2cell(StartNum : size(app.ExperimentTable.Data,1)+StartNum-1);
        end

        % Button pushed function: RefreshDevicesButton
        function RefreshDevicesButtonPushed(app, event)
            refreshcamera(app)
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            app.selcamIdx = find(strcmp(app.CameraDropDown.Value,app.CameraDropDown.Items),1);
            selectcam(app)
        end

        % Value changed function: ImageSizeDropDown
        function ImageSizeDropDownValueChanged(app, event)
            app.PupilStruct.selformatIdx = find(strcmp(app.ImageSizeDropDown.Value,app.ImageSizeDropDown.Items),1);
            selectformat(app)
        end

        % Value changed function: 
        % NumberofPointsinPreviewGraphEditField
        function NumberofPointsinPreviewGraphEditFieldValueChanged(app, event)
            app.PupilStruct.previewgraph_size = app.NumberofPointsinPreviewGraphEditField.Value;
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            app.PupilImage1UIAxes.CameraUpVectorMode = 'manual';
            app.PupilImage1UIAxes.CameraUpVector = [0 1 0];
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            switch app.PupilStruct.preview_status
                case 0
                    % Initialize the preview plot
                    PreviewInitialize(app)
                    
                    app.StartPreviewButton.Text = 'Stop Preview';
                    app.PupilStruct.preview_status = 1;
                case 1
                    PreviewStop(app)

                    app.StartPreviewButton.Text = 'Start Preview';
                    app.PupilStruct.preview_status = 0;
            end
        end

        % Value changed function: DurationsEditField
        function DurationsEditFieldValueChanged(app, event)
            app.PupilStruct.acqDuration = app.DurationsEditField.Value;
        end

        % Button pushed function: PupilStartAcquisitionButton
        function PupilStartAcquisitionButtonPushed(app, event)
            switch app.PupilStruct.acqStatus % Status of the button
                case 0
                    
%                     If there is already a preview open, it is closed.
                    if app.PupilStruct.preview_status
                        StartPreviewButtonPushed(app, [])
                    end
                    
                    if isfield(app.PupilStruct,'AcquisitionTimer')
                        % Remove any acquisition timer
                        delete(app.PupilStruct.AcquisitionTimer)
                    end
                    
%                     Checking if the specified folder where to save the
%                     files is there. If it's not there, then it is
%                     created.
                    if  exist(app.SaveFolderEditField.Value, 'dir') ~= 7 % 7 is when a name is a folder
                        mkdir(app.SaveFolderEditField.Value)
                    end

%                     If the preview-while-acquiring check box is checked,
%                     then the preview is started before the actual
%                     acquisition
                    if app.PreviewWhileAcquiringCheckBox.Value
                        PreviewInitialize(app)
                    end
                    
                    % This is a timer object for the actual acquisition. The busymode
                    % queue makes sure that no execution is dropped.
                    % The tasks to be executed are set to 2.
                    % This timer has only two tasks: the first is to start the acquisition
                    % The second is to stop the acquisition and plot
                    app.PupilStruct.acqTimerStatus = 0;
                    app.PupilStruct.AcquisitionTimer = timer('BusyMode','queue','ExecutionMode','fixedSpacing','Period',app.DurationsEditField.Value,'TasksToExecute',2,'StartDelay',0.001,'TimerFcn',@(~,~) AcquisitionTimerFcn(app));
                    start(app.PupilStruct.AcquisitionTimer)
                    app.acqStatusTxt.Value = 'Acquiring...';
                    app.acqLamp.Color = [1 0 0];
                    app.PupilStartAcquisitionButton.Enable = 0;
                    app.PupilStruct.acqStatus = 1;
            end
        end

        % Button pushed function: generatestimuliFlashBtn
        function generatestimuliFlashBtnPushed(app, event)
%             This is to generate a certain number of stimuli
        minL = app.FlashFastMinL.Value;
        maxL = app.FlashFastMaxL.Value;
        number = app.FlashFastTotN.Value;
        if app.logspacedFlashCk.Value
            if app.includezeroFlashCk.Value
%                 If zero is included, a 0 is added at the beginning of the
%                 stimulus vector.
                StimVect = [0, logspace(log10(minL),log10(maxL),number-1)];
%                 minL is now set to zero, because this is the value that
%                 is going to be used as baseline in the table.
                minL = 0;
            else
                StimVect = logspace(log10(minL),log10(maxL),number);
            end
        else
            StimVect = linspace(minL,maxL,number);
        end
        app.FlashFastTable.Data = horzcat((1:number)',StimVect',repmat(minL,number,1));
        end

        % Value changed function: FlashFastMinL
        function FlashFastMinLValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             minimum luminance must be more than 0.
            if app.logspacedFlashCk.Value && app.FlashFastMinL.Value==0
                uialert(app.EscherMasterFig,'For logarithmically-spaced stimuli, zero is not a valid input!','Bad input')
                app.FlashFastMinL.Value = event.PreviousValue;
            end
        end

        % Value changed function: CheckFastMinC
        function CheckFastMinCValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             minimum luminance must be more than 0.
            if app.logspacedCheckCk.Value && app.CheckFastMinC.Value==0
                uialert(app.EscherMasterFig,'For logarithmically-spaced stimuli, zero is not a valid input!','Bad input')
                app.CheckFastMinC.Value = event.PreviousValue;
            end
        end

        % Value changed function: logspacedFlashCk
        function logspacedFlashCkValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             "include zero" check box must be enabled to allow zero to be
%             inserted as a value in the list.
            if app.logspacedFlashCk.Value
                app.includezeroFlashCk.Enable = 1;
            else
                app.includezeroFlashCk.Enable = 0;
            end
        end

        % Value changed function: logspacedCheckCk
        function logspacedCheckCkValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             "include zero" check box must be enabled to allow zero to be
%             inserted as a value in the list.
            if app.logspacedCheckCk.Value
                app.includezeroCheckCk.Enable = 1;
            else
                app.includezeroCheckCk.Enable = 0;
            end
        end

        % Button pushed function: generatestimuliCheckBtn
        function generatestimuliCheckBtnButtonPushed(app, event)
%             This is to generate a certain number of stimuli
        minC = app.CheckFastMinC.Value;
        maxC = app.CheckFastMaxC.Value;
        number = app.CheckFastTotN.Value;
        if app.logspacedCheckCk.Value
            if app.includezeroCheckCk.Value
%                 If zero is included, a 0 is added at the beginning of the
%                 stimulus vector.
                StimVect = [0, logspace(log10(minC),log10(maxC),number-1)];
            else
                StimVect = logspace(log10(minC),log10(maxC),number);
            end
        else
            StimVect = linspace(minC,maxC,number);
        end
        app.CheckFastTable.Data = horzcat((1:number)',StimVect',repmat(app.CheckFastMeanL.Value,number,1));
        end

        % Value changed function: CheckFastTotN
        function CheckFastTotNValueChanged(app, event)
            StimTotN = app.CheckFastTotN.Value;
            TableSize = size(app.CheckFastTable.Data,1);
            
            if StimTotN > TableSize
%             A number of rows are added to the table.
                if app.logspacedCheckCk.Value && app.includezeroCheckCk.Value
                    minC = 0;
                else
                    minC = app.CheckFastMinC.Value;
                end
%                 The extra rows are filled with a blank stimulus.
                app.CheckFastTable.Data = vertcat(app.CheckFastTable.Data,...
                    repmat([minC,app.CheckFastMeanL.Value],StimTotN-TableSize,2));
            else
%                 The table is shortened.
                app.CheckFastTable.Data = app.CheckFastTable.Data(1:StimTotN,:);
            end
        end

        % Button pushed function: generatestimuliOrientBtn
        function generatestimuliOrientBtnButtonPushed(app, event)
%             This is to generate a certain number of stimuli
        minA = app.OrientFastMinA.Value;
        maxA = app.OrientFastMaxA.Value;
        number = app.OrientFastTotN.Value;
        StimVect = linspace(minA,maxA,number);
        app.OrientFastTable.Data = horzcat((1:number)',StimVect');
        end

        % Button pushed function: savetableButton
        function savetableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
            StimTable = array2table(app.FlashFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','FlashStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
            writetable(StimTable,[file,path])
        end

        % Button pushed function: CheckFastSaveTable
        function CheckFastSaveTableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
            StimTable = array2table(app.CheckFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','CheckStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
            writetable(StimTable,[file,path])
        end

        % Button pushed function: OrientFastSaveTable
        function OrientFastSaveTableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
            StimTable = array2table(app.OrientFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','OrientStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
            writetable(StimTable,[file,path])
        end

        % Button pushed function: OrientFastLoadTable
        function OrientFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','OrientStimuliTable.csv');
            if file==0
                return
            end
            StimTable = readtable([path, file]);
            app.OrientFastTable.Data = StimTable;
        end

        % Button pushed function: RepeatStimBtn
        function RepeatStimBtnButtonPushed(app, event)
            currIdx = app.ExpListCurrentIdx;
            if app.ExpListCurrentIdx > 1
                app.ExperimentTable.Data = vertcat(app.ExperimentTable.Data(1:currIdx-1,:),...
                    app.ExperimentTable.Data(currIdx-1,:), app.ExperimentTable.Data(currIdx:end,:));
                app.ExperimentTable.Data(currIdx:end,1) = ...
                    num2cell(currIdx:app.ExperimentTable.Data{end,1}+1);
            else
                %                 If the index of current experiment is 1 or 0, there is
                %                 nothing to repeat!
                uialert(app.EscherMasterFig,'There is nothing to repeat!','Error')
            end
        end

        % Button pushed function: GetMasterIPandMACBtn
        function GetMasterIPandMACBtnPushed(app, event)
            ConnParams = GetConnParams;
            app.MasterIp.Value = ConnParams.IP;
            app.MasterMac.Value = ConnParams.MAC;
            app.SubnetMask.Value = ConnParams.SubMask;
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Button pushed function: StartUdpBtn
        function StartUdpBtnButtonPushed(app, event)
            if ~app.UdpConnectionFlag
                if isempty(app.MasterMac.Value)||...
                        (strcmp(app.UDPBroadcastDropDown.Value, app.UDPBroadcastDropDown.Items{1}) && strcmp(app.SlaveIp.Value,'0.0.0.0'))||...
                        strcmp(app.MasterIp.Value,'0.0.0.0')
                    uialert(app.EscherMasterFig,'Invalid Addresses','Error')
                    return
                end
                if app.ScreenOpenFlag
%                 If the screen is open the app will ask for confirmation to start working as master.
                    selection = uiconfirm(app.EscherMasterFig, ['The app will start now working as master.' ...
                        'This will close any open screen. Do you confirm?'], 'Warning');
                    if strcmp(selection,'OK')
                        OpenScreenBtn_Callback(app, [])
                    else
                        return
                    end
                end
                
%                 Change the status text, while waiting for connection
%                 Also, the button for starting the connection is being
%                 disabled. When the app receives an answer from the UDP
%                 connection OR when the timer times out.
                EnableGoBtn(app,0)
%                 All the connection parameters cannot be changed while the
%                 connection is ongoing.
                set([app.MasterIp,app.MasterMac,app.MasterPort,app.SlaveIp,app.SlavePort,app.SubnetMask],'Editable',0);
                app.ConnectionStatus.Value = 'Connecting';
                
                
                if strcmp(app.UDPBroadcastDropDown.Value,app.UDPBroadcastDropDown.Items{1})||...
                        strcmp(app.UDPBroadcastDropDown.Value,app.UDPBroadcastDropDown.Items{2})
%                 This is the UDP object, used to make the master (this app)
%                 communicate with the slave (the app actually performing the
%                 stimulation).
                    switch app.UDPBroadcastDropDown.Value
                        case app.UDPBroadcastDropDown.Items{1}
                            SlaveIpAddress = app.SlaveIp.Value;
                            msg=strjoin([{'start'},{app.MasterIp.Value},{app.MasterMac.Value},...
                                {num2str(app.MasterPort.Value)},{'0'}],',');
                        case app.UDPBroadcastDropDown.Items{2}
                            SlaveIpAddress = app.BroadcastAddress.Value;
                            msg=strjoin([{'start'},{app.MasterIp.Value},{app.MasterMac.Value},...
                                {num2str(app.MasterPort.Value)},{'1'}],',');
                    end
                    
                    try
                        app.UdpObj.RemoteHost = SlaveIpAddress;
                        app.UdpObj.RemotePort = app.SlavePort.Value;
                        app.UdpOnj.LocalPort = app.MasterPort.Value;
    %                     Open the UDP object.
                        fopen(app.UdpObj);
                    catch
    %                     If for some reason, the UDP connection cannot be
    %                     opened, we must close all connections.
    %                     Erase all UDP connections and initialize a UDP object.
                        CloseAllUdp
                        app.UdpObj = udp(SlaveIpAddress, 'RemotePort',...
                            app.SlavePort.Value, 'LocalPort', app.MasterPort.Value);
                        fopen(app.UdpObj);
                    end
    %                 Callback function for when a datagram is received. If a
    %                 confirmation message is read, the timer is stopped.
                    app.UdpObj.DatagramReceivedFcn = @(obj,~) UdpDataReceivedCallback(app,obj);
                    
    %                 And start reading.
                    readasync(app.UdpObj)
                    app.UdpObj.ReadAsyncMode = 'continuous';
                    
    %                 Send a message to start the connection. It contains the
    %                 IP, MAC and port of the master.
                    SendUdpMsg(app,msg,app.TimeoutTime.Value)
                else
%                     In this case, the Send-to-all option has been
%                     selected, which means that a sort of manual broadcast
%                     is going to be executed.
%                     First of all, a list of all IP addresses to be used
%                     is generated.
                    CloseAllUdp
                    app.UdpObj = udp('127.0.0.1', 'RemotePort',...
                        app.SlavePort.Value, 'LocalPort', app.MasterPort.Value);
                    fopen(app.UdpObj);
                    readasync(app.UdpObj)
                    app.UdpObj.ReadAsyncMode = 'continuous';
    %                 Callback function for when a datagram is received. If a
    %                 confirmation message is read, the timer is stopped.
                    app.UdpObj.DatagramReceivedFcn = @(obj,~) UdpDataReceivedCallback(app,obj);
                    
%                     The list of all possible IPs is generated and is
%                     saved as a property of the app. In this way, we
%                     avoid any conflict with the asynchronous callback
%                     function, called when data are received.
                    IpList = SendToAllIpList(app.STAstartTxt.Value,app.STAendTxt.Value);
                    msg=strjoin([{'start'},{app.MasterIp.Value},{app.MasterMac.Value},...
                        {num2str(app.MasterPort.Value)},{'1'}],',');
                    app.SendToAllTerminator = 0;
                    i=1;
                    while ~app.SendToAllTerminator || i<=length(IpList)
                        app.UdpObj.RemoteHost = IpList{i};
        %                 Send a message to start the connection. It contains the
        %                 IP, MAC and port of the master.
                        fprintf(app.UdpObj,msg);
                        i=i+1;
                    end
                    if ~app.SendToAllTerminator
            %             A timer for timeout has to be called. Before that, the
            %             current timer is stopped and deleted.
                        if isvalid(app.UdpTimeoutTimer)
                            stop(app.UdpTimeoutTimer)
                        end
                        delete(app.UdpTimeoutTimer) % maybe it's an overkill?
                        app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                            app.TimeoutTime.Value,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
            %             The timer is started before sending the UDP message. This is
            %             to avoid that a UDP answer arrives (and stops the timer)
            %             before the timer is started, since the two objects are
            %             asynchronous.
                        start(app.UdpTimeoutTimer)
                    end
                end
                
            else
%                 If the connection was ongoing, the button is used to
%                 cancel it.
                stopasync(app.UdpObj)
                fclose(app.UdpObj);
%                 The button is reenabled, if it was not.
                EnableGoBtn(app,1)
%                 All the connection parameters cannot be changed while the
%                 connection is ongoing. So now they can be changed again
                set([app.MasterIp,app.MasterMac,app.MasterPort,app.SlaveIp,app.SlavePort,app.SubnetMask],'Editable',1);%                 If for some reason the timer for UDP timeout is running,
%                 it is stopped.
                if isvalid(app.UdpTimeoutTimer)
                    stop(app.UdpTimeoutTimer)
                end
%                 The connection is undone, so the buttons and other stuff must be changed
%                 accordingly.
%                 Revert the button back to its status. Also the flag value
%                 is set back to 0.
                app.StartUdpBtn.Text = 'Start Connection';
                app.ConnectionStatus.Value = 'Disconnected.';
                app.ResendScreenParams.Enable = 1;
                app.UDPConnectionLamp.Color = [1 0 0];
                app.UdpConnectionFlag = 0;
            end
        end

        % Button pushed function: ResendScreenParams
        function ResendScreenParamsButtonPushed(app, event)
            msg = ScreenParameterMsg(app);
            SendUdpMsg(app,msg,app.TimeoutTime.Value)
        end

        % Button pushed function: DeBusyButton
        function DeBusyButtonPushed(app, event)
            EnableGoBtn(app,1)
        end

        % Value changed function: ScreenDistance
        function ScreenDistanceValueChanged(app, event)
            SetScreenSizeAngle(app)
            PixelAngleCalc(app)
        end

        % Button pushed function: SaveScreenFileButton
        function SaveScreenFileButtonPushed(app, event)
            [file,path] = uiputfile({'*.dat;*.csv;*.txt',...
                'Text files (*.dat,*.csv,*.txt)';...
                '*.*',  'All Files (*.*)'}, 'Save a File');
            if file==0
                return
            end
            
            T = table;
            
            T.name{1} = app.ScreenNameTxt.Value;
            T.width(1) = app.ScreenWidth.Value;
            T.height(1) = app.ScreenHeight.Value;
            T.formula{1} = app.ScreenFuncTxt.Value;
            T.notes{1} = app.ScreenNotesTxt.Value;
            
            writetable(T,[path, file])
        end

        % Value changed function: MasterIp
        function MasterIpValueChanged(app, event)
            if ~ValidateIPv4(app.MasterIp.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.MasterIp.Value = event.PreviousValue;
            end
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Value changed function: SubnetMask
        function SubnetMaskValueChanged(app, event)
            if ~ValidateIPv4(app.SubnetMask.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.SubnetMask.Value = event.PreviousValue;
            end
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Value changed function: SlaveIp
        function SlaveIpValueChanged(app, event)
            if ~ValidateIPv4(app.SlaveIp.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.SlaveIp.Value = event.PreviousValue;
            end
        end

        % Value changed function: MasterMac
        function MasterMacValueChanged(app, event)
            if ~ValidateMac(app.MasterMac.Value)
                uialert(app.EscherMasterFig,'Invalid MAC','Error')
                app.MasterMac.Value = event.PreviousValue;
            end
        end

        % Value changed function: wwTxt
        function wwTxtValueChanged(app, event)
            HermesRectCalc(app)
        end

        % Value changed function: hhTxt
        function hhTxtValueChanged(app, event)
            HermesRectCalc(app)
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            HermesRectCalc(app)            
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            HermesRectCalc(app)            
        end

        % Value changed function: ScreenFuncTxt
        function ScreenFuncTxtValueChanged(app, event)
            app.ScreenFunc = app.ScreenFuncTxt.Value;
        end

        % Value changed function: STAstartTxt
        function STAstartTxtValueChanged(app, event)
            if ~ValidateIPv4(app.STAstartTxt.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.STAstartTxt.Value = event.PreviousValue;
            end            
        end

        % Value changed function: STAendTxt
        function STAendTxtValueChanged(app, event)
            if ~ValidateIPv4(app.STAendTxt.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.STAendTxt.Value = event.PreviousValue;
            end
        end

        % Button pushed function: FindPCOButton
        function FindPCOButtonPushed(app, event)
            PcoInitialize(app)
        end

        % Button pushed function: PcoStartPreviewButton
        function PcoStartPreviewButtonPushed(app, event)
            if app.PcoStruct.preview_status==0
                
%                 if app.PcoStruct.ArmedFlag == 0
%                     PcoPreImage(app)
%                 end
%               
                HermesMsgMake(app, 'mod', 'camPr','s')
                
                app.PcoStruct.preview_status = 1;
                
                % Creating the preview timer
                % This is a timer object for the preview that executes at a high rate.
                % The busymode 'drop' makes sure that if the preview rate is too high, the
                % timer execution is just dropped and it doesn't clog anything
                app.PcoStruct.PreviewTimer = timer('BusyMode','drop','ExecutionMode','singleShot',...
                    'StartDelay',0.001,'TimerFcn',@(~,~) PcoPreviewTimerFcn(app),'StopFcn',@(~,~)delete(app.PcoStruct.PreviewTimer));
                start(app.PcoStruct.PreviewTimer)
                                
                app.PcoStartPreviewButton.Text = 'Stop Preview';
                
            else
                app.PcoStruct.preview_status = 0;
                HermesMsgMake(app,'parameters','stopTrig')
                pause(1);
                
                app.PcoStartPreviewButton.Text = 'Start Preview';
            end
        end

        % Value changed function: PcoCDataMinEditField
        function PcoCDataMinEditFieldValueChanged(app, event)
            app.PcoHAxes.CLim(1) = app.PcoCDataMinEditField.Value;
        end

        % Value changed function: PcoCDataMaxEditField
        function PcoCDataMaxEditFieldValueChanged(app, event)
            app.PcoHAxes.CLim(2) = app.PcoCDataMaxEditField.Value;
        end

        % Value changed function: ExposureTimeEditField
        function ExposureTimeEditFieldValueChanged(app, event)
%             When PCO camera is armed, the corresponding flag is set.
            app.PcoStruct.ArmedFlag = 0;
            app.PCOarmedLamp.Color = [1,0,0];
            app.PCOunarmedLabel.Text = 'PCO un-armed';
%             And the preview and acquire button are enabled
            app.PcoStartPreviewButton.Enable = 0;
            app.PcoStartAcquisitionButton.Enable = 0;
            
        end

        % Button pushed function: PcoStartAcquisitionButton
        function PcoStartAcquisitionButtonPushed(app, event)
            switch app.PcoStruct.SelSyncMode
                case 1
%                     HermesMsg = []; there is no HermesMsg anymore
                case 2
%                     Create a message to Hermes thay says to perform a
%                     series ('s') of a certain number of frames and with 1
%                     trial and trial period zero.
                    HermesMsgMake(app,'mod','camAc','s',round(app.PcoNumberFramesEditField.Value),1,0);
            end
            PcoStartAcquisition(app,app.PcoStruct.imah)
        end

        % Value changed function: RecordingsubmodeDropDown
        function RecordingsubmodeDropDownValueChanged(app, event)
%             Take the selected submode.
            RecSubmodeIdx = find(strcmp(app.RecordingsubmodeDropDown.Value,app.RecordingsubmodeDropDown.Items));
            if ~isscalar(RecSubmodeIdx)
                error('Invalid choice: two items have the same name!')
            end
        %             Set the recorder submode
            switch RecSubmodeIdx
                case 1
                    app.PcoStruct.SelRecSubmode = uint16(0);
                case 2
                    app.PcoStruct.SelRecSubmode = uint16(1);
            end
        end

        % Button pushed function: ArmPCOButton
        function ArmPCOButtonPushed(app, event)
            PcoInitialize(app)
        end

        % Value changed function: PcoSyncDropDown
        function PcoSyncDropDownValueChanged(app, event)
%             Take the selected synchronization mode for PCO acquisition.
            PcoSyncModeIdx = find(strcmp(app.PcoSyncDropDown.Value,app.PcoSyncDropDown.Items));
            if ~isscalar(PcoSyncModeIdx)
                error('Invalid choice: two items have the same name!')
            end
            
%             Set the maximum frequency, if it is zero then it is set to
%             Inf.
            if app.PcoMaxFreqEditField.Value ~= 0
                app.PcoSelectedFreqEditField.Limits = [0,app.PcoMaxFreqEditField.Value];
            else
                app.PcoSelectedFreqEditField.Limits = [0,Inf];
            end
        %             Set the synchronization mode.
            switch PcoSyncModeIdx
                case 1
%                     Independent acquisition: only duration is set. Then
%                     the PCO acquires at maximum speed for the duration.
                    app.PcoSelectedFreqEditField.Enable = 0;
                    app.PcoSelectedFreqEditField.Value = app.PcoMaxFreqEditField.Value;
%                     Auto sequence, no trigger is used.
                    app.PcoStruct.SelTriggerMode = uint16(0);
                case 2
%                     Selection relying on Hermes.
                    app.PcoSelectedFreqEditField.Enable = 1;
%                     This sets the trigger mode to 2 (external exposure
%                     start).
                    app.PcoStruct.SelTriggerMode = uint16(2);
%                     If the selected frequency is zero, its value is set
%                     to the maximum frequency.
                    if app.PcoSelectedFreqEditField.Value == 0
                        app.PcoSelectedFreqEditField.Value = app.PcoMaxFreqEditField.Value;
                    end
                    if app.HermesSerialFlag
                        HermesMsgMake(app,'parameters',{'TTL_time','frameP','idleTime','serial_delay'})
                    end

            end
            app.PcoStruct.SelSyncMode = PcoSyncModeIdx;
            
            PcoSelectedFreqEditFieldValueChanged(app,[])
        end

        % Value changed function: PcoSelectedFreqEditField
        function PcoSelectedFreqEditFieldValueChanged(app, event)
            app.PcoNumberFramesEditField.Value = fix(app.PcoSelectedFreqEditField.Value*app.PcoDurationEditField.Value);
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','frameP')
            end
        end

        % Button pushed function: RefreshCOMButton
        function RefreshCOMButtonPushed(app, event)
%             if isa(app.HermesSerialObj, 'serial')
%                 fclose(app.HermesSerialObj);
%             end
            app.SerialCOMDropDown.Items = cellstr([serialportlist,"NONE"]);
            for i=1:length(app.SerialCOMDropDown.Items)
                try
                    app.SerialCOMDropDown.Value = app.SerialCOMDropDown.Items{1};
                    HermesConnect(app,app.SerialCOMDropDown.Value)
                catch ME
                    warning([ME,'\nCouldn''t start serial communication on ',app.SerialCOMDropDown.Value,' port.'],'%s')
                end
            end
        end

        % Value changed function: SerialCOMDropDown
        function SerialCOMDropDownValueChanged(app, event)
%             if isa(app.HermesSerialObj, 'serial')
%                 fclose(app.HermesSerialObj);
%             end
            
            if ~strcmp(app.SerialCOMDropDown.Value,"NONE")
                try
                    HermesConnect(app,app.SerialCOMDropDown.Value)
                    app.HermesSerialFlag = 1;
                catch ME
                    uialert(app.EscherMasterFig,ME.message,'Warning')
                    app.HermesSerialFlag = 0;
                end
                
            end            
        end

        % Button pushed function: PcoAutoCLimButton
        function PcoAutoCLimButtonPushed(app, event)
            if isfield(app.PcoStruct,'imah')
                limits = quantile(app.PcoStruct.imah.CData,[0.025,0.975],'all');
                app.PcoCDataMinEditField.Value = cast(limits(1),'double');
                app.PcoCDataMaxEditField.Value = cast(limits(2),'double');
                app.PcoHAxes.CLim = limits;
            end
        end

        % Button pushed function: PcoAutoCLimStimButton
        function PcoAutoCLimStimButtonPushed(app, event)
            if isfield(app.PcoStruct,'imahStim')
                limits = quantile(app.PcoStruct.imahStim.CData,[0.025,0.975],'all');
                app.PcoCDataStimMinEditField.Value = cast(limits(1),'double');
                app.PcoCDataStimMaxEditField.Value = cast(limits(2),'double');
                app.PcoHAxesStim.CLim = limits;
            end
        end

        % Value changed function: PcoDurationEditField
        function PcoDurationEditFieldValueChanged(app, event)
            app.PcoNumberFramesEditField.Value = fix(app.PcoSelectedFreqEditField.Value*app.PcoDurationEditField.Value);
        end

        % Value changed function: HermesInitialDelaysEditField
        function HermesInitialDelaysEditFieldValueChanged(app, event)
%             This time is rounded to the third decimal digit.
            app.HermesInitialDelaysEditField.Value = round(app.HermesInitialDelaysEditField.Value,3);
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','serial_delay')
            end
        end

        % Value changed function: HermesTtlDurationEditField
        function HermesTtlDurationEditFieldValueChanged(app, event)
            app.HermesTtlDurationEditField.Value = round(app.HermesTtlDurationEditField.Value,3);
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','TTL_time')
            end
        end

        % Callback function
        function HermesLedDurationEditFieldValueChanged(app, event)
            value = round(app.HermesLedDurationEditField.Value,3);
            if value ~= 0 || value<app.HermesTtlDurationEditField.Value
                value = app.HermesTtlDurationEditField.Value;
                uialert(app.EscherMasterFig,'The value must be either >= TTL duration or equal to zero.','Warning')
            end
            app.HermesLedDurationEditField.Value = value;
        end

        % Button pushed function: PuffGo
        function PuffGoButtonPushed(app, event)
            % 2021/04/04 this function does not work anymore
            
            p = app.PuffPeriod.Value;
            n = app.PuffRepetitions.Value;
            
            
            if app.PcoStruct.ArmedFlag == 1
%                 Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,p,n,0)
                
%                 FrameN is the number of frames acquired in each trial
                FrameN = fix(app.PcoSelectedFreqEditField.Value*p)-2;
                app.PcoNumberFramesEditField.Value = FrameN*n;
                
%                 Create a message for Hermes
                HermesMsgMake(app,'mod','camAc','s',FrameN,n,1000*p);
                disp(HermesMsg)

%                 Send the message to Hermes.
                PcoStartAcquisition(app,app.PcoStruct.imahStim,HermesMsg)
            else
                FrameN = 1;
                %                 Create a message for Hermes
                HermesMsg = HermesMsgMake(app,'p','p',FrameN,1000*p);
                disp(HermesMsg)
                writeline(app.HermesSerialObj,HermesMsg);
            end
            
        end

        % Value changed function: HermesIdleTimeEditField
        function HermesIdleTimeEditFieldValueChanged(app, event)
%             value = app.HermesIdleTimeEditField.Value;
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','idleTime')
            end
        end

        % Button pushed function: IntrinsicGOButton
        function IntrinsicGOButtonPushed(app, event)
            
%             If the "PCO while stimulating" box is checked, Hermes must be
%             also connected with serial communication.
            if app.PcoWhileStimulusCk.Value == 1 && app.HermesSerialFlag == 0
            	uialert(app.EscherMasterFig,['Hermes must be connected in serial mode...',...
                 'to allow PCO recording while presenting visual stimulations.'],'Error')
             return
            end
            
            %             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            if app.IntrinsicDefaultParametersCheckBox.Value
                % To be filled with default values.
                if app.CalibratedButton.Value
%                     If the calibration of the screen is being used,
%                     Consider the lowest and higherst possible values
%                     permitted by the screen.
                    [~,Blumi] = Lumi2Escher(0, app.white, app.ScreenFunc);
                    [~,Slumi] = Lumi2Escher(200, app.white, app.ScreenFunc);
                    Glumi = (Blumi+Slumi)/2;
                else
%                     If the calibration of the screen is not being used,
%                     set minimum and maximum values for black and white,
%                     that are 0 and 1 in escher values.
                    Blumi = 0;
                    Slumi = 1;
                    Glumi = 0.5;
                end
                sF = 0.2; % spatial frequency (cycles/degree) of the checkerboard.
                Bt = app.initialDelay.Value; % initial delay (seconds, it happens only once).
                p = 0.1; % hemi-period (seconds). p is the time between a reversal and the next (2 reversals = 1 period)
                dS = 10; % drifting speed of the stimulus in degrees/s.
                PostG = 1; % post-grid duration. It's the pause length after the drifting rectangle (in seconds).
                n = app.GridRepetitions.Value; % number of repetitions of the stimulus
            else
                % load parameters in local variables
                Blumi = app.CheckLumiB.Value;
                Slumi = app.CheckLumiW.Value;
%             gray luminance for equiluminant background.
                Glumi = app.CheckLumiMean.Value;
                sF = app.spatialFrequency.Value; % spatial frequency of the checkerboard
                Bt = app.initialDelay.Value;
                p = app.CheckPeriod.Value; % hemi-period. p is the time between a reversal and the next (2 reversals = 1 period)
                dS = app.DriftingSpeed_txt.Value; % drifting speed of the stimulus in cycles/s.
                PostG = app.PostGridDuration.Value; % post-grid duration
                n = app.GridRepetitions.Value; % number of repetitions of the stimulus
            end
            
            % angle of the stimulus. 0 means vertical bars.
            if strcmp(app.IntrOrientationSwitch.Value,'Vertical')
                Angle = 0;
            else
                Angle = 90;
            end
            
%             The default grating movement will always be from left to
%             right, then the angle is changed.
            if ismember(app.IntrDirectionSwitch.Value,{'Right-to-left','Top-to-bottom'})
                Angle = Angle + 180;
            end
            
            RectangleSizeDeg = app.IntrRectSizeTxt.Value; % Rectangle size in degrees
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.intrimg', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 13],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 11]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','sF','Bt','p','Angle','RectangleSizeDeg','dS','PostG','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [Blumi,Slumi,Glumi,...
                sF,Bt,p,Angle,RectangleSizeDeg,dS,PostG,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
%             Other parameters to pass
            OneScreenFlag = app.OneScreenMode_ck.Value;
            CalibrationFlag = app.CalibratedButton.Value;
            oculusFlag = app.oculusCk.Value;
            ard_flag = app.ard_ck.Value;
            optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            
%             ParameterVector is the vector of parameters that is passed as
%             UDP message. If UDP is not used, it is useful anyway as input
%             for the stimulus-presenting function.
            ParameterVector = [Blumi,Slumi,Glumi,sF,Bt,p,Angle,RectangleSizeDeg,dS,PostG,n,...
                OneScreenFlag,CalibrationFlag,oculusFlag,ard_flag,optDtrTime];
            
%             Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
%                 Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,p,2*n,2*Bt)

%                 Create a message for Hermes
%                 HermesMsg = HermesMsgMake(app,'o');
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/(2*n));
                
%                 Send the message to Hermes.
                PcoStartAcquisition(app,app.PcoStruct.imahStim,HermesMsg)
            end

            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'check'},arrayfun(@num2str,ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                return
            end
            
%             Additional parameters to be passed: flags and synchronization
%             parameters.
            OneScreenFlag = app.OneScreenMode_ck.Value;
            CalibrationFlag = app.CalibratedButton.Value;
            oculusFlag = app.oculusCk.Value;
            ard_flag = app.ard_ck.Value;
            optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            
%             Let's add some parameters to the ParameterVector for the
%             CheckPresent function.
            ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,oculusFlag,ard_flag,optDtrTime]);
%             Now starts the function for the presentation of the stimulus.
            timestamps = IntrinsicPresent(app,ParameterVector);
                        
%             Log writing.
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
            app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
            %             Enables all "Go" buttons
            EnableGoBtn(app,1)
        end

        % Value changed function: IntrOrientationSwitch
        function IntrOrientationSwitchValueChanged(app, event)
            if strcmp(app.IntrOrientationSwitch.Value, 'Vertical')
                app.IntrDirectionSwitch.Items = {'Left-to-right','Right-to-left'};
            else
                app.IntrDirectionSwitch.Items = {'Bottom-to-top','Top-to-bottom'};
            end
        end

        % Value changed function: OpenFieldAnalyzerButton
        function OpenFieldAnalyzerButtonValueChanged(app, event)
            value = app.OpenFieldAnalyzerButton.Value;
            if value
%                 If selected value is 1, it means that the analyzer app
%                 will be opened, and the button must be used to close it
%                 next time it will be pressed.
%                 Here, code to open the analyzer must be placed.
                app.VisualFieldAnalyzerApp = visualFieldAnalyzer2;
                app.OpenFieldAnalyzerButton.Text = 'Close Field Analyzer';
            else
                selection = uiconfirm(app.EscherMasterFig,'Do you really want to close the Analyzer?','Warning');
                if strcmp(selection,'OK')
%                     Here code to close it must be placed.
                    delete(app.VisualFieldAnalyzerApp)
                    app.OpenFieldAnalyzerButton.Text = 'Open Field Analyzer';
                else
                    app.OpenFieldAnalyzerButton.Value = 1;
                    app.OpenFieldAnalyzerButton.Text = 'Close Field Analyzer';
                end
            end
        end

        % Value changed function: optoStartEditField
        function optoStartEditFieldValueChanged(app, event)
            HermesMsgMake(app,'parameters','optoStart')
        end

        % Value changed function: optoDurEditField
        function optoDurEditFieldValueChanged(app, event)
            HermesMsgMake(app,'parameters','optoDur')
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            mode = app.ModeDropDown.Value;
            switch app.TriggerDropDown.Value
                case 'phototransistor'
                    trig = 'p';
                case 'serial'
                    trig = 's';
            end
            HermesMsgMake(app,'mod',mode,trig)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EscherMasterFig = uifigure('Visible', 'off');
            app.EscherMasterFig.Name = 'EscherMaster';
            app.EscherMasterFig.CloseRequestFcn = createCallbackFcn(app, @EscherMasterFigCloseRequest, true);
            app.TabGroup = uitabgroup(app.EscherMasterFig);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.uipanel6 = uipanel(app.SettingsTab);
            app.uipanel6.Title = 'Setting Screen and Synchronisation';
            app.OpenScreenBtn = uibutton(app.uipanel6, 'push');
            app.OpenScreenBtn.ButtonPushedFcn = createCallbackFcn(app, @OpenScreenBtn_Callback, true);
            app.OpenScreenBtn.Tooltip = {'Open screen when in dual monitor mode (default)'};
            app.OpenScreenBtn.Text = 'Open Screen';
            app.RefreshStBy = uibutton(app.uipanel6, 'push');
            app.RefreshStBy.ButtonPushedFcn = createCallbackFcn(app, @RefreshStByButtonPushed, true);
            app.RefreshStBy.Tooltip = {'Fill screen with stand by luminance'};
            app.RefreshStBy.Text = 'Standby';
            app.OneScreenMode_ck = uicheckbox(app.uipanel6);
            app.OneScreenMode_ck.Text = 'One Screen Mode';
            app.OneScreenMode_ck.Value = true;
            app.oculusCk = uicheckbox(app.uipanel6);
            app.oculusCk.Text = 'VR goggles';
            app.SetstandbyluminanceEditFieldLabel = uilabel(app.uipanel6);
            app.SetstandbyluminanceEditFieldLabel.Text = 'Set standby luminance';
            app.StandbyL = uieditfield(app.uipanel6, 'numeric');
            app.LabelLumi_1 = uilabel(app.uipanel6);
            app.LabelLumi_1.Text = 'cd/m²';
            app.ApplydistortionCheckBox = uicheckbox(app.uipanel6);
            app.ApplydistortionCheckBox.Text = 'Apply distortion';
            app.ApplydistortionCheckBox.Value = true;
            app.uipanel4 = uipanel(app.SettingsTab);
            app.uipanel4.Title = 'Monitor Size and Geometry';
            app.ScreendistancecmLabel = uilabel(app.uipanel4);
            app.ScreendistancecmLabel.Tooltip = {'Enter the three parameters definig the screen angular size. '};
            app.ScreendistancecmLabel.Text = 'Screen distance (cm):';
            app.ScreenDistance = uieditfield(app.uipanel4, 'numeric');
            app.ScreenDistance.Limits = [0 120];
            app.ScreenDistance.RoundFractionalValues = 'on';
            app.ScreenDistance.ValueDisplayFormat = '%4.3g';
            app.ScreenDistance.ValueChangedFcn = createCallbackFcn(app, @ScreenDistanceValueChanged, true);
            app.ScreenDistance.Value = 30;
            app.WidthpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.WidthpixelsEditFieldLabel.Text = 'Width (pixels):';
            app.WidthPix = uieditfield(app.uipanel4, 'numeric');
            app.WidthPix.Limits = [0 Inf];
            app.WidthPix.RoundFractionalValues = 'on';
            app.WidthPix.Editable = 'off';
            app.WidthPix.Value = 1240;
            app.HeightpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.HeightpixelsEditFieldLabel.Text = 'Height (pixels):';
            app.HeightPix = uieditfield(app.uipanel4, 'numeric');
            app.HeightPix.Limits = [0 Inf];
            app.HeightPix.RoundFractionalValues = 'on';
            app.HeightPix.Editable = 'off';
            app.HeightPix.Value = 800;
            app.WidthcmLabel = uilabel(app.uipanel4);
            app.WidthcmLabel.Text = 'Width (cm)';
            app.ScreenWidth = uieditfield(app.uipanel4, 'numeric');
            app.ScreenWidth.Limits = [0 Inf];
            app.ScreenWidth.ValueChangedFcn = createCallbackFcn(app, @ScreenWidthValueChanged, true);
            app.ScreenWidth.Value = 34;
            app.HeightcmEditFieldLabel = uilabel(app.uipanel4);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.ScreenHeight = uieditfield(app.uipanel4, 'numeric');
            app.ScreenHeight.Limits = [0 Inf];
            app.ScreenHeight.ValueChangedFcn = createCallbackFcn(app, @ScreenHeightValueChanged, true);
            app.ScreenHeight.Value = 27;
            app.AngularsizeofscreenEditFieldLabel = uilabel(app.uipanel4);
            app.AngularsizeofscreenEditFieldLabel.Text = 'Angular size of screen';
            app.ScreenSizeAngle = uieditfield(app.uipanel4, 'text');
            app.SynchronizationPanel = uipanel(app.SettingsTab);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.ard_ck = uicheckbox(app.SynchronizationPanel);
            app.ard_ck.Tooltip = {'Display the Hermes synchronisation cue'};
            app.ard_ck.Text = 'Hermes Synch';
            app.ard_ck.Value = true;
            app.HeightpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.hhTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.hhTxt.Limits = [0 Inf];
            app.hhTxt.ValueChangedFcn = createCallbackFcn(app, @hhTxtValueChanged, true);
            app.hhTxt.Value = 100;
            app.CueWidthpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.CueWidthpxEditFieldLabel.Text = 'Cue Width (px)';
            app.wwTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.wwTxt.Limits = [0 Inf];
            app.wwTxt.ValueChangedFcn = createCallbackFcn(app, @wwTxtValueChanged, true);
            app.wwTxt.Value = 100;
            app.CuepositionPanel = uipanel(app.SynchronizationPanel);
            app.CuepositionPanel.Title = 'Cue position';
            app.ButtonGroup_2 = uibuttongroup(app.CuepositionPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.CueTopRB = uiradiobutton(app.ButtonGroup_2);
            app.CueTopRB.Text = 'Top';
            app.CueBotRB = uiradiobutton(app.ButtonGroup_2);
            app.CueBotRB.Text = 'Bottom';
            app.CueBotRB.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.CuepositionPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.CueLeftRB = uiradiobutton(app.ButtonGroup_3);
            app.CueLeftRB.Text = 'Left';
            app.CueRightRB = uiradiobutton(app.ButtonGroup_3);
            app.CueRightRB.Text = 'Right';
            app.CueRightRB.Value = true;
            app.DurationofcuemsEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.DurationofcuemsEditFieldLabel.Text = 'Duration of cue (ms):';
            app.optDtrTimeTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.optDtrTimeTxt.Limits = [0 Inf];
            app.optDtrTimeTxt.Value = 100;
            app.UDPConnectionPanel = uipanel(app.SettingsTab);
            app.UDPConnectionPanel.Title = 'UDP Connection';
            app.StartUdpBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.StartUdpBtn.ButtonPushedFcn = createCallbackFcn(app, @StartUdpBtnButtonPushed, true);
            app.StartUdpBtn.Tooltip = {'Start UDP connection to the slave Escher app. The slave must be in listening mode.'};
            app.StartUdpBtn.Text = 'Start Connection';
            app.SlaveIPLabel = uilabel(app.UDPConnectionPanel);
            app.SlaveIPLabel.Text = 'Slave IP:';
            app.SlaveIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveIp.ValueChangedFcn = createCallbackFcn(app, @SlaveIpValueChanged, true);
            app.SlaveIp.Tooltip = {'Set here the IP address of the slave app.'};
            app.SlaveIp.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port:';
            app.SlavePort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.SlavePort.Limits = [1023 65535];
            app.SlavePort.RoundFractionalValues = 'on';
            app.SlavePort.ValueDisplayFormat = '%8.5g';
            app.SlavePort.Tooltip = {'Port for the UDP connection on the slave side. It must be the same port on which the slave is listening. Default: 8866.'};
            app.SlavePort.Value = 8866;
            app.MasterIPLabel = uilabel(app.UDPConnectionPanel);
            app.MasterIPLabel.Text = 'Master IP:';
            app.MasterIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterIp.ValueChangedFcn = createCallbackFcn(app, @MasterIpValueChanged, true);
            app.MasterIp.Tooltip = {'Set here the IP address of the master (this app).'};
            app.MasterIp.Value = '0.0.0.0';
            app.PortEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel_2.Text = 'Port:';
            app.MasterPort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.MasterPort.Limits = [1023 65535];
            app.MasterPort.RoundFractionalValues = 'on';
            app.MasterPort.ValueDisplayFormat = '%8.5g';
            app.MasterPort.Tooltip = {'Port for the UDP connection on the master side. Default: 8844.'};
            app.MasterPort.Value = 8844;
            app.GetMasterIPandMACBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.GetMasterIPandMACBtn.ButtonPushedFcn = createCallbackFcn(app, @GetMasterIPandMACBtnPushed, true);
            app.GetMasterIPandMACBtn.Tooltip = {'Get the IP and MAC of this computer (master).'};
            app.GetMasterIPandMACBtn.Text = 'Get Master IP and MAC';
            app.StatusEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.StatusEditFieldLabel_2.Text = 'Status:';
            app.ConnectionStatus = uieditfield(app.UDPConnectionPanel, 'text');
            app.ConnectionStatus.Editable = 'off';
            app.ConnectionStatus.Value = 'Disconnected.';
            app.MACLabel_3 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_3.Text = 'MAC:';
            app.MasterMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterMac.ValueChangedFcn = createCallbackFcn(app, @MasterMacValueChanged, true);
            app.MasterMac.Tooltip = {'The physical address of the master (this app). It is used by the app as identifier.'};
            app.MasterMac.Value = '000000000000';
            app.MACLabel_2 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_2.Text = 'MAC:';
            app.SlaveMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveMac.Editable = 'off';
            app.SlaveMac.Tooltip = {'The physical address of the slave app. It is going to be given by the slave itself.'};
            app.SlaveMac.Value = '000000000000';
            app.TimeouttimesEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.TimeouttimesEditFieldLabel.Text = 'Timeout time (s)';
            app.TimeoutTime = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.TimeoutTime.Limits = [0 Inf];
            app.TimeoutTime.Tooltip = {'The time after which'; ' if the master does not receive any answer from the slave'; ' the UDP connection will be cut.'};
            app.TimeoutTime.Value = 20;
            app.ResendScreenParams = uibutton(app.UDPConnectionPanel, 'push');
            app.ResendScreenParams.ButtonPushedFcn = createCallbackFcn(app, @ResendScreenParamsButtonPushed, true);
            app.ResendScreenParams.Enable = 'off';
            app.ResendScreenParams.Tooltip = {'Send again the screen parameters of the Settings Tab to the slave app.'};
            app.ResendScreenParams.Text = {'Resend Screen'; 'Parameters'};
            app.SubnetmaskEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.SubnetmaskEditFieldLabel.Text = 'Subnet mask';
            app.SubnetMask = uieditfield(app.UDPConnectionPanel, 'text');
            app.SubnetMask.ValueChangedFcn = createCallbackFcn(app, @SubnetMaskValueChanged, true);
            app.SubnetMask.Tooltip = {'Subnet mask of the network (only for general UDP broadcast)'};
            app.SubnetMask.Value = '0.0.0.0';
            app.BroadcastEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.BroadcastEditFieldLabel.Text = 'Broadcast';
            app.BroadcastAddress = uieditfield(app.UDPConnectionPanel, 'text');
            app.BroadcastAddress.Value = '0.0.0.0.';
            app.UDPBroadcastDropDownLabel = uilabel(app.UDPConnectionPanel);
            app.UDPBroadcastDropDownLabel.Text = 'UDP Broadcast:';
            app.UDPBroadcastDropDown = uidropdown(app.UDPConnectionPanel);
            app.UDPBroadcastDropDown.Items = {'No broadcast', 'Broadcast', 'Send to all'};
            app.UDPBroadcastDropDown.Tooltip = {'Choose how the connection is started:'; '-No broadcast. The communication is started only with slave IP typed by the user.'; '-Broadcast. The communication is started through a true UDP broadcast. Slave IP is ignored and the connection is set with the computer that answers.'; '-Send to all. A communication is sent to all IPs in the subnet.'};
            app.UDPBroadcastDropDown.Value = 'No broadcast';
            app.SendtoallstartaddressLabel = uilabel(app.UDPConnectionPanel);
            app.SendtoallstartaddressLabel.Text = {'Send-to-all'; 'start address'};
            app.STAstartTxt = uieditfield(app.UDPConnectionPanel, 'text');
            app.STAstartTxt.ValueChangedFcn = createCallbackFcn(app, @STAstartTxtValueChanged, true);
            app.STAstartTxt.Value = '0.0.0.0';
            app.SendtoallendaddressEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.SendtoallendaddressEditFieldLabel.Text = {'Send-to-all'; 'end address'};
            app.STAendTxt = uieditfield(app.UDPConnectionPanel, 'text');
            app.STAendTxt.ValueChangedFcn = createCallbackFcn(app, @STAendTxtValueChanged, true);
            app.STAendTxt.Value = '0.0.0.0';
            app.LoadScreenInformationPanel = uipanel(app.SettingsTab);
            app.LoadScreenInformationPanel.Title = 'Load Screen Information';
            app.LoadScreenFileButton = uibutton(app.LoadScreenInformationPanel, 'push');
            app.LoadScreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScreenFileButtonPushed, true);
            app.LoadScreenFileButton.Text = 'Load Screen File';
            app.ScreennameEditFieldLabel = uilabel(app.LoadScreenInformationPanel);
            app.ScreennameEditFieldLabel.Text = 'Screen name';
            app.ScreenNameTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenNameTxt.Value = 'asus large B0';
            app.ScreenTransferFormulaLabel = uilabel(app.LoadScreenInformationPanel);
            app.ScreenTransferFormulaLabel.Text = {'Screen '; 'Transfer Formula:'};
            app.ScreenFuncTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenFuncTxt.ValueChangedFcn = createCallbackFcn(app, @ScreenFuncTxtValueChanged, true);
            app.ScreenFuncTxt.Value = '@(x)11.25*x.^1.9';
            app.NotesLabel = uilabel(app.LoadScreenInformationPanel);
            app.NotesLabel.Text = 'Notes:';
            app.ScreenNotesTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenNotesTxt.Value = 'standard ; K=100; B=0';
            app.SaveScreenFileButton = uibutton(app.LoadScreenInformationPanel, 'push');
            app.SaveScreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScreenFileButtonPushed, true);
            app.SaveScreenFileButton.Text = 'Save Screen File';
            app.luminanceLabel = uilabel(app.LoadScreenInformationPanel);
            app.luminanceLabel.Text = 'luminance=';
            app.CalModeRBGroup = uibuttongroup(app.LoadScreenInformationPanel);
            app.CalModeRBGroup.SelectionChangedFcn = createCallbackFcn(app, @CalModeRBGroupSelectionChanged, true);
            app.CalModeRBGroup.Title = 'Mode:';
            app.UncalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.UncalibratedButton.Text = 'Uncalibrated';
            app.CalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.CalibratedButton.Text = 'Calibrated';
            app.CalibratedButton.Value = true;
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.FlashPanel = uipanel(app.StimulationTab);
            app.FlashPanel.Title = 'Full Screen Flash';
            app.BaselineluminanceLabel = uilabel(app.FlashPanel);
            app.BaselineluminanceLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.BaselineluminanceLabel.Text = 'Baseline luminance:';
            app.FSbaselineL = uieditfield(app.FlashPanel, 'numeric');
            app.FSbaselineL.Limits = [0 Inf];
            app.FSbaselineL.ValueDisplayFormat = '%5.3g';
            app.FSbaselineL.ValueChangedFcn = createCallbackFcn(app, @FSbaselineLValueChanged, true);
            app.FSbaselineL.Tooltip = {'Luminance of the background'};
            app.FlashluminanceLabel = uilabel(app.FlashPanel);
            app.FlashluminanceLabel.Text = {'Flash luminance:'; ''};
            app.FSflashL = uieditfield(app.FlashPanel, 'numeric');
            app.FSflashL.Limits = [0 Inf];
            app.FSflashL.ValueChangedFcn = createCallbackFcn(app, @FSflashLValueChanged, true);
            app.FSflashL.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashL.Value = 0.8;
            app.LabelLumi_2 = uilabel(app.FlashPanel);
            app.LabelLumi_2.Text = 'cd/m²';
            app.LabelLumi_3 = uilabel(app.FlashPanel);
            app.LabelLumi_3.Text = 'cd/m²';
            app.FSbaselineLEsch = uieditfield(app.FlashPanel, 'numeric');
            app.FSbaselineLEsch.Limits = [0 Inf];
            app.FSbaselineLEsch.ValueDisplayFormat = '%5.3g';
            app.FSbaselineLEsch.Editable = 'off';
            app.FSbaselineLEsch.Tooltip = {'Luminance of the background'};
            app.LabelLumiEsch_2 = uilabel(app.FlashPanel);
            app.LabelLumiEsch_2.Text = '(0-1)';
            app.FSflashLEsch = uieditfield(app.FlashPanel, 'numeric');
            app.FSflashLEsch.Limits = [0 Inf];
            app.FSflashLEsch.Editable = 'off';
            app.FSflashLEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashLEsch.Value = 0.8;
            app.LabelLumiEsch_3 = uilabel(app.FlashPanel);
            app.LabelLumiEsch_3.Text = '(0-1)';
            app.FastFlashStimulusSelectionPanel = uipanel(app.FlashPanel);
            app.FastFlashStimulusSelectionPanel.Title = 'Fast Flash Stimulus Selection';
            app.FlashFastTable = uitable(app.FastFlashStimulusSelectionPanel);
            app.FlashFastTable.ColumnName = {'Stim. n.'; 'Flash L'; 'Base L'};
            app.FlashFastTable.RowName = {};
            app.FlashFastTable.ColumnEditable = [false true true];
            app.noftotstimLabel = uilabel(app.FastFlashStimulusSelectionPanel);
            app.noftotstimLabel.Text = 'n. of tot stim';
            app.FlashFastTotN = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastTotN.Limits = [1 Inf];
            app.FlashFastTotN.RoundFractionalValues = 'on';
            app.FlashFastTotN.ValueChangedFcn = createCallbackFcn(app, @FlashFastTotNValueChanged, true);
            app.FlashFastTotN.Value = 9;
            app.MinLEditFieldLabel = uilabel(app.FastFlashStimulusSelectionPanel);
            app.MinLEditFieldLabel.Text = 'Min L';
            app.FlashFastMinL = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastMinL.Limits = [0 Inf];
            app.FlashFastMinL.ValueChangedFcn = createCallbackFcn(app, @FlashFastMinLValueChanged, true);
            app.FlashFastMinL.Value = 0.1;
            app.MaxLEditFieldLabel = uilabel(app.FastFlashStimulusSelectionPanel);
            app.MaxLEditFieldLabel.Text = 'Max L';
            app.FlashFastMaxL = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastMaxL.Limits = [0 Inf];
            app.FlashFastMaxL.Value = 0.6;
            app.logspacedFlashCk = uicheckbox(app.FastFlashStimulusSelectionPanel);
            app.logspacedFlashCk.ValueChangedFcn = createCallbackFcn(app, @logspacedFlashCkValueChanged, true);
            app.logspacedFlashCk.Text = 'log spaced';
            app.logspacedFlashCk.Value = true;
            app.includezeroFlashCk = uicheckbox(app.FastFlashStimulusSelectionPanel);
            app.includezeroFlashCk.Text = 'include zero';
            app.includezeroFlashCk.Value = true;
            app.generatestimuliFlashBtn = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.generatestimuliFlashBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliFlashBtnPushed, true);
            app.generatestimuliFlashBtn.Text = {'generate stimuli'; 'table'};
            app.savetableButton = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.savetableButton.ButtonPushedFcn = createCallbackFcn(app, @savetableButtonPushed, true);
            app.savetableButton.Text = 'save table';
            app.FlashFastLoadTable = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.FlashFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @FlashFastLoadTableButtonPushed, true);
            app.FlashFastLoadTable.Text = 'load table';
            app.ChoosestimEditFieldLabel = uilabel(app.FastFlashStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel.Text = 'Choose stim.';
            app.FlashFastN = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastN.Limits = [1 Inf];
            app.FlashFastN.RoundFractionalValues = 'on';
            app.FlashFastN.ValueDisplayFormat = '%.0f';
            app.FlashFastN.ValueChangedFcn = createCallbackFcn(app, @FlashFastN_Callback, true);
            app.FlashFastN.Value = 1;
            app.FSFlashGo = uibutton(app.FlashPanel, 'push');
            app.FSFlashGo.ButtonPushedFcn = createCallbackFcn(app, @GoFlash_Callback, true);
            app.FSFlashGo.Text = 'Flash GO!';
            app.FlashdurationsLabel = uilabel(app.FlashPanel);
            app.FlashdurationsLabel.Text = 'Flash duration (s)';
            app.FlashDuration = uieditfield(app.FlashPanel, 'numeric');
            app.FlashDuration.Limits = [0 Inf];
            app.FlashDuration.Value = 0.1;
            app.RepetitionsLabel_2 = uilabel(app.FlashPanel);
            app.RepetitionsLabel_2.Text = 'Repetitions:';
            app.FlashRepetitions = uieditfield(app.FlashPanel, 'numeric');
            app.FlashRepetitions.Limits = [0 Inf];
            app.FlashRepetitions.Value = 20;
            app.PeriodsEditFieldLabel = uilabel(app.FlashPanel);
            app.PeriodsEditFieldLabel.Text = 'Period (s)';
            app.FlashPeriod = uieditfield(app.FlashPanel, 'numeric');
            app.FlashPeriod.Limits = [0 Inf];
            app.FlashPeriod.Value = 4;
            app.uipanel14 = uipanel(app.StimulationTab);
            app.uipanel14.Title = 'General Settings';
            app.InitialdelaysLabel = uilabel(app.uipanel14);
            app.InitialdelaysLabel.Text = 'Initial delay (s):';
            app.initialDelay = uieditfield(app.uipanel14, 'numeric');
            app.initialDelay.Limits = [0 Inf];
            app.initialDelay.Tooltip = {'Initial delay from the start of the sequence to the apparition of the first stimulus. The screen is set to the baseline or mean luminance'};
            app.initialDelay.Value = 1;
            app.ContrastStimulationPanel = uipanel(app.StimulationTab);
            app.ContrastStimulationPanel.Title = 'Contrast Stimulation';
            app.LabelLumi_4 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumi_4.Text = 'cd/m²';
            app.BlackluminanceLabel = uilabel(app.ContrastStimulationPanel);
            app.BlackluminanceLabel.Text = {'Black luminance:'; ''};
            app.CheckLumiB = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiB.Limits = [0 Inf];
            app.CheckLumiB.ValueChangedFcn = createCallbackFcn(app, @CheckLumiBValueChanged, true);
            app.CheckLumiB.Tooltip = {'Luminance of the flash stimulus'};
            app.LabelLumi_5 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumi_5.Text = 'cd/m²';
            app.WhiteluminanceLabel = uilabel(app.ContrastStimulationPanel);
            app.WhiteluminanceLabel.Text = {'White luminance:'; ''};
            app.CheckLumiW = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiW.Limits = [0 Inf];
            app.CheckLumiW.ValueChangedFcn = createCallbackFcn(app, @CheckLumiWValueChanged, true);
            app.CheckLumiW.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiW.Value = 0.6;
            app.LabelLumi_6 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumi_6.Text = 'cd/m²';
            app.MeanluminanceLabel = uilabel(app.ContrastStimulationPanel);
            app.MeanluminanceLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.MeanluminanceLabel.Text = 'Mean luminance:';
            app.CheckLumiMean = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiMean.Limits = [0 Inf];
            app.CheckLumiMean.ValueDisplayFormat = '%5.3g';
            app.CheckLumiMean.ValueChangedFcn = createCallbackFcn(app, @CheckLumiMeanValueChanged, true);
            app.CheckLumiMean.Tooltip = {'Luminance of the background'};
            app.CheckLumiMean.Value = 0.3;
            app.ContrastLabel = uilabel(app.ContrastStimulationPanel);
            app.ContrastLabel.Text = 'Contrast (%)';
            app.CheckContrast = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckContrast.Limits = [0 100];
            app.CheckContrast.ValueChangedFcn = createCallbackFcn(app, @CheckContrastValueChanged, true);
            app.CheckContrast.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckContrast.Value = 100;
            app.CheckerboardPanel = uipanel(app.ContrastStimulationPanel);
            app.CheckerboardPanel.Title = 'Checkerboard';
            app.CheckGo = uibutton(app.CheckerboardPanel, 'push');
            app.CheckGo.ButtonPushedFcn = createCallbackFcn(app, @GoCheck_Callback, true);
            app.CheckGo.Text = 'Checkerboard GO!';
            app.RepetitionsEditField_2Label = uilabel(app.CheckerboardPanel);
            app.RepetitionsEditField_2Label.Text = 'Repetitions:';
            app.CheckRepetitions = uieditfield(app.CheckerboardPanel, 'numeric');
            app.CheckRepetitions.Value = 20;
            app.PeriodsEditField_2Label = uilabel(app.CheckerboardPanel);
            app.PeriodsEditField_2Label.Text = 'Period (s)';
            app.CheckPeriod = uieditfield(app.CheckerboardPanel, 'numeric');
            app.CheckPeriod.Limits = [0 Inf];
            app.CheckPeriod.Value = 4;
            app.GratingsPanel = uipanel(app.ContrastStimulationPanel);
            app.GratingsPanel.Title = 'Periodic Gratings';
            app.SliderOrientation = uislider(app.GratingsPanel);
            app.SliderOrientation.Limits = [0 360];
            app.SliderOrientation.MajorTicks = [];
            app.SliderOrientation.ValueChangedFcn = createCallbackFcn(app, @SliderOrientation_Callback, true);
            app.SliderOrientation.MinorTicks = [];
            app.GoGrid = uibutton(app.GratingsPanel, 'push');
            app.GoGrid.ButtonPushedFcn = createCallbackFcn(app, @GoGrid_Callback, true);
            app.GoGrid.Text = 'Grating GO!';
            app.DrawMask = uicheckbox(app.GratingsPanel);
            app.DrawMask.Text = '';
            app.ButtonGroup = uibuttongroup(app.GratingsPanel);
            app.SineGrid = uiradiobutton(app.ButtonGroup);
            app.SineGrid.Text = 'Sinusoidal';
            app.SquareGrid = uiradiobutton(app.ButtonGroup);
            app.SquareGrid.Text = 'Square';
            app.SquareGrid.Value = true;
            app.GridorientationdegEditFieldLabel = uilabel(app.GratingsPanel);
            app.GridorientationdegEditFieldLabel.Text = 'Grid orientation (deg):';
            app.GridOrientation = uieditfield(app.GratingsPanel, 'numeric');
            app.GridOrientation.Limits = [0 360];
            app.GridOrientation.ValueChangedFcn = createCallbackFcn(app, @GridOrientationValueChanged, true);
            app.RepetitionsEditFieldLabel = uilabel(app.GratingsPanel);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.GridRepetitions = uieditfield(app.GratingsPanel, 'numeric');
            app.GridRepetitions.Limits = [0 Inf];
            app.GridRepetitions.Value = 20;
            app.BaselineGratingPostEditFieldLabel = uilabel(app.GratingsPanel);
            app.BaselineGratingPostEditFieldLabel.Text = 'Baseline, Grating, Post';
            app.PreGridDuration = uieditfield(app.GratingsPanel, 'numeric');
            app.PreGridDuration.Limits = [0 Inf];
            app.PreGridDuration.Value = 0.5;
            app.GridDuration = uieditfield(app.GratingsPanel, 'numeric');
            app.GridDuration.Limits = [0 Inf];
            app.GridDuration.Value = 2;
            app.PostGridDuration = uieditfield(app.GratingsPanel, 'numeric');
            app.PostGridDuration.Limits = [0 Inf];
            app.PostGridDuration.Value = 1.5;
            app.DriftingspeedcyclessecEditFieldLabel = uilabel(app.GratingsPanel);
            app.DriftingspeedcyclessecEditFieldLabel.Text = 'Drifting speed (cycles/sec):';
            app.DriftingSpeed_txt = uieditfield(app.GratingsPanel, 'numeric');
            app.DriftingSpeed_txt.Limits = [0 Inf];
            app.DriftingSpeed_txt.Value = 2;
            app.SizegaussianmaskpixEditFieldLabel = uilabel(app.GratingsPanel);
            app.SizegaussianmaskpixEditFieldLabel.Text = 'Size gaussian mask (pix):';
            app.GaussSize_txt = uieditfield(app.GratingsPanel, 'numeric');
            app.GaussSize_txt.Value = 300;
            app.FastOrientationStimulusSelectionPanel = uipanel(app.GratingsPanel);
            app.FastOrientationStimulusSelectionPanel.Title = 'Fast Orientation Stimulus Selection';
            app.generatestimuliOrientBtn = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.generatestimuliOrientBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliOrientBtnButtonPushed, true);
            app.generatestimuliOrientBtn.Text = {'generate stimuli'; 'table'};
            app.OrientFastSaveTable = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.OrientFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @OrientFastSaveTableButtonPushed, true);
            app.OrientFastSaveTable.Text = 'save table';
            app.OrientFastLoadTable = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.OrientFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @OrientFastLoadTableButtonPushed, true);
            app.OrientFastLoadTable.Text = 'load table';
            app.noftotstimLabel_3 = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.noftotstimLabel_3.Text = 'n. of tot stim';
            app.OrientFastTotN = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastTotN.Value = 4;
            app.StartAngleEditFieldLabel = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.StartAngleEditFieldLabel.Text = 'Start Angle';
            app.OrientFastMinA = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.EndAngleEditFieldLabel = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.EndAngleEditFieldLabel.Text = 'End Angle';
            app.OrientFastMaxA = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastMaxA.Value = 270;
            app.OrientFastTable = uitable(app.FastOrientationStimulusSelectionPanel);
            app.OrientFastTable.ColumnName = {'Stim n.'; 'Orientation'};
            app.OrientFastTable.RowName = {};
            app.OrientFastTable.ColumnEditable = [false true];
            app.ChoosestimEditFieldLabel_3 = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel_3.Text = 'Choose stim.';
            app.OrientFastN = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastN.Limits = [1 Inf];
            app.OrientFastN.RoundFractionalValues = 'on';
            app.OrientFastN.ValueDisplayFormat = '%.0f';
            app.OrientFastN.ValueChangedFcn = createCallbackFcn(app, @OrientFastNValueChanged, true);
            app.OrientFastN.Value = 1;
            app.IntrinsicImagingPanel = uipanel(app.GratingsPanel);
            app.IntrinsicImagingPanel.Title = 'Intrinsic Imaging';
            app.IntrinsicDefaultParametersCheckBox = uicheckbox(app.IntrinsicImagingPanel);
            app.IntrinsicDefaultParametersCheckBox.Text = {'Intrinsic Default'; 'Parameters'};
            app.IntrinsicDefaultParametersCheckBox.Value = true;
            app.IntrinsicGOButton = uibutton(app.IntrinsicImagingPanel, 'push');
            app.IntrinsicGOButton.ButtonPushedFcn = createCallbackFcn(app, @IntrinsicGOButtonPushed, true);
            app.IntrinsicGOButton.Text = 'Intrinsic GO!';
            app.OrientationSwitchLabel = uilabel(app.IntrinsicImagingPanel);
            app.OrientationSwitchLabel.Text = 'Orientation';
            app.IntrOrientationSwitch = uiswitch(app.IntrinsicImagingPanel, 'toggle');
            app.IntrOrientationSwitch.Items = {'Vertical', 'Horizontal'};
            app.IntrOrientationSwitch.ValueChangedFcn = createCallbackFcn(app, @IntrOrientationSwitchValueChanged, true);
            app.IntrOrientationSwitch.Value = 'Vertical';
            app.MovementdirectionSwitchLabel = uilabel(app.IntrinsicImagingPanel);
            app.MovementdirectionSwitchLabel.Text = {'Movement'; 'direction'};
            app.IntrDirectionSwitch = uiswitch(app.IntrinsicImagingPanel, 'toggle');
            app.IntrDirectionSwitch.Items = {'Left-to-right', 'Right-to-left'};
            app.IntrDirectionSwitch.Value = 'Left-to-right';
            app.RectanglesizedegEditFieldLabel = uilabel(app.IntrinsicImagingPanel);
            app.RectanglesizedegEditFieldLabel.Text = 'Rectangle size (deg)';
            app.IntrRectSizeTxt = uieditfield(app.IntrinsicImagingPanel, 'numeric');
            app.IntrRectSizeTxt.Limits = [0 Inf];
            app.IntrRectSizeTxt.Value = 2;
            app.CheckLumiBEsch = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiBEsch.Limits = [0 Inf];
            app.CheckLumiBEsch.Editable = 'off';
            app.CheckLumiBEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiWEsch = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiWEsch.Limits = [0 Inf];
            app.CheckLumiWEsch.Editable = 'off';
            app.CheckLumiWEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiWEsch.Value = 0.6;
            app.CheckLumiMeanEsch = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiMeanEsch.Limits = [0 Inf];
            app.CheckLumiMeanEsch.ValueDisplayFormat = '%5.3g';
            app.CheckLumiMeanEsch.Editable = 'off';
            app.CheckLumiMeanEsch.Tooltip = {'Luminance of the background'};
            app.CheckLumiMeanEsch.Value = 0.3;
            app.LabelLumiEsch_4 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumiEsch_4.Text = '(0-1)';
            app.LabelLumiEsch_5 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumiEsch_5.Text = '(0-1)';
            app.LabelLumiEsch_6 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumiEsch_6.Text = '(0-1)';
            app.FastContrastStimulusSelectionPanel = uipanel(app.ContrastStimulationPanel);
            app.FastContrastStimulusSelectionPanel.Title = 'Fast Contrast Stimulus Selection';
            app.CheckFastTable = uitable(app.FastContrastStimulusSelectionPanel);
            app.CheckFastTable.ColumnName = {'Stim n.'; 'Contrast'; 'Mean L'};
            app.CheckFastTable.RowName = {};
            app.CheckFastTable.ColumnEditable = [false true true];
            app.ChoosestimEditFieldLabel_2 = uilabel(app.FastContrastStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel_2.Text = 'Choose stim.';
            app.CheckFastN = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastN.Limits = [1 Inf];
            app.CheckFastN.RoundFractionalValues = 'on';
            app.CheckFastN.ValueDisplayFormat = '%.0f';
            app.CheckFastN.ValueChangedFcn = createCallbackFcn(app, @CheckFastNValueChanged, true);
            app.CheckFastN.Value = 1;
            app.CheckFastSaveTable = uibutton(app.FastContrastStimulusSelectionPanel, 'push');
            app.CheckFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @CheckFastSaveTableButtonPushed, true);
            app.CheckFastSaveTable.Text = 'save table';
            app.CheckFastLoadTable = uibutton(app.FastContrastStimulusSelectionPanel, 'push');
            app.CheckFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @CheckFastLoadTableButtonPushed, true);
            app.CheckFastLoadTable.Text = 'load table';
            app.generatestimuliCheckBtn = uibutton(app.FastContrastStimulusSelectionPanel, 'push');
            app.generatestimuliCheckBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliCheckBtnButtonPushed, true);
            app.generatestimuliCheckBtn.Text = {'generate stimuli'; 'table'};
            app.logspacedCheckCk = uicheckbox(app.FastContrastStimulusSelectionPanel);
            app.logspacedCheckCk.ValueChangedFcn = createCallbackFcn(app, @logspacedCheckCkValueChanged, true);
            app.logspacedCheckCk.Text = 'log spaced';
            app.logspacedCheckCk.Value = true;
            app.includezeroCheckCk = uicheckbox(app.FastContrastStimulusSelectionPanel);
            app.includezeroCheckCk.Text = 'include zero';
            app.includezeroCheckCk.Value = true;
            app.MinLEditFieldLabel_2 = uilabel(app.FastContrastStimulusSelectionPanel);
            app.MinLEditFieldLabel_2.Text = 'Min C';
            app.CheckFastMinC = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastMinC.Limits = [0 100];
            app.CheckFastMinC.ValueChangedFcn = createCallbackFcn(app, @CheckFastMinCValueChanged, true);
            app.CheckFastMinC.Value = 6;
            app.MaxLEditFieldLabel_2 = uilabel(app.FastContrastStimulusSelectionPanel);
            app.MaxLEditFieldLabel_2.Text = 'Max C';
            app.CheckFastMaxC = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastMaxC.Limits = [0 100];
            app.CheckFastMaxC.Value = 95;
            app.noftotstimLabel_2 = uilabel(app.FastContrastStimulusSelectionPanel);
            app.noftotstimLabel_2.Text = 'n. of tot stim';
            app.CheckFastTotN = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastTotN.Limits = [1 Inf];
            app.CheckFastTotN.RoundFractionalValues = 'on';
            app.CheckFastTotN.ValueChangedFcn = createCallbackFcn(app, @CheckFastTotNValueChanged, true);
            app.CheckFastTotN.Value = 6;
            app.MeanLEditFieldLabel = uilabel(app.FastContrastStimulusSelectionPanel);
            app.MeanLEditFieldLabel.Text = 'Mean L';
            app.CheckFastMeanL = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastMeanL.Limits = [0 Inf];
            app.CheckFastMeanL.Value = 0.6;
            app.FrequencycyclesDegLabel = uilabel(app.ContrastStimulationPanel);
            app.FrequencycyclesDegLabel.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.FrequencycyclesDegLabel.Text = 'Frequency (cycles/Deg):';
            app.spatialFrequency = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.spatialFrequency.Limits = [0 Inf];
            app.spatialFrequency.ValueDisplayFormat = '%5.3g';
            app.spatialFrequency.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.spatialFrequency.Value = 0.04;
            app.SomatosensoryPanel = uipanel(app.StimulationTab);
            app.SomatosensoryPanel.Title = 'Somatosensory';
            app.RepetitionsLabel_3 = uilabel(app.SomatosensoryPanel);
            app.RepetitionsLabel_3.Text = 'Repetitions:';
            app.PuffRepetitions = uieditfield(app.SomatosensoryPanel, 'numeric');
            app.PuffRepetitions.Limits = [0 Inf];
            app.PuffRepetitions.Value = 40;
            app.PuffdurationmsLabel = uilabel(app.SomatosensoryPanel);
            app.PuffdurationmsLabel.Text = 'Puff duration (ms):';
            app.PuffDuration = uieditfield(app.SomatosensoryPanel, 'numeric');
            app.PuffDuration.Limits = [0 Inf];
            app.PuffDuration.Editable = 'off';
            app.PuffDuration.Value = 0.1;
            app.PeriodsLabel = uilabel(app.SomatosensoryPanel);
            app.PeriodsLabel.Text = 'Period (s):';
            app.PuffPeriod = uieditfield(app.SomatosensoryPanel, 'numeric');
            app.PuffPeriod.Limits = [0 Inf];
            app.PuffPeriod.Value = 2;
            app.PuffGo = uibutton(app.SomatosensoryPanel, 'push');
            app.PuffGo.ButtonPushedFcn = createCallbackFcn(app, @PuffGoButtonPushed, true);
            app.PuffGo.Text = 'GO!';
            app.ExperimentlistTab = uitab(app.TabGroup);
            app.ExperimentlistTab.Title = 'Experiment list';
            app.ExperimentTable = uitable(app.ExperimentlistTab);
            app.ExperimentTable.ColumnName = {'Run n.'; 'Stimulus type'; 'Stimulus n.'; 'Notes'};
            app.ExperimentTable.RowName = {};
            app.ExperimentTable.ColumnEditable = [false true true true];
            app.ExperimentTable.CellSelectionCallback = createCallbackFcn(app, @ExperimentTableCellSelection, true);
            app.RandomizeexperimentPanel = uipanel(app.ExperimentlistTab);
            app.RandomizeexperimentPanel.Title = 'Randomize experiment';
            app.ExpListFlashCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListFlashCk.Text = 'Flash';
            app.ExpListFlashCk.Value = true;
            app.ExpListCheckCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListCheckCk.Text = 'Checkerboard (contrast)';
            app.ExpListCheckCk.Value = true;
            app.ExpListGratingsOrientCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListGratingsOrientCk.Text = 'Grating (orientation)';
            app.ExpListGratingsOrientCk.Value = true;
            app.RandomizeButton = uibutton(app.RandomizeexperimentPanel, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.NumberofsessionsEditFieldLabel = uilabel(app.RandomizeexperimentPanel);
            app.NumberofsessionsEditFieldLabel.Text = 'Number of sessions';
            app.ExpListSessionN = uieditfield(app.RandomizeexperimentPanel, 'numeric');
            app.ExpListSessionN.Limits = [1 Inf];
            app.ExpListSessionN.Value = 2;
            app.ExpListGratingsContrastCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListGratingsContrastCk.Text = 'Grating (contrast)';
            app.ExpListGratingsContrastCk.Value = true;
            app.ExpListGo = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListGo.ButtonPushedFcn = createCallbackFcn(app, @ExpListGoPushed, true);
            app.ExpListGo.Enable = 'off';
            app.ExpListGo.Text = 'Go!';
            app.ExpListAddRows = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListAddRows.ButtonPushedFcn = createCallbackFcn(app, @ExpListAddRowsButtonPushed, true);
            app.ExpListAddRows.Text = 'add';
            app.ExpListDeleteRows = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListDeleteRows.ButtonPushedFcn = createCallbackFcn(app, @ExpListDeleteRowsButtonPushed, true);
            app.ExpListDeleteRows.Enable = 'off';
            app.ExpListDeleteRows.Text = 'del';
            app.ExpMoveUp = uibutton(app.ExperimentlistTab, 'push');
            app.ExpMoveUp.ButtonPushedFcn = createCallbackFcn(app, @ExpMoveUpPushed, true);
            app.ExpMoveUp.Text = '↑';
            app.ExpMoveDown = uibutton(app.ExperimentlistTab, 'push');
            app.ExpMoveDown.ButtonPushedFcn = createCallbackFcn(app, @ExpMoveDownButtonPushed, true);
            app.ExpMoveDown.Text = '↓';
            app.CopytableButton = uibutton(app.ExperimentlistTab, 'push');
            app.CopytableButton.ButtonPushedFcn = createCallbackFcn(app, @CopytableButtonPushed, true);
            app.CopytableButton.Text = 'Copy table';
            app.CurrentrunnEditFieldLabel = uilabel(app.ExperimentlistTab);
            app.CurrentrunnEditFieldLabel.Text = 'Current run n.';
            app.ExpListCurrentNum = uieditfield(app.ExperimentlistTab, 'numeric');
            app.ExpListCurrentNum.Editable = 'off';
            app.RepeatStimBtn = uibutton(app.ExperimentlistTab, 'push');
            app.RepeatStimBtn.ButtonPushedFcn = createCallbackFcn(app, @RepeatStimBtnButtonPushed, true);
            app.RepeatStimBtn.Enable = 'off';
            app.RepeatStimBtn.Text = {'Repeat'; 'last'; 'stimulus'};
            app.NoffirstexpEditFieldLabel = uilabel(app.ExperimentlistTab);
            app.NoffirstexpEditFieldLabel.Text = 'N. of first exp';
            app.ExpListFirstNum = uieditfield(app.ExperimentlistTab, 'numeric');
            app.ExpListFirstNum.ValueChangedFcn = createCallbackFcn(app, @ExpListFirstNumValueChanged, true);
            app.ExpListFirstNum.Value = 1;
            app.PupilWhileStimulating = uicheckbox(app.ExperimentlistTab);
            app.PupilWhileStimulating.Text = 'Pupil Preview while stimulating';
            app.PcoCDataStimMaxEditField = uieditfield(app.ExperimentlistTab, 'numeric');
            app.PcoCDataStimMaxEditField.Limits = [0 65536];
            app.PcoCDataStimMaxEditField.RoundFractionalValues = 'on';
            app.PcoCDataStimMaxEditField.Value = 2000;
            app.MaxLabel_2 = uilabel(app.ExperimentlistTab);
            app.MaxLabel_2.Text = 'Max:';
            app.CDataMinEditField_2Label = uilabel(app.ExperimentlistTab);
            app.CDataMinEditField_2Label.Text = 'CData Min:';
            app.PcoCDataStimMinEditField = uieditfield(app.ExperimentlistTab, 'numeric');
            app.PcoCDataStimMinEditField.Limits = [0 65536];
            app.PcoCDataStimMinEditField.RoundFractionalValues = 'on';
            app.PcoAutoCLimStimButton = uibutton(app.ExperimentlistTab, 'push');
            app.PcoAutoCLimStimButton.ButtonPushedFcn = createCallbackFcn(app, @PcoAutoCLimStimButtonPushed, true);
            app.PcoAutoCLimStimButton.Text = 'Auto';
            app.StimulationandPCOPanel = uipanel(app.ExperimentlistTab);
            app.StimulationandPCOPanel.Title = 'Stimulation and PCO';
            app.PcoWhileStimulusCk = uicheckbox(app.StimulationandPCOPanel);
            app.PcoWhileStimulusCk.Text = 'PCO while acquiring';
            app.DeadtimebetweeneachtrialmsEditFieldLabel = uilabel(app.StimulationandPCOPanel);
            app.DeadtimebetweeneachtrialmsEditFieldLabel.Text = {'Dead time between'; 'each trial (ms):'};
            app.DeadTBeforeTrialEditField = uieditfield(app.StimulationandPCOPanel, 'numeric');
            app.DeadTBeforeTrialEditField.Limits = [0 Inf];
            app.DeadTBeforeTrialEditField.Tooltip = {'Amount of time the PCO will stop acquiring before the start of each trial. Rounded to microsecond precision.'};
            app.DeadTBeforeTrialEditField.Value = 150;
            app.PcoHAxesStim = uiaxes(app.ExperimentlistTab);
            app.PcoHAxesStim.XTick = [];
            app.PcoHAxesStim.YTick = [];
            app.PupilTab = uitab(app.TabGroup);
            app.PupilTab.Title = 'Pupil';
            app.CameraPanel = uipanel(app.PupilTab);
            app.CameraPanel.Title = 'Camera Panel';
            app.RefreshDevicesButton = uibutton(app.CameraPanel, 'push');
            app.RefreshDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDevicesButtonPushed, true);
            app.RefreshDevicesButton.Text = 'Refresh Devices';
            app.CameraDropDownLabel = uilabel(app.CameraPanel);
            app.CameraDropDownLabel.Text = 'Camera:';
            app.CameraDropDown = uidropdown(app.CameraPanel);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = {};
            app.ImageSizeDropDownLabel = uilabel(app.CameraPanel);
            app.ImageSizeDropDownLabel.Text = 'Image Size:';
            app.ImageSizeDropDown = uidropdown(app.CameraPanel);
            app.ImageSizeDropDown.Items = {};
            app.ImageSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSizeDropDownValueChanged, true);
            app.ImageSizeDropDown.Value = {};
            app.CameraUnconnectedLampLabel = uilabel(app.CameraPanel);
            app.CameraUnconnectedLampLabel.Text = 'Camera Unconnected';
            app.CameraUnconnectedLamp = uilamp(app.CameraPanel);
            app.PreviewPanel = uipanel(app.PupilTab);
            app.PreviewPanel.Title = 'Preview Panel';
            app.NumberofPointsinPreviewGraphEditFieldLabel = uilabel(app.PreviewPanel);
            app.NumberofPointsinPreviewGraphEditFieldLabel.Text = 'Number of Points in Preview Graph';
            app.NumberofPointsinPreviewGraphEditField = uieditfield(app.PreviewPanel, 'numeric');
            app.NumberofPointsinPreviewGraphEditField.Limits = [1 Inf];
            app.NumberofPointsinPreviewGraphEditField.RoundFractionalValues = 'on';
            app.NumberofPointsinPreviewGraphEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsinPreviewGraphEditFieldValueChanged, true);
            app.NumberofPointsinPreviewGraphEditField.Value = 100;
            app.StartPreviewButton = uibutton(app.PreviewPanel, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start Preview';
            app.skipPupilPrevCk = uicheckbox(app.PreviewPanel);
            app.skipPupilPrevCk.Text = 'Skip pupil analysis in preview';
            app.ResetAxesButton = uibutton(app.PreviewPanel, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset Axes';
            app.ThresholdSliderLabel = uilabel(app.PupilTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.PupilTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.Value = 0.5;
            app.AcquisitionPanel = uipanel(app.PupilTab);
            app.AcquisitionPanel.Title = 'Acquisition Panel';
            app.DurationsEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s):';
            app.DurationsEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.DurationsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsEditFieldValueChanged, true);
            app.DurationsEditField.Value = 5;
            app.PupilStartAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.PupilStartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @PupilStartAcquisitionButtonPushed, true);
            app.PupilStartAcquisitionButton.Text = 'START ACQUISITION';
            app.PreviewWhileAcquiringCheckBox = uicheckbox(app.AcquisitionPanel);
            app.PreviewWhileAcquiringCheckBox.Text = 'Preview While Acquiring';
            app.PreviewWhileAcquiringCheckBox.Value = true;
            app.SaveFolderEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.SaveFolderEditFieldLabel.Text = 'Save Folder';
            app.SaveFolderEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.SaveFolderEditField.Value = 'D:\Enrico\Documents\MATLAB\PupillometryCalibration';
            app.FilenEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FilenEditFieldLabel.Text = 'File n.';
            app.OutputFileN = uieditfield(app.AcquisitionPanel, 'numeric');
            app.OutputFileN.Limits = [1 Inf];
            app.OutputFileN.RoundFractionalValues = 'on';
            app.OutputFileN.ValueDisplayFormat = '%.0f';
            app.OutputFileN.Value = 1;
            app.StatusEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.acqStatusTxt = uieditfield(app.AcquisitionPanel, 'text');
            app.acqStatusTxt.Editable = 'off';
            app.acqStatusTxt.Value = 'Not acquiring';
            app.skipPupilAcqCk = uicheckbox(app.AcquisitionPanel);
            app.skipPupilAcqCk.Text = 'Skip pupil analysis';
            app.skipPupilAcqCk.Value = true;
            app.acqLamp = uilamp(app.AcquisitionPanel);
            app.FilenameEditFieldLabel_2 = uilabel(app.AcquisitionPanel);
            app.FilenameEditFieldLabel_2.Text = 'File name';
            app.PupilFilenameEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.PupilFilenameEditField.Value = '120x160_Vert';
            app.PupilImage1UIAxes = uiaxes(app.PupilTab);
            title(app.PupilImage1UIAxes, 'Pupil Recognition')
            app.PupilImage1UIAxes.XTick = [];
            app.PupilImage1UIAxes.YTick = [];
            app.PupilImage2UIAxes = uiaxes(app.PupilTab);
            title(app.PupilImage2UIAxes, 'Binarized Image')
            app.PupilImage2UIAxes.XTick = [];
            app.PupilImage2UIAxes.YTick = [];
            app.PupilGraphUIAxes = uiaxes(app.PupilTab);
            title(app.PupilGraphUIAxes, 'Pupil Measurement')
            xlabel(app.PupilGraphUIAxes, 'Time (s)')
            ylabel(app.PupilGraphUIAxes, 'Pupil Dimension (px)')
            app.PCOImagingTab = uitab(app.TabGroup);
            app.PCOImagingTab.Title = 'PCO Imaging';
            app.FindPCOButton = uibutton(app.PCOImagingTab, 'push');
            app.FindPCOButton.ButtonPushedFcn = createCallbackFcn(app, @FindPCOButtonPushed, true);
            app.FindPCOButton.Text = 'Find PCO';
            app.PCOLabel = uilabel(app.PCOImagingTab);
            app.PCOLabel.Enable = 'off';
            app.PCOLabel.Text = 'PCO:';
            app.PcoDropDown = uidropdown(app.PCOImagingTab);
            app.PcoDropDown.Items = {};
            app.PcoDropDown.Enable = 'off';
            app.PcoDropDown.Value = {};
            app.AcquisitionPanel_2 = uipanel(app.PCOImagingTab);
            app.AcquisitionPanel_2.Title = 'Acquisition Panel';
            app.DurationsEditField_2Label = uilabel(app.AcquisitionPanel_2);
            app.DurationsEditField_2Label.Text = 'Duration (s):';
            app.PcoDurationEditField = uieditfield(app.AcquisitionPanel_2, 'numeric');
            app.PcoDurationEditField.ValueChangedFcn = createCallbackFcn(app, @PcoDurationEditFieldValueChanged, true);
            app.PcoDurationEditField.Value = 80;
            app.PcoStartAcquisitionButton = uibutton(app.AcquisitionPanel_2, 'push');
            app.PcoStartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @PcoStartAcquisitionButtonPushed, true);
            app.PcoStartAcquisitionButton.Enable = 'off';
            app.PcoStartAcquisitionButton.Text = 'START ACQUISITION';
            app.SaveFolderEditField_2Label = uilabel(app.AcquisitionPanel_2);
            app.SaveFolderEditField_2Label.Text = 'Save Folder';
            app.PcoSaveFolderEditField = uieditfield(app.AcquisitionPanel_2, 'text');
            app.PcoSaveFolderEditField.Value = 'C:\Downloads\PcoSaves\20200305 aav9 cag ddGCaMP6f p50';
            app.FilenEditFieldLabel_2 = uilabel(app.AcquisitionPanel_2);
            app.FilenEditFieldLabel_2.Text = 'File n.';
            app.PcoOutputFileN = uieditfield(app.AcquisitionPanel_2, 'numeric');
            app.PcoOutputFileN.Limits = [1 Inf];
            app.PcoOutputFileN.RoundFractionalValues = 'on';
            app.PcoOutputFileN.ValueDisplayFormat = '%.0f';
            app.PcoOutputFileN.Value = 1;
            app.FilenameEditFieldLabel = uilabel(app.AcquisitionPanel_2);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.PcoFilenameEditField = uieditfield(app.AcquisitionPanel_2, 'text');
            app.PcoFilenameEditField.Value = 'file';
            app.MaxFrequencyHzEditFieldLabel = uilabel(app.AcquisitionPanel_2);
            app.MaxFrequencyHzEditFieldLabel.Text = 'Max Frequency (Hz):';
            app.PcoMaxFreqEditField = uieditfield(app.AcquisitionPanel_2, 'numeric');
            app.PcoMaxFreqEditField.Limits = [0 Inf];
            app.PcoMaxFreqEditField.Editable = 'off';
            app.SelectedFrequencyHzEditFieldLabel = uilabel(app.AcquisitionPanel_2);
            app.SelectedFrequencyHzEditFieldLabel.Enable = 'off';
            app.SelectedFrequencyHzEditFieldLabel.Text = 'Selected Frequency (Hz):';
            app.PcoSelectedFreqEditField = uieditfield(app.AcquisitionPanel_2, 'numeric');
            app.PcoSelectedFreqEditField.Limits = [0 Inf];
            app.PcoSelectedFreqEditField.ValueChangedFcn = createCallbackFcn(app, @PcoSelectedFreqEditFieldValueChanged, true);
            app.PcoSelectedFreqEditField.Enable = 'off';
            app.NumberofframesEditFieldLabel = uilabel(app.AcquisitionPanel_2);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames:';
            app.PcoNumberFramesEditField = uieditfield(app.AcquisitionPanel_2, 'numeric');
            app.PcoNumberFramesEditField.Limits = [0 Inf];
            app.PcoNumberFramesEditField.Editable = 'off';
            app.PcoSavefileCk = uicheckbox(app.AcquisitionPanel_2);
            app.PcoSavefileCk.Text = 'Save file';
            app.PcoSavefileCk.Value = true;
            app.TimeoutsEditFieldLabel = uilabel(app.AcquisitionPanel_2);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s):';
            app.PcoTimeoutEditField = uieditfield(app.AcquisitionPanel_2, 'numeric');
            app.PcoTimeoutEditField.Limits = [0 Inf];
            app.PcoTimeoutEditField.Value = 5;
            app.HermesInitialDelaysEditFieldLabel = uilabel(app.AcquisitionPanel_2);
            app.HermesInitialDelaysEditFieldLabel.Text = 'Hermes Initial Delay (s):';
            app.HermesInitialDelaysEditField = uieditfield(app.AcquisitionPanel_2, 'numeric');
            app.HermesInitialDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @HermesInitialDelaysEditFieldValueChanged, true);
            app.HermesInitialDelaysEditField.Tooltip = {'Amount of time Hermes will wait before starting a TTL sequence. It doesn''t work with stimuli. Rounded to millisecond precision.'};
            app.HermesInitialDelaysEditField.Value = 1;
            app.ExposureTimemsEditFieldLabel = uilabel(app.PCOImagingTab);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimeEditField = uieditfield(app.PCOImagingTab, 'numeric');
            app.ExposureTimeEditField.Limits = [0 Inf];
            app.ExposureTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeEditFieldValueChanged, true);
            app.ExposureTimeEditField.Value = 20;
            app.TimestampLabel = uilabel(app.PCOImagingTab);
            app.TimestampLabel.Text = 'Timestamp:';
            app.TimestampDropDown = uidropdown(app.PCOImagingTab);
            app.TimestampDropDown.Items = {'No timestamp', 'Binary-only', 'Binary and ASCII'};
            app.TimestampDropDown.Value = 'Binary-only';
            app.RecordingsubmodeDropDownLabel = uilabel(app.PCOImagingTab);
            app.RecordingsubmodeDropDownLabel.Text = 'Recording submode:';
            app.RecordingsubmodeDropDown = uidropdown(app.PCOImagingTab);
            app.RecordingsubmodeDropDown.Items = {'Sequence', 'Ring buffer'};
            app.RecordingsubmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingsubmodeDropDownValueChanged, true);
            app.RecordingsubmodeDropDown.Value = 'Sequence';
            app.SynchronizationtypeDropDownLabel = uilabel(app.PCOImagingTab);
            app.SynchronizationtypeDropDownLabel.Text = 'Synchronization type';
            app.PcoSyncDropDown = uidropdown(app.PCOImagingTab);
            app.PcoSyncDropDown.Items = {'Escher-only', 'Hermes'};
            app.PcoSyncDropDown.ValueChangedFcn = createCallbackFcn(app, @PcoSyncDropDownValueChanged, true);
            app.PcoSyncDropDown.Value = 'Escher-only';
            app.PreviewPanel_2 = uipanel(app.PCOImagingTab);
            app.PreviewPanel_2.Title = 'Preview Panel';
            app.PcoStartPreviewButton = uibutton(app.PreviewPanel_2, 'push');
            app.PcoStartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PcoStartPreviewButtonPushed, true);
            app.PcoStartPreviewButton.Enable = 'off';
            app.PcoStartPreviewButton.Text = 'Start Preview';
            app.PcoCDataMaxEditField = uieditfield(app.PreviewPanel_2, 'numeric');
            app.PcoCDataMaxEditField.Limits = [0 65536];
            app.PcoCDataMaxEditField.RoundFractionalValues = 'on';
            app.PcoCDataMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PcoCDataMaxEditFieldValueChanged, true);
            app.PcoCDataMaxEditField.Value = 2000;
            app.MaxLabel = uilabel(app.PreviewPanel_2);
            app.MaxLabel.Text = 'Max:';
            app.CDataMinLabel = uilabel(app.PreviewPanel_2);
            app.CDataMinLabel.Text = 'CData Min:';
            app.PcoCDataMinEditField = uieditfield(app.PreviewPanel_2, 'numeric');
            app.PcoCDataMinEditField.Limits = [0 65536];
            app.PcoCDataMinEditField.RoundFractionalValues = 'on';
            app.PcoCDataMinEditField.ValueChangedFcn = createCallbackFcn(app, @PcoCDataMinEditFieldValueChanged, true);
            app.PreviewoneframeeveryLabel = uilabel(app.PreviewPanel_2);
            app.PreviewoneframeeveryLabel.Text = 'Preview one frame every';
            app.PcoNFramesInPreview = uieditfield(app.PreviewPanel_2, 'numeric');
            app.PcoNFramesInPreview.Value = 10;
            app.PcoAutoCLimButton = uibutton(app.PreviewPanel_2, 'push');
            app.PcoAutoCLimButton.ButtonPushedFcn = createCallbackFcn(app, @PcoAutoCLimButtonPushed, true);
            app.PcoAutoCLimButton.Text = 'Auto';
            app.PCOunarmedLabel = uilabel(app.PCOImagingTab);
            app.PCOunarmedLabel.Text = 'PCO unarmed';
            app.PCOarmedLamp = uilamp(app.PCOImagingTab);
            app.ArmPCOButton = uibutton(app.PCOImagingTab, 'push');
            app.ArmPCOButton.ButtonPushedFcn = createCallbackFcn(app, @ArmPCOButtonPushed, true);
            app.ArmPCOButton.Text = 'Arm PCO';
            app.BuffernumberEditFieldLabel = uilabel(app.PCOImagingTab);
            app.BuffernumberEditFieldLabel.Text = 'Buffer number';
            app.PcoBufferNumEditField = uieditfield(app.PCOImagingTab, 'numeric');
            app.PcoBufferNumEditField.Limits = [1 16];
            app.PcoBufferNumEditField.RoundFractionalValues = 'on';
            app.PcoBufferNumEditField.Value = 6;
            app.OpenFieldAnalyzerButton = uibutton(app.PCOImagingTab, 'state');
            app.OpenFieldAnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @OpenFieldAnalyzerButtonValueChanged, true);
            app.OpenFieldAnalyzerButton.Text = 'Open Field Analyzer';
            app.PcoHAxes = uiaxes(app.PCOImagingTab);
            app.PcoHAxes.XTick = [];
            app.PcoHAxes.YTick = [];
            app.OptogeneticsTab = uitab(app.TabGroup);
            app.OptogeneticsTab.Title = 'Optogenetics';
            app.TypeButtonGroup = uibuttongroup(app.OptogeneticsTab);
            app.TypeButtonGroup.Title = 'Type';
            app.ContinuousButton = uiradiobutton(app.TypeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.PulsedButton = uiradiobutton(app.TypeButtonGroup);
            app.PulsedButton.Enable = 'off';
            app.PulsedButton.Text = 'Pulsed';
            app.FrequencyHzEditFieldLabel = uilabel(app.TypeButtonGroup);
            app.FrequencyHzEditFieldLabel.Enable = 'off';
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.TypeButtonGroup, 'numeric');
            app.FrequencyHzEditField.Enable = 'off';
            app.FrequencyHzEditField.Value = 30;
            app.DuntycycleLabel = uilabel(app.TypeButtonGroup);
            app.DuntycycleLabel.Enable = 'off';
            app.DuntycycleLabel.Text = 'Dunty cycle (%)';
            app.DuntycycleEditField = uieditfield(app.TypeButtonGroup, 'numeric');
            app.DuntycycleEditField.Enable = 'off';
            app.DuntycycleEditField.Value = 50;
            app.TimingPanel = uipanel(app.OptogeneticsTab);
            app.TimingPanel.Title = 'Timing';
            app.StartmsEditFieldLabel = uilabel(app.TimingPanel);
            app.StartmsEditFieldLabel.Text = 'Start (ms)';
            app.optoStartEditField = uieditfield(app.TimingPanel, 'numeric');
            app.optoStartEditField.ValueChangedFcn = createCallbackFcn(app, @optoStartEditFieldValueChanged, true);
            app.optoStartEditField.Value = 200;
            app.afterstimulusonsetLabel = uilabel(app.TimingPanel);
            app.afterstimulusonsetLabel.Text = 'after stimulus onset';
            app.DurationmsEditFieldLabel = uilabel(app.TimingPanel);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.optoDurEditField = uieditfield(app.TimingPanel, 'numeric');
            app.optoDurEditField.ValueChangedFcn = createCallbackFcn(app, @optoDurEditFieldValueChanged, true);
            app.optoDurEditField.Value = 800;
            app.HermesTab = uitab(app.TabGroup);
            app.HermesTab.Title = 'Hermes';
            app.calblack_btn = uibutton(app.HermesTab, 'push');
            app.calblack_btn.ButtonPushedFcn = createCallbackFcn(app, @calblack_btn_Callback, true);
            app.calblack_btn.Text = 'C-Black';
            app.calwhite_btn = uibutton(app.HermesTab, 'push');
            app.calwhite_btn.ButtonPushedFcn = createCallbackFcn(app, @calwhite_btn_Callback, true);
            app.calwhite_btn.Text = 'C-White';
            app.TTLdurationmsLabel = uilabel(app.HermesTab);
            app.TTLdurationmsLabel.Text = 'TTL duration (ms):';
            app.HermesTtlDurationEditField = uieditfield(app.HermesTab, 'numeric');
            app.HermesTtlDurationEditField.Limits = [0 Inf];
            app.HermesTtlDurationEditField.ValueChangedFcn = createCallbackFcn(app, @HermesTtlDurationEditFieldValueChanged, true);
            app.HermesTtlDurationEditField.Value = 2;
            app.HermesIdletimemsLabel = uilabel(app.HermesTab);
            app.HermesIdletimemsLabel.Text = 'Hermes Idle time (ms):';
            app.HermesIdleTimeEditField = uieditfield(app.HermesTab, 'numeric');
            app.HermesIdleTimeEditField.Limits = [0 Inf];
            app.HermesIdleTimeEditField.RoundFractionalValues = 'on';
            app.HermesIdleTimeEditField.ValueChangedFcn = createCallbackFcn(app, @HermesIdleTimeEditFieldValueChanged, true);
            app.HermesIdleTimeEditField.Tooltip = {'Amount of time Hermes will wait to start a new TTL sognal after a TTL signal. Rounded to millisecond precision.'};
            app.HermesIdleTimeEditField.Value = 200;
            app.SerialPanel = uipanel(app.HermesTab);
            app.SerialPanel.Title = 'Serial';
            app.RefreshCOMButton = uibutton(app.SerialPanel, 'push');
            app.RefreshCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMButtonPushed, true);
            app.RefreshCOMButton.Text = 'Refresh COM';
            app.SerialCOMDropDownLabel = uilabel(app.SerialPanel);
            app.SerialCOMDropDownLabel.Text = 'Serial COM:';
            app.SerialCOMDropDown = uidropdown(app.SerialPanel);
            app.SerialCOMDropDown.Items = {};
            app.SerialCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialCOMDropDownValueChanged, true);
            app.SerialCOMDropDown.Value = {};
            app.PrintSerialCommCk = uicheckbox(app.SerialPanel);
            app.PrintSerialCommCk.Text = 'Print Serial Comm';
            app.PrintSerialCommCk.Value = true;
            app.WorkingconfigurationPanel = uipanel(app.HermesTab);
            app.WorkingconfigurationPanel.Title = 'Working configuration';
            app.ModeDropDownLabel = uilabel(app.WorkingconfigurationPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.WorkingconfigurationPanel);
            app.ModeDropDown.Items = {'classic', 'camAcq', 'camPrev', 'opto'};
            app.ModeDropDown.Value = 'classic';
            app.TriggerDropDownLabel = uilabel(app.WorkingconfigurationPanel);
            app.TriggerDropDownLabel.Text = 'Trigger';
            app.TriggerDropDown = uidropdown(app.WorkingconfigurationPanel);
            app.TriggerDropDown.Items = {'phototransistor', 'serial'};
            app.TriggerDropDown.Value = 'phototransistor';
            app.ApplyButton = uibutton(app.WorkingconfigurationPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.BusyStatusLampLabel = uilabel(app.EscherMasterFig);
            app.BusyStatusLampLabel.Text = 'Busy Status:';
            app.BusyStatusLamp = uilamp(app.EscherMasterFig);
            app.DeBusyButton = uibutton(app.EscherMasterFig, 'push');
            app.DeBusyButton.ButtonPushedFcn = createCallbackFcn(app, @DeBusyButtonPushed, true);
            app.DeBusyButton.Text = 'De-Busy';
            app.UDPConnectionLampLabel = uilabel(app.EscherMasterFig);
            app.UDPConnectionLampLabel.Text = 'UDP Connection:';
            app.UDPConnectionLamp = uilamp(app.EscherMasterFig);
            % Show the figure after all components are created
            app.EscherMasterFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EscherAppMaster

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EscherMasterFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EscherMasterFig)
        end
    end
end